classdef configurations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        currentDBSourceEditField       matlab.ui.control.EditField
        currentDBSourceEditFieldLabel  matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        refreshDBSourceButton          matlab.ui.control.Button
        configureDBSourceButton        matlab.ui.control.Button
        languageDropDown               matlab.ui.control.DropDown
        languageDropDownLabel          matlab.ui.control.Label
        dbSourceUITable                matlab.ui.control.Table
        dbSourceUITableLabel           matlab.ui.control.Label
        applyChangesButton             matlab.ui.control.Button
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        %% Color for UI
        darkThemeColorUIF =  [0,0,0]; 
        darkThemeColorBGLabels = "none";
        darkThemeColorBGContainers= [20,20,20]/100;
        darkThemeFontColor = [94,94,94]/100; 

        lightThemeColorUIF = [94, 94, 94]/100; 
        lightThemeColorBGLabels = "none";
        lightThemeColorBGContainers= [1,1,1];
        lightThemeFontColor = [0,0,0];
        
        %% Control for language and DB soruce selection
        configurationsLanguage
        items
        name
        driverType
        columNames

        appName
        currentLanguage 
        newLanguage

        mainApp
        dbSource

        dbSourceNotAvailable

        %% Origin path for files load
        originPath
    end
    
    methods (Access = private)
        
        function [] = setDarkTheme(app)
            %% Setting up color for dark theme
            app.UIFigure.Color = app.darkThemeColorUIF;

            app.applyChangesButton.BackgroundColor = app.darkThemeColorBGContainers;
            app.configureDBSourceButton.BackgroundColor = app.darkThemeColorBGContainers;
            app.dbSourceUITable.BackgroundColor = app.darkThemeColorBGContainers;
            app.languageDropDown.BackgroundColor = app.darkThemeColorBGContainers;
            app.refreshDBSourceButton.BackgroundColor = app.darkThemeColorBGContainers;

            app.applyChangesButton.FontColor = app.darkThemeFontColor;
            app.configureDBSourceButton.FontColor = app.darkThemeFontColor;
            app.dbSourceUITable.ForegroundColor = app.darkThemeFontColor;
            app.dbSourceUITableLabel.FontColor = app.darkThemeFontColor;
            app.languageDropDown.FontColor = app.darkThemeFontColor;
            app.languageDropDownLabel.FontColor = app.darkThemeFontColor;
            app.refreshDBSourceButton.FontColor = app.darkThemeFontColor;

            app.currentDBSourceEditField.BackgroundColor = app.darkThemeColorBGContainers;
            app.currentDBSourceEditFieldLabel.FontColor = app.darkThemeFontColor;
            app.currentDBSourceEditField.FontColor = app.darkThemeFontColor;

        end
        
        function [] = setLightTheme(app)
            %% Setting up color for light theme +
            app.UIFigure.Color = app.lightThemeColorUIF;

            app.applyChangesButton.BackgroundColor = app.lightThemeColorBGContainers;
            app.configureDBSourceButton.BackgroundColor = app.lightThemeColorBGContainers;
            app.dbSourceUITable.BackgroundColor = app.lightThemeColorBGContainers;
            app.languageDropDown.BackgroundColor = app.lightThemeColorBGContainers;
            app.refreshDBSourceButton.BackgroundColor = app.lightThemeColorBGContainers;

            app.applyChangesButton.FontColor = app.lightThemeFontColor;
            app.configureDBSourceButton.FontColor = app.lightThemeFontColor;
            app.dbSourceUITable.ForegroundColor = app.lightThemeFontColor;
            app.dbSourceUITableLabel.FontColor = app.lightThemeFontColor;
            app.languageDropDown.FontColor = app.lightThemeFontColor;
            app.languageDropDownLabel.FontColor = app.lightThemeFontColor;
            app.refreshDBSourceButton.FontColor = app.lightThemeFontColor;

            app.currentDBSourceEditField.BackgroundColor = app.lightThemeColorBGContainers;
            app.currentDBSourceEditFieldLabel.FontColor = app.lightThemeFontColor;
            app.currentDBSourceEditField.FontColor = app.lightThemeFontColor;
        end
        
        function setLanguage(app,languageValue)
            %% Loading language table
            app.currentLanguage = languageValue;
            component = app.configurationsLanguage.componentName;
            property = app.configurationsLanguage.propertyName;
            label = app.configurationsLanguage.(languageValue);
            
            %% Changing language
            for i = 1 : length(component)
                if(property{i} == "none")
                    app.(component{i}) = label{i};
                else
                    app.(component{i}).(property{i}) = label{i};
                end
            end
            
        end
        
        function refreshDBSources(app)
            %% Set items for database source list box
            dbSources = listDataSources;
            dbSources = dbSources(:,1:2);
            columnNames = {app.name,app.driverType};
            app.dbSourceUITable.ColumnName = columnNames;
            app.dbSourceUITable.Data = dbSources;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, theme, language, App, canModify)
            %% Initial settings
            
            if isdeployed
                app.originPath = fullfile(ctfroot,"login");
            else
                app.originPath = pwd;
            end
            
            %% Setting up language
            languagePath = fullfile(app.originPath,"language\configurationsLanguage.xlsx");
            app.configurationsLanguage = readtable(languagePath,"Sheet","language");
            app.setLanguage(language)
            app.newLanguage = language;
            
            %% Set theme
            if theme == 0
                app.setLightTheme
            else
                app.setDarkTheme
            end

            %% Load database source 
            try 
                dbSourcePath = fullfile(app.originPath,"settings","dbSource.mat");
                dbSourceStruct = load(dbSourcePath);
                app.dbSource = dbSourceStruct.dbSource;
                app.currentDBSourceEditField.Value = app.dbSource;
            catch 
                app.currentDBSourceEditField.Value = app.dbSourceNotAvailable;
            end
            
            %% Enable or disable data base source selection
            if canModify
                app.dbSourceUITable.Enable = "on";
                app.configureDBSourceButton.Enable = "on";
            else
                app.dbSourceUITable.Enable = "off";
                app.configureDBSourceButton.Enable = "off";
            end

            %% Get screen size
            screenSize = get(0,'ScreenSize');
            sWidth = screenSize(3)/2;
            sHeight = screenSize(4)/2;
            
            %% Center UIFigure
            fWidth = app.UIFigure.Position(3)/2;
            fHeight = app.UIFigure.Position(4)/2;
            app.UIFigure.Position(1:2) = [(sWidth-fWidth),(sHeight-fHeight)];
            
            %% Select language icon
            languagePath = fullfile(app.originPath,"language\configurationsLanguage.xlsx");
            app.items = readtable(languagePath,"Sheet","listItems");
            iconName = app.items.iconSource{app.items.English == language};
            iconPath = fullfile(app.originPath,iconName);
            app.Image.ImageSource = iconPath;

            %% Set items for the language drop down
            languageItems = app.items.(language);
            app.languageDropDown.Items = languageItems;
            languageValue = languageItems{app.items.English == language};
            app.languageDropDown.Value = languageValue;

            %% Set items for database source list box
            app.refreshDBSources
            
            %% Changing app name
            app.UIFigure.Name = app.appName;

            %% Interface main app and auxiliar app
            app.mainApp = App;
        end

        % Cell selection callback: dbSourceUITable
        function dbSourceUITableCellSelection(app, event)
            %% Select database source
            indices = event.Indices;
            app.dbSource = app.dbSourceUITable.Data.Name(indices(1));
        end

        % Button pushed function: applyChangesButton
        function applyChangesButtonPushed(app, event)
            %% Send data to main app
            updateConfigurations(app.mainApp,app.dbSource,app.newLanguage)
            delete(app)
        end

        % Value changed function: languageDropDown
        function languageDropDownValueChanged(app, event)
            value = app.languageDropDown.Value;
            value = convertCharsToStrings(value);
            app.newLanguage = app.items.English{app.items.(app.currentLanguage) == value};
            app.newLanguage = convertCharsToStrings(app.newLanguage);
        end

        % Button pushed function: configureDBSourceButton
        function configureDBSourceButtonPushed(app, event)
            configureODBCDataSource
        end

        % Button pushed function: refreshDBSourceButton
        function refreshDBSourceButtonPushed(app, event)
            app.dbSourceUITable.Data = [];
            pause(0.1)
            app.refreshDBSources
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'cogwheel-svgrepo-com.png');
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'icons', 'database-svgrepo-Config.svg');
            app.applyChangesButton = uibutton(app.UIFigure, 'push');
            app.applyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @applyChangesButtonPushed, true);
            app.dbSourceUITableLabel = uilabel(app.UIFigure);
            app.dbSourceUITable = uitable(app.UIFigure);
            app.dbSourceUITable.ColumnName = {'Column 1'; 'Column 2'};
            app.dbSourceUITable.RowName = {};
            app.dbSourceUITable.SelectionType = 'row';
            app.dbSourceUITable.CellSelectionCallback = createCallbackFcn(app, @dbSourceUITableCellSelection, true);
            app.dbSourceUITable.Multiselect = 'off';
            app.languageDropDownLabel = uilabel(app.UIFigure);
            app.languageDropDownLabel.Text = 'Drop Down';
            app.languageDropDown = uidropdown(app.UIFigure);
            app.languageDropDown.ValueChangedFcn = createCallbackFcn(app, @languageDropDownValueChanged, true);
            app.configureDBSourceButton = uibutton(app.UIFigure, 'push');
            app.configureDBSourceButton.ButtonPushedFcn = createCallbackFcn(app, @configureDBSourceButtonPushed, true);
            app.configureDBSourceButton.Text = 'Button2';
            app.refreshDBSourceButton = uibutton(app.UIFigure, 'push');
            app.refreshDBSourceButton.ButtonPushedFcn = createCallbackFcn(app, @refreshDBSourceButtonPushed, true);
            app.refreshDBSourceButton.Icon = fullfile(pathToMLAPP, 'icons', 'refresh-svgrepo-com.svg');
            app.refreshDBSourceButton.Text = '';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'icons', 'toolbox-svgrepo-com.svg');
            app.currentDBSourceEditFieldLabel = uilabel(app.UIFigure);
            app.currentDBSourceEditFieldLabel.Text = 'Edit Field';
            app.currentDBSourceEditField = uieditfield(app.UIFigure, 'text');
            app.currentDBSourceEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = configurations(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end