classdef customClock < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        timeLabel  matlab.ui.control.Label
        dateLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        backGroundColor (1,3) double {mustBeInRange(backGroundColor, 0,1,'inclusive')} = [0.94,0.94,0.94];
        fontColor (1,3) double {mustBeInRange(fontColor, 0,1,'inclusive')} = [0 0 0];
        fontSize (1,1) double {mustBePositive(fontSize)} = 24;
    end
    
    
    methods (Access = public)
        
        function [] = updateDateTime(comp,~,~)
            % Get current date and time
            date = char(datetime("now","Format","dd/MM/uuuu"));
            time = char(datetime("now","Format","HH:mm:ss aa"));
            % Display it on a label
            comp.dateLabel.Text = date;
            comp.timeLabel.Text = time;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
        
        comp.timeLabel.FontSize = comp.fontSize;
        comp.timeLabel.FontColor = comp.fontColor;
        comp.dateLabel.FontSize = comp.fontSize;
        comp.dateLabel.FontColor = comp.fontColor;
        
        % Starting a timer for every second to control date and time
        dateTimer = timer("Period",1,"TasksToExecute",Inf,...
            "ExecutionMode","fixedSpacing");
        % Runing the timer function (update date and time)
        dateTimer.TimerFcn = @comp.updateDateTime; 
        start(dateTimer)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            % Setting public properties

            % Font size and colors
            comp.timeLabel.FontSize = comp.fontSize;
            comp.timeLabel.FontColor = comp.fontColor;
            comp.dateLabel.FontSize = comp.fontSize;
            comp.dateLabel.FontColor = comp.fontColor;
    
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 320 90];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.dateLabel = uilabel(comp);
            comp.dateLabel.Text = 'dd/MM/yyyy';
            comp.timeLabel = uilabel(comp);
            comp.timeLabel.Text = 'HH:mm:ss aa';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end