classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Toolbar        matlab.ui.container.Toolbar
        infoTool       matlab.ui.container.toolbar.PushTool
        screenTool     matlab.ui.container.toolbar.ToggleTool
        themeTool      matlab.ui.container.toolbar.ToggleTool
        configTool     matlab.ui.container.toolbar.PushTool
        closeTool      matlab.ui.container.toolbar.PushTool
        capsLockImage  matlab.ui.control.Image
        Image4         matlab.ui.control.Image
        customClock    customClock
        TextArea       matlab.ui.control.TextArea
        TextAreaLabel  matlab.ui.control.Label
        logInButton    matlab.ui.control.Button
        passwordLabel  matlab.ui.control.Label
        userEditField  matlab.ui.control.EditField
        userLabel      matlab.ui.control.Label
        PasswordField  wt.PasswordField
        Image          matlab.ui.control.Image
    end

    
    properties (Access = private)
        %% Theme ,window and language control
        currentTheme = 0       % 0 for light theme and 1 for dark theme
        currentWindowState = 1 % 0 for maximized screen and 1 for fullscren
        currentLanguage = "Spanish"
        
        %% Dark and light themes colors
        darkThemeColorUIF =  [0,0,0]; 
        darkThemeColorToolBar = [15,15,15]/100;
        darkThemeColorBGLabels = "none";
        darkThemeColorBGContainers= [20,20,20]/100;
        darkThemeFontColor = [94,94,94]/100; 

        lightThemeColorUIF = [94, 94, 94]/100;
        lightThemeColorToolBar = '#3db52d';
        lightThemeColorBGLabels = "none";
        lightThemeColorBGContainers= [1,1,1];
        lightThemeFontColor = [0,0,0];

        %% Declaring properties to save labels and text
        infoToolMessage
        infoTitle

        credentialsInfo
        emptyUser
        emptyPassword
        emptyLogInTitle

        errorLogInTitle

        userError
        userErrorTitle
        passwordError
        passwordErrorTitle

        invalidCredentials
        invalidCredentialsTitle
        connectionError
        connectionErrorTitle

        errorMessage

        
        %% Dialog box app (configurations)
        configurationsApp

        %% Database properties
        dbSource
        dbUser
        dbPassword

        %% Control of language labels
        logInLanguage

        %% Origin paths for files
        originPath

        %% Properties for user access
        user
        password
        validatingInfo

        %% Connection to database
        conn
        dbSourceNotFound
        dbSourceNotFoundTitle
        dbLackOfPermits
        dbLackOfPermitsTitle

        %% Icon paths
        infoIconPath
        successIconPath
        warningIconPath
        errorIconPath


    end

    properties (SetAccess = immutable)
        %% SQL querys
        serverRoleMember = "SELECT IS_SRVROLEMEMBER('%s', '%s');"
        databaseReader = "SELECT IS_ROLEMEMBER ('db_datareader','%s');"
        databaseWriter = "SELECT IS_ROLEMEMBER ('db_datawriter','%s');"

        %% Configurations app control
        canModify = 1;
    end
    
    methods (Access = private)
        
        function setDarkTheme(app)
            %% Updating current theme an icon
            app.themeTool.State = "on";
            app.currentTheme = 1;
            iconPath = fullfile(app.originPath,"icons\sun-svgrepo-com-LC-White.svg");
            app.themeTool.Icon = iconPath;
            %% Obtaining all existing components by tags
            labels = findobj(app.UIFigure,'Tag','label');
            buttons = findobj(app.UIFigure,'Tag','button');
            efields = findobj(app.UIFigure,'Tag','efield');
            
            %% Changing colors for UIFigure,toolbar and clock
            app.UIFigure.Color = app.darkThemeColorUIF;
            app.Toolbar.BackgroundColor = app.darkThemeColorToolBar;
            app.customClock.BackgroundColor = app.darkThemeColorUIF;
            app.customClock.fontColor = app.darkThemeFontColor;

            %% Changing colors for components
            for i = 1 : length(labels)
                labels(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(buttons)
                buttons(i).FontColor = app.darkThemeFontColor;
                buttons(i).BackgroundColor = app.darkThemeColorBGContainers;
            end

            for i = 1 : length(efields)
                efields(i).BackgroundColor = app.lightThemeColorBGContainers;
            end


        end
        
        function [] = setLightTheme(app)
            %% Updating state, current theme an icon
            app.themeTool.State = "off";
            app.currentTheme = 0;
            iconPath = fullfile(app.originPath,"icons\moon-stars-svgrepo-com-LC-Black.svg");
            app.themeTool.Icon = iconPath;
            %% Obtaining all existing components by tags
            labels = findobj(app.UIFigure,'Tag','label');
            buttons = findobj(app.UIFigure,'Tag','button');
            efields = findobj(app.UIFigure,'Tag','efield');
            
            %% Changing colors for UIFigure,toolbar and clock
            app.UIFigure.Color = app.lightThemeColorUIF;
            app.Toolbar.BackgroundColor = app.lightThemeColorToolBar;
            app.customClock.BackgroundColor = app.lightThemeColorUIF;
            app.customClock.fontColor = app.lightThemeFontColor;

            %% Changing colors for components
            for i = 1 : length(labels)
                labels(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(buttons)
                buttons(i).FontColor = app.lightThemeFontColor;
                buttons(i).BackgroundColor = app.lightThemeColorBGContainers;
            end

            for i = 1 : length(efields)
                efields(i).BackgroundColor = app.lightThemeColorBGContainers;
            end
            
        end
        
        
        function setLanguage(app,languageValue)
            %% Loading language table
            app.currentLanguage = languageValue;
            component = app.logInLanguage.componentName;
            property = app.logInLanguage.propertyName;
            label = app.logInLanguage.(languageValue);
            
            %% Changing language
            for i = 1 : length(component)
                if(property{i} == "none")
                    app.(component{i}) = label{i};
                else
                    app.(component{i}).(property{i}) = label{i};
                end
            end
            
        end
        
        function saveSettings(app)
            %% Save settings 
            windowState = app.currentWindowState;
            theme = app.currentTheme;
            language = app.currentLanguage;
            lastSessionPath = fullfile(app.originPath,"settings","lastSessionSettings.mat");
            save(lastSessionPath,'windowState','theme','language')
        end
        
        
        function setUser(app,user)
            app.user = user;
        end
        
        function setPassword(app,password)
            app.password = password;
        end

        function user = getUser(app)
            user = app.user;
        end

        function password = getPassword(app)
            password = app.password;
        end

        
        function isMember = isServerRoleMember(app,serverRole,user)
            query = sprintf(app.serverRoleMember,serverRole,user);
            isMember = fetch(app.conn,query);
            isMember = logical(table2array(isMember));
        end
        
        function isMember = isReaderAndWriter(app,user)
            %% Check if user is data reader
            query = sprintf(app.databaseReader,user);
            isReader = fetch(app.conn,query);
            isReader = table2array(isReader);
            isReader(isnan(isReader)) = 0;

            %% Check if user is data writer
            query = sprintf(app.databaseWriter,user);
            isWriter = fetch(app.conn,query);
            isWriter = table2array(isWriter);
            isWriter(isnan(isWriter)) = 0;

            isMember = isReader && isWriter;
        end
        
        function setSettings(app,windowState,theme,language)
            % Setting up window state
            if windowState
                app.screenToolOn
            else
                app.screenToolOff
            end

            % Setting up theme
            if theme
                app.setDarkTheme
            else
                app.setLightTheme
            end
            
            % Setting up language
            app.setLanguage(language)
        end
        
        function getLastSessionSettings(app,path)
            lastSession = load(path);
            app.currentWindowState = lastSession.windowState;
            app.currentTheme = lastSession.theme;
            app.currentLanguage = lastSession.language;
            app.setSettings(app.currentWindowState,app.currentTheme,app.currentLanguage)
        end
    end
    
    methods (Access = public)
        
        function updateConfigurations(app,dbSource,language)
            app.setLanguage(language)

            if ~isempty(dbSource)
                app.dbSource = dbSource;
                dbSourcePath = fullfile(app.originPath,"settings","dbSource.mat");
                save(dbSourcePath,'dbSource');
            end

        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, windowState, theme, language)
            
            %% Initial settings
            if isdeployed
                app.originPath = fullfile(ctfroot,"login");
            else
                app.originPath = pwd;
            end

            %% Get icon paths
            app.infoIconPath = fullfile(app.originPath,"icons\sign-info-svgrepo-com.svg");
            app.successIconPath = fullfile(app.originPath,"icons\success-icon.svg");
            app.warningIconPath = fullfile(app.originPath,"icons\warning-svgrepo-com.svg");
            app.errorIconPath = fullfile(app.originPath,"icons\error-svgrepo-com.svg");

            %% Load language file
            languagePath = fullfile(app.originPath,"language\logInLanguage.xlsx");
            app.logInLanguage = readtable(languagePath); 

            try
                %% Starting app from another app
                app.setSettings(windowState,theme,language)
                app.saveSettings()
                sessionPath = fullfile(app.originPath,"settings","lastSessionSettings.mat");
            catch 
                %% Saving default settings 
                sessionPath = fullfile(app.originPath,"settings","lastSessionSettings.mat");
                
            end

            try
                %% Get last session settings
                app.getLastSessionSettings(sessionPath)
            catch
                %% Use default settings
                app.saveSettings()
                app.getLastSessionSettings(sessionPath)
            end

            %% Load database source
            try
                dbSourcePath = fullfile(app.originPath,"settings","dbSource.mat");
                dbSourceStruct = load(dbSourcePath);
                app.dbSource = dbSourceStruct.dbSource;
            catch
                uialert(app.UIFigure,app.dbSourceNotFound,app.dbSourceNotFoundTitle...
                    ,"Icon",app.infoIconPath)
            end
        end

        % Button pushed function: logInButton
        function logInButtonPushed(app, event)

            %% Get password and user
            userLogIn = app.getUser;
            passwordLogIn = app.getPassword;
            
            %% Creating a flag to control program execution, in case of lack
            % of information about user or password
            userEmptyFlag = 0;
            passwordEmptyFlag = 0;
            str = convertCharsToStrings(app.credentialsInfo);
            str = vertcat(str,"");


            %% Checking if user field is empty
            if isempty(userLogIn)
                str = vertcat(str,app.emptyUser);
                userEmptyFlag = 1;
            end
           
            %% Checking if password field is empty
            if isempty(passwordLogIn)
                str = vertcat(str,app.emptyPassword);
                passwordEmptyFlag = 1;
            end
            
            %% If either user field or password field are empty then stop
            % program execution
            if userEmptyFlag || passwordEmptyFlag
                uialert(app.UIFigure,str,app.emptyLogInTitle,"Icon",...
                    app.warningIconPath)
                return
            end

            progressBar = uiprogressdlg(app.UIFigure,"Indeterminate","on");
            progressBar.Message = app.validatingInfo;
            pause(1)
            
            %% Connect to database
            try
                app.conn = database(app.dbSource,app.user,app.password);
                %% Correct username and password
                if isempty(app.conn.Message)
                    isSysAdmin = app.isServerRoleMember("sysadmin",app.getUser);
                    % Open users management 
                    if isSysAdmin
                        delete(timerfindall)
                        usersManagement(app.currentWindowState,app.currentTheme,app.currentLanguage,app.conn)
                        delete(app)
                    else
                    % Check permissions    
                        isDBReaderAndWriter = app.isReaderAndWriter(app.getUser);
                        % Open select action app 
                        if isDBReaderAndWriter
                            delete(timerfindall)
                            selectAction(app.currentWindowState,app.currentTheme...
                                ,app.currentLanguage,app.conn)
                            delete(app)
                        % The user does not have permissions to access the software.    
                        else
                            uialert(app.UIFigure,app.dbLackOfPermits...
                                ,app.dbLackOfPermitsTitle,"Icon",app.errorIconPath)
                        end
                    end
                %% Incorrect username or password
                else
                    uialert(app.UIFigure,app.invalidCredentials,...
                        app.invalidCredentialsTitle,"Icon",app.errorIconPath)
                    app.setPassword('');
                    app.PasswordField.Value = app.getPassword;
                    app.setUser('')
                    app.userEditField.Value = app.getUser;
                end
            catch Me
                error = Me.message;
                error = append(app.errorMessage,error);
                str = vertcat(app.connectionError,"",error);
                uialert(app.UIFigure,str, app.connectionErrorTitle,"Icon",app.errorIconPath)
            end

            % app.TextArea.Value = DataHash(passwordLogIn,'HEX','SHA-512','ascii');
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %% Save settings for next boot
            app.saveSettings

            %% Delete timers and apps
            delete(app.configurationsApp)
            delete(timerfindall)
            delete(app)
        end

        % On callback: screenTool
        function screenToolOn(app, event)
            app.currentWindowState = 1;
            app.screenTool.State = "on";
            app.UIFigure.WindowState = "fullscreen";
        end

        % Off callback: screenTool
        function screenToolOff(app, event)
            app.currentWindowState = 0;
            app.screenTool.State = "off";
            app.UIFigure.WindowState = "maximized";
        end

        % On callback: themeTool
        function themeToolOn(app, event)
            app.setDarkTheme
        end

        % Off callback: themeTool
        function themeToolOff(app, event)
            app.setLightTheme
        end

        % Callback function: infoTool
        function infoToolClicked(app, event)
            uialert(app.UIFigure,app.infoToolMessage,app.infoTitle,"Icon",app.infoIconPath)
        end

        % Callback function: configTool
        function configToolClicked(app, event)
            app.configurationsApp = configurations(app.currentTheme...
                ,app.currentLanguage,login,app.canModify);
        end

        % Callback function: closeTool
        function closeToolClicked(app, event)
            app.UIFigureCloseRequest
        end

        % Value changed function: userEditField
        function userEditFieldValueChanged(app, event)
            value = app.userEditField.Value;
            app.setUser(value)
            app.TextArea.Value = ctfroot;
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            if key == "capslock" || key == "shift"
                app.capsLockImage.Visible = "on";
            end
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;
            if key == "capslock" || key == "shift"
                app.capsLockImage.Visible = "off";
            end
        end

        % Callback function: PasswordField
        function PasswordFieldValueChanging(app, event)
            changingValue = event.Value;
            app.setPassword(changingValue)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'logoLogin.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.Toolbar.Tag = 'toolBar';
            app.infoTool = uipushtool(app.Toolbar);
            app.infoTool.Tag = 'toolBar';
            app.infoTool.ClickedCallback = createCallbackFcn(app, @infoToolClicked, true);
            app.infoTool.Icon = fullfile(pathToMLAPP, 'icons', 'sign-info-svgrepo-com.svg');
            app.infoTool.Separator = 'on';
            app.screenTool = uitoggletool(app.Toolbar);
            app.screenTool.Tag = 'toolBar';
            app.screenTool.Icon = fullfile(pathToMLAPP, 'icons', 'full-screen-svgrepo-com.svg');
            app.screenTool.Separator = 'on';
            app.screenTool.OffCallback = createCallbackFcn(app, @screenToolOff, true);
            app.screenTool.OnCallback = createCallbackFcn(app, @screenToolOn, true);
            app.themeTool = uitoggletool(app.Toolbar);
            app.themeTool.Tag = 'toolBar';
            app.themeTool.Icon = fullfile(pathToMLAPP, 'icons', 'moon-stars-svgrepo-com-LC-Black.svg');
            app.themeTool.OffCallback = createCallbackFcn(app, @themeToolOff, true);
            app.themeTool.OnCallback = createCallbackFcn(app, @themeToolOn, true);
            app.configTool = uipushtool(app.Toolbar);
            app.configTool.ClickedCallback = createCallbackFcn(app, @configToolClicked, true);
            app.configTool.Icon = fullfile(pathToMLAPP, 'icons', 'cogwheel-svgrepo-com.svg');
            app.closeTool = uipushtool(app.Toolbar);
            app.closeTool.ClickedCallback = createCallbackFcn(app, @closeToolClicked, true);
            app.closeTool.Icon = fullfile(pathToMLAPP, 'icons', 'cross-close-svgrepo-com.svg');
            app.closeTool.Separator = 'on';
            app.Image = uiimage(app.UIFigure);
            app.Image.Tag = 'image';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'logoLogin.png');
            app.PasswordField = wt.PasswordField(app.UIFigure);
            app.PasswordField.ValueChangingFcn = createCallbackFcn(app, @PasswordFieldValueChanging, true);
            app.PasswordField.Tag = 'efield';
            app.userLabel = uilabel(app.UIFigure);
            app.userLabel.Tag = 'label';
            app.userLabel.Text = 'Usuario:';
            app.userEditField = uieditfield(app.UIFigure, 'text');
            app.userEditField.ValueChangedFcn = createCallbackFcn(app, @userEditFieldValueChanged, true);
            app.userEditField.Tag = 'efield';
            app.passwordLabel = uilabel(app.UIFigure);
            app.passwordLabel.Tag = 'label';
            app.passwordLabel.Text = 'Contraseña:';
            app.logInButton = uibutton(app.UIFigure, 'push');
            app.logInButton.ButtonPushedFcn = createCallbackFcn(app, @logInButtonPushed, true);
            app.logInButton.Tag = 'button';
            app.logInButton.Icon = fullfile(pathToMLAPP, 'icons', 'log-in-svgrepo-com.svg');
            app.logInButton.Text = 'Iniciar sesión';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Tag = 'label';
            app.TextAreaLabel.Visible = 'off';
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Tag = 'efield';
            app.TextArea.Visible = 'off';
            app.customClock = customClock(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.Tag = 'image';
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'icons', 'calendar-clock-svgrepo-com.svg');
            app.capsLockImage = uiimage(app.UIFigure);
            app.capsLockImage.Visible = 'off';
            app.capsLockImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'uppercase-svgrepo-com.svg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end