classdef recordPatient < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        infoTool                        matlab.ui.container.toolbar.PushTool
        screenTool                      matlab.ui.container.toolbar.ToggleTool
        themeTool                       matlab.ui.container.toolbar.ToggleTool
        configTool                      matlab.ui.container.toolbar.PushTool
        logoutTool                      matlab.ui.container.toolbar.PushTool
        closeTool                       matlab.ui.container.toolbar.PushTool
        mainMenuTool                    matlab.ui.container.toolbar.PushTool
        imagePreviewTool                matlab.ui.container.toolbar.PushTool
        contactInformationPanel         matlab.ui.container.Panel
        addressTextArea                 matlab.ui.control.TextArea
        addressTextAreaLabel            matlab.ui.control.Label
        Image5_3                        matlab.ui.control.Image
        Image5_2                        matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        postalCodeEditField             matlab.ui.control.NumericEditField
        postalCodeEditFieldLabel        matlab.ui.control.Label
        emailEditField                  matlab.ui.control.EditField
        emailEditFieldLabel             matlab.ui.control.Label
        phoneNumberEditField            matlab.ui.control.EditField
        phoneNumberEditFieldLabel       matlab.ui.control.Label
        saveButton                      matlab.ui.control.Button
        patientRecordLabel              matlab.ui.control.Label
        clinicHistoryPanel              matlab.ui.container.Panel
        Image4_4                        matlab.ui.control.Image
        Image4_3                        matlab.ui.control.Image
        Image4_2                        matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        physicalExplorationTextArea     matlab.ui.control.TextArea
        physicalExplorationTextAreaLabel  matlab.ui.control.Label
        phatologicalPersonalHistoryTextArea  matlab.ui.control.TextArea
        phatologicalPersonalHistoryTextAreaLabel  matlab.ui.control.Label
        nonPhatologicalPersonalHistoryTextArea  matlab.ui.control.TextArea
        nonPhatologicalPersonalHistoryTextAreaLabel  matlab.ui.control.Label
        inheritedPhatologicalHistoryTextArea  matlab.ui.control.TextArea
        inheritedPhatologicalHistoryTextAreaLabel  matlab.ui.control.Label
        idivPanel                       matlab.ui.container.Panel
        createdAtEditField              matlab.ui.control.EditField
        createdAtEditFieldLabel         matlab.ui.control.Label
        idEditField                     matlab.ui.control.EditField
        idEditFieldLabel                matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        generalInformationPanel         matlab.ui.container.Panel
        Image_8                         matlab.ui.control.Image
        Image_7                         matlab.ui.control.Image
        Image_6                         matlab.ui.control.Image
        curpCheckBox                    matlab.ui.control.CheckBox
        curpEditField                   matlab.ui.control.EditField
        curpEditFieldLabel              matlab.ui.control.Label
        placeOfResidenceDropDown        matlab.ui.control.DropDown
        placeOfResidenceDropDownLabel   matlab.ui.control.Label
        sLastNameCheckBox               matlab.ui.control.CheckBox
        ageEditField                    matlab.ui.control.NumericEditField
        ageEditFieldLabel               matlab.ui.control.Label
        Image_5                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        bloodTypeDropDown               matlab.ui.control.DropDown
        bloodTypeDropDownLabel          matlab.ui.control.Label
        indigenousLanguageDropDown      matlab.ui.control.DropDown
        indigenousLanguageDropDownLabel  matlab.ui.control.Label
        indigenousLanguageSpeakerCheckBox  matlab.ui.control.CheckBox
        placeOfBirthDropDown            matlab.ui.control.DropDown
        placeOfBirthDropDownLabel       matlab.ui.control.Label
        nationalityDropDown             matlab.ui.control.DropDown
        nationalityDropDownLabel        matlab.ui.control.Label
        birthDatePicker                 matlab.ui.control.DatePicker
        birthDatePickerLabel            matlab.ui.control.Label
        biologicalSexDropDown           matlab.ui.control.DropDown
        biologicalSexDropDownLabel      matlab.ui.control.Label
        sLastNameEditField              matlab.ui.control.EditField
        sLastNameEditFieldLabel         matlab.ui.control.Label
        fLastNameEditField              matlab.ui.control.EditField
        fLastNameEditFieldLabel         matlab.ui.control.Label
        nameEditField                   matlab.ui.control.EditField
        nameEditFieldLabel              matlab.ui.control.Label
        clockImage                      matlab.ui.control.Image
        customClock                     customClock
        selectFilesPanel                matlab.ui.container.Panel
        fileSelectorListBox             matlab.ui.control.ListBox
        bodyPartExaminedEditField       matlab.ui.control.EditField
        bodyPartExaminedEditFieldLabel  matlab.ui.control.Label
        studyDescriptionEditField       matlab.ui.control.EditField
        studyDescriptionEditFieldLabel  matlab.ui.control.Label
        studyDateEditField              matlab.ui.control.EditField
        studyDateEditFieldLabel         matlab.ui.control.Label
        modalityEditField               matlab.ui.control.EditField
        modalityEditFieldLabel          matlab.ui.control.Label
        allImagePreviewButton           matlab.ui.control.Button
        imagePreviewButton              matlab.ui.control.Button
        addFileButton                   matlab.ui.control.Button
        filesToIncludeLabel             matlab.ui.control.Label
        fileExplorerLabel               matlab.ui.control.Label
        deleteAllFilesButton            matlab.ui.control.Button
        deleteFileButton                matlab.ui.control.Button
        fileSelectorButton              matlab.ui.control.Button
        fileSelectorEditField           matlab.ui.control.EditField
        Image3                          matlab.ui.control.Image
    end

    
    properties (Access = private)
        %% Theme ,window and language control
        currentTheme           % 0 for light theme and 1 for dark theme
        currentWindowState     % 0 for maximized screen and 1 for fullscren
        currentLanguage

        %% File selector control
        fileToDelete

        %% Declaring porperties to save text messages
        excludeFileTitle
        excludeFileMessage
        TeXFormat
        infoToolMessage
        infoToolTitle
        toGenerate
        toGenerateTip
        
        %% Setting colors for dark theme and light theme
        figureColor

        darkThemeColorUIF =  [0,0,0]; 
        darkThemeColorToolBar = [15,15,15]/100;
        darkThemeColorBGLabels = "none";
        darkThemeColorBGContainers= [12,12,12]/100;
        darkThemeColorBGPanels = [7,7,7]/100;
        darkThemeFontColor = [94,94,94]/100;

        lightThemeColorUIF = [94, 94, 94]/100;
        lightThemeColorToolBar = '#3db52d';
        lightThemeColorBGLabels = "none";
        lightThemeColorBGContainers= [1,1,1];
        lightThemeColorBGPanels = [90,90,90]/100;
        lightThemeFontColor = [0,0,0];  

        %% Configurations app control
        configurationsApp

        %% Database control
        dbSource
        conn

        %% institution name 
        institutionName

        %% Declaring variables to save nationalities, indigenous languages, federal entities
        % blood type and sex
        nationalities 
        indigenousLanguages 
        federalEntities 
        bloodTypes
        biologicalSexItems 


        %% Declaring properties to save patient information
        address
        age
        birthDate
        birthEntityKey
        biologicalSex
        bloodType
        curp
        email
        firstLastName
        indigenousLanguage
        inheritedPhatologicalHistory
        name
        nationalityKey
        nonPhatologicalPersonalHistory
        residenceEntityKey
        secondLastName
        patientID 
        phoneNumber
        phatologicalPersonalHistory
        physicalExploration
        postalCode

        %% Declaring error flags
        errorFlags
        emptyFieldsMessage
        emptyFieldsTitle

        %% UI components
        buttons
        checkBoxes 
        dropDowns 
        efields 
        labels 
        listBoxes 
        nefields
        panels 
        textAreas

        %% Control for image preview
        previewNotAvailableMessage
        allPreviewAvailableMessage

        %% previewImageMessage
        figureForPreview
        figurePreviewTitle

        %% DICOM control properties
        dicomLoadError
        dicomLoadErrorTitle
        fieldsToExtract
        dicomFieldNotAvailable

        %% Control of language labels
        recordPatientLanguage

        %% Origin paths for files
        originPath

        %% Progress bar messages
        progressBarTitle
        validationProgress
        cipheringProgress
        imageCipheringProgress
        connectionProgress
        saveProgress
        saveSuccess
        saveError
        saveErrorTitle

        %% Icon paths
        infoIconPath
        successIconPath
        warningIconPath
        errorIconPath


    end

    properties (SetAccess = immutable)
        checkIC = "SELECT init_counter FROM identification_data WHERE init_counter = %s"
        getPatientIndex = "SELECT patient_index FROM admins WHERE username = '%s'"
        getAdminID = "SELECT admin_id FROM admins WHERE username = '%s'"
        increaseIndex = "UPDATE admins " + ...
            "SET patient_index = patient_index + 1 " + ...
            "WHERE username = '%s' " 

        %% Configurations app control
        canModify = 0
    end
    
    methods (Access = private)
        
        function setDarkTheme(app)
            
            %% Updating current theme an icon
            app.themeTool.State = "on";
            app.figureColor = [0,0,0];
            app.currentTheme = 1;
            iconPath = fullfile(app.originPath,"icons\sun-svgrepo-com-LC-White.svg");
            app.themeTool.Icon = iconPath;
            
            %% Changing colors 
            app.UIFigure.Color = app.darkThemeColorUIF;
            app.Toolbar.BackgroundColor = app.darkThemeColorToolBar;
            app.customClock.BackgroundColor = app.darkThemeColorUIF;
            app.customClock.fontColor = app.darkThemeFontColor;
            app.birthDatePicker.BackgroundColor = app.darkThemeColorBGContainers;
            app.birthDatePicker.FontColor = app.darkThemeFontColor;
            app.ageEditField.BackgroundColor = app.darkThemeColorBGContainers;
            app.ageEditField.FontColor = app.darkThemeFontColor;
            
            for i = 1 : length(app.buttons)
                app.buttons(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.buttons(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.checkBoxes)
                app.checkBoxes(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.dropDowns)
                app.dropDowns(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.dropDowns(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.efields)
                app.efields(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.efields(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.labels)
                app.labels(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.listBoxes)
                app.listBoxes(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.listBoxes(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.nefields)
                app.nefields(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.nefields(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.panels)
                app.panels(i).BackgroundColor = app.darkThemeColorBGPanels;
                app.panels(i).ForegroundColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.textAreas)
                app.textAreas(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.textAreas(i).FontColor = app.darkThemeFontColor;
            end
        end
        
        function setLightTheme(app)
            %% Updating state, current theme an icon
            app.themeTool.State = "off";
            app.figureColor = [1,1,1];
            app.currentTheme = 0;
            iconPath = fullfile(app.originPath,"icons\moon-stars-svgrepo-com-LC-Black.svg");
            app.themeTool.Icon = iconPath;

            %% Changing colors
            app.UIFigure.Color = app.lightThemeColorUIF;
            app.Toolbar.BackgroundColor = app.lightThemeColorToolBar;
            app.customClock.BackgroundColor = app.lightThemeColorUIF;
            app.customClock.fontColor = app.lightThemeFontColor;
            app.birthDatePicker.BackgroundColor = app.lightThemeColorBGContainers;
            app.birthDatePicker.FontColor = app.lightThemeFontColor;
            app.ageEditField.BackgroundColor = app.lightThemeColorBGContainers;
            app.ageEditField.FontColor = app.lightThemeFontColor;
            
            for i = 1 : length(app.buttons)
                app.buttons(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.buttons(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.checkBoxes)
                app.checkBoxes(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.dropDowns)
                app.dropDowns(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.dropDowns(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.efields)
                app.efields(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.efields(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.labels)
                app.labels(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.listBoxes)
                app.listBoxes(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.listBoxes(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.nefields)
                app.nefields(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.nefields(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.panels)
                app.panels(i).BackgroundColor = app.lightThemeColorBGPanels;
                app.panels(i).ForegroundColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.textAreas)
                app.textAreas(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.textAreas(i).FontColor = app.lightThemeFontColor;
            end
        end
        
        function setLanguage(app,languageValue)
            
            %% Loading language table
            app.currentLanguage = languageValue;
            component = app.recordPatientLanguage.componentName;
            property = app.recordPatientLanguage.propertyName;
            label = app.recordPatientLanguage.(languageValue);
            
            %% Changing language
            app.errorFlags = [];
            for i = 1 : length(component)
                if(property{i} == "none")
                    app.(component{i}) = label{i};
                elseif (property{i} == "errorFlag")
                    app.errorFlags = [app.errorFlags,label(i)];
                else
                    app.(component{i}).(property{i}) = label{i};
                end
            end

            %% Setting biological sex drop down items for each language
            app.biologicalSexDropDown.Items = app.biologicalSexItems.(languageValue);
            biologicalSexForLanguage = convertCharsToStrings(app.biologicalSexItems.(languageValue));
            app.biologicalSexDropDown.Value = biologicalSexForLanguage(convertCharsToStrings(app.biologicalSexItems.key)...
                == string(app.biologicalSex));

            %% Setting nationality drop down items for each language
            nationalityForLanguage = app.nationalities.(languageValue);
            nationalityForLanguage = sortrows(nationalityForLanguage);
            app.nationalityDropDown.Items = nationalityForLanguage;
            
            %% Updating value of nationality dropdown to its equivalent in the corresponding language
            nationality = app.getNationalityFromKey(app.nationalityKey);
            app.nationalityDropDown.Value = nationality;

            %% Setting drop down items for place of birth and place of residence
            app.placeOfBirthDropDown.Items = app.federalEntities.(languageValue);
            app.placeOfResidenceDropDown.Items = app.federalEntities.(languageValue);

            %% Updating value of place of birth dropdown to its equivalent in the corresponding language
            app.placeOfBirthDropDown.Value = app.getFederalEntityFromKey(app.birthEntityKey);

            %% Show ID and Creation date messages and tooltip
            app.idEditField.Value = app.toGenerate;
            app.idEditField.Tooltip = app.toGenerateTip;
            app.createdAtEditField.Value = app.toGenerate;
            app.createdAtEditField.Tooltip = app.toGenerateTip;


        end
        
        function key = getKeyFromNationality(app,nationality)
            %% Search nationality key using a nationality string in any language available
            nationality = convertCharsToStrings(nationality);
            key = app.nationalities.nationalityKey{app.nationalities.(app.currentLanguage) == nationality};
            key = convertStringsToChars(key);
        end
        
        function nationality = getNationalityFromKey(app,key)
            %% Search nationality using a nationality key string in any language available
            key = convertCharsToStrings(key);
            nationalityLanguage = app.nationalities.(app.currentLanguage);
            nationality = nationalityLanguage(app.nationalities.nationalityKey == key);
            nationality = convertStringsToChars(nationality);
        end
        
        function countryCode = getCountryCodeFromKey(app,key)
            %% Search country code using a nationality key string in any language available
            countryCode = app.nationalities.countryCode(app.nationalities.nationalityKey == key);
        end
        
        function federalEntity = getFederalEntityFromKey(app,key)
            %% Search federal entity using a entity key string in any language available
            key = convertCharsToStrings(key);
            stateLanguage = app.federalEntities.(app.currentLanguage);
            federalEntity = stateLanguage(app.federalEntities.stateKey == key);
            federalEntity = convertStringsToChars(federalEntity);
        end
        
        function key = getKeyFromFederalEntity(app,federalEntity)
            %% Search entity key using a federal entity string in any language available
            federalEntity = convertCharsToStrings(federalEntity);
            key = app.federalEntities.stateKey(app.federalEntities.(app.currentLanguage)== federalEntity);
            key = convertStringsToChars(key);
        end
        
        function closeFigureForPreview(app,~,~)
            %% Disable image preview tool and delete figure for preview
            app.imagePreviewTool.Enable = "off";
            app.imagePreviewTool.Visible = "off";
            delete(app.figureForPreview)
        end
        
        function createFigureForPreview(app,~,~)
            %% Enable image preview tool
            app.imagePreviewTool.Enable = "on";
            app.imagePreviewTool.Visible = "on";
        end
        
        function  value = isDicom(~,path)
            %% Check if current file is DICOM format
            try
                dicomData = dicominfo(path);
                formatExists = isfield(dicomData,"Format");
                if formatExists
                    format = dicomfind(dicomData,"Format");
                    %% It is dicom
                    if format.Value == "DICOM"
                       value = 1; 
                    else
                        %% Error occurred so it is not dicom or is an incompatible file
                        value = 0;
                        return
                    end
                end
            catch
                %% Error 
                value = 0;
            end
            
        end
        
        function [excludedFiles,includedFiles] = checkDicom(app,path,files)
            paths = fullfile(path,files);
            if iscell(files)
                isDicomArray = zeros(1,length(files));
                
                for i = 1 : length(files)
                    isDicomArray(i) = app.isDicom(paths{i});
                end
    
                isDicomArray = logical(isDicomArray);
                
                excludedFiles = paths(~isDicomArray)';
                includedFiles = paths(isDicomArray)';
            else
                if files == 0
                    includedFiles = '';
                    excludedFiles = '';
                    return
                else
                    if app.isDicom(paths)
                        includedFiles = paths;
                        excludedFiles = '';
                    else
                        includedFiles = '';
                        excludedFiles = paths;
                    end
                end
            end
        end
        
        function includeFiles(app,includedFiles)
            
            %% No files to include
            if isempty(includedFiles)
                return
            else
                if iscell(includedFiles)
                    %% Include files and update file to delete
                    lastPath = includedFiles{end};
                    app.fileSelectorEditField.Value = lastPath;
                    app.fileToDelete = lastPath;

                    app.fileSelectorListBox.Items = includedFiles;
                else
                    %% Include file and update file to delete
                    lastPath = includedFiles;
                    app.fileSelectorEditField.Value = lastPath;
                    app.fileToDelete = lastPath;

                    app.fileSelectorListBox.Items = {includedFiles};
                end
                

            end
        end
        
        function showExcludedFiles(app,excludedFiles)
            if isempty(excludedFiles)
                return
            else
                str = append(app.TeXFormat," ",excludedFiles);
                str = vertcat(app.dicomLoadError,"",str);
                uialert(app.UIFigure,str,app.excludeFileTitle,"Icon",app.errorIconPath ...
                    ,"Interpreter","tex")
            end
            
        end
        
        function information = findDicomData(app,dicomData)
            %% Pre allocate obtained information cell array
            fieldsToExtractLen = length(app.fieldsToExtract.fields);
            information = cell(1,fieldsToExtractLen);

            %% If field exists then get the value 
            for i = 1 : fieldsToExtractLen
                if isfield(dicomData,app.fieldsToExtract.fields{i})
                    data = dicomfind(dicomData,app.fieldsToExtract.fields{i});
                    information{i} = data.Value{1};
                else
                    information{i} = 'NE';
                    continue
                end
            end
        end
        
        function displayDicomInfo(app,dicomData)
            %% Get dicom data
            extractedFields = app.findDicomData(dicomData);
            emptyFields = cellfun(@(x) string(x) == "NE",extractedFields,'UniformOutput',true);
            extractedFields{emptyFields} = app.dicomFieldNotAvailable;
            
            %% Format date
            date = extractedFields{2};
            date = [date(1:end-4),'/',date(end-3:end-2),'/',date(end-1:end)];

            %% Enable labels and fields to show information
            app.modalityEditField.Enable = "on";
            app.studyDateEditField.Enable = "on";
            app.studyDescriptionEditField.Enable = "on";
            app.modalityEditFieldLabel.Enable = "on";
            app.studyDateEditFieldLabel.Enable = "on";
            app.studyDescriptionEditFieldLabel.Enable = "on";
            app.bodyPartExaminedEditField.Enable = "on";
            app.bodyPartExaminedEditFieldLabel.Enable = "on";

            %% Show information
            app.modalityEditField.Value = extractedFields{1};
            app.studyDateEditField.Value = date;
            app.studyDescriptionEditField.Value = extractedFields{3};
            app.bodyPartExaminedEditField.Value = extractedFields{4};
        end
        
        function disableDicomInfoFields(app)
            %% Disable labels and fields to show information
            app.modalityEditField.Enable = "off";
            app.studyDateEditField.Enable = "off";
            app.studyDescriptionEditField.Enable = "off";
            app.modalityEditFieldLabel.Enable = "off";
            app.studyDateEditFieldLabel.Enable = "off";
            app.studyDescriptionEditFieldLabel.Enable = "off";
            app.bodyPartExaminedEditField.Enable = "off";
            app.bodyPartExaminedEditFieldLabel.Enable = "off";

            %% delete information
            app.modalityEditField.Value = "";
            app.studyDateEditField.Value = "";
            app.studyDescriptionEditField.Value = "";
            app.bodyPartExaminedEditField.Value = "";
        end
        
        function validateInformation(app)
            %% Create a progress bar 
            progressBar = uiprogressdlg(app.UIFigure,'Title',app.progressBarTitle,'Indeterminate','on');
            progressBar.Message = app.validationProgress;
            pause(1)
            
            %% Flag for encryption control 
            emptyFieldFlag = false;

            %% Collect all information from general information panel and contact information panel
            generalInformation = {app.name,app.firstLastName,app.secondLastName,...
                app.biologicalSex,app.bloodType,app.birthDate,app.nationalityKey,...
                app.birthEntityKey,app.residenceEntityKey,app.curp,app.indigenousLanguage,...
                app.postalCode,app.address,app.phoneNumber,app.email};
            

            %% Collect all information from clinic history panel
            clinicHistoryData = {app.inheritedPhatologicalHistory,app.nonPhatologicalPersonalHistory,...
                app.phatologicalPersonalHistory,app.physicalExploration};

            %% Collect all mandatory information (imaging studies are optional)
            patientInfo = [generalInformation,clinicHistoryData];

            %% Check if any mandatory field is empty 
            emptyFieldStr = cell(1,length(patientInfo));
            for i = 1 : length(patientInfo)
                if isempty(patientInfo{i})
                    emptyFieldFlag = true;
                    emptyFieldStr{i} = app.errorFlags{i};
                else
                    emptyFieldStr{i} = '';
                end
            end

            %% Save all field names that are empty and delete all field names that are filled
            emptyFieldStr(cellfun(@isempty,emptyFieldStr)) = [];
            
            if emptyFieldFlag
                %% Show all fields that are empty using an uialert and stop execution
                emptyFieldStr = sortrows(table(emptyFieldStr'));
                emptyFieldStr = table2cell(emptyFieldStr);
                emptyFieldStr = convertCharsToStrings(emptyFieldStr);
                emptyFieldStr = append(app.TeXFormat," ",emptyFieldStr);
                emptyFieldStr = vertcat(app.emptyFieldsMessage,"",emptyFieldStr);
                uialert(app.UIFigure,emptyFieldStr,app.emptyFieldsTitle,"Icon"...
                    ,app.warningIconPath,"Interpreter","tex")
                return
            else
                key = 1:16;
                progressBar.Message = app.cipheringProgress;
                %% Generate patient ID
                app.patientID = char(app.generatePatientID());

                %% Generate initial counter
                initCounter = uint8(app.genInitCounter());
                
                %% Append record creation date
                createdAt = char(datetime);
                generalInformation = [generalInformation,createdAt];

                %% Data has been validated , ciphering and generating tables
                % For identification data 
                [clinicHistoryInitCounter,identificationData] = app.cipherGeneralInformation(generalInformation...
                    ,key,initCounter);

                clinicHistoryInitCounter = uint8(clinicHistoryInitCounter{1});
                
                % For clinic history
                [imagingStudiesInitCounter,clinicHistoryData] = app.cipherClinicHistory(clinicHistoryData...
                    ,key,clinicHistoryInitCounter);

                imagingStudiesInitCounter = uint8(imagingStudiesInitCounter{1});

                writeImagesFlag = false;
                emptyImageFlag = isempty(app.fileSelectorListBox.Items);
                % For imaging studies
                if ~emptyImageFlag
                    %% Cipher images
                    progressBar.Message = app.imageCipheringProgress;
                    imagePaths = app.fileSelectorListBox.Items;
                    [~,imagingStudies] = app.cipherImagingStudies(imagePaths...
                        ,key,imagingStudiesInitCounter);
                    writeImagesFlag = true;
                end

                %% Saving data into database
                progressBar.Message = app.connectionProgress;
                
                try
                    progressBar.Message = app.saveProgress;
                    %% Write data into sql server
                    sqlwrite(app.conn,'identification_data',identificationData);
                    sqlwrite(app.conn,'clinic_history',clinicHistoryData);
                    if writeImagesFlag
                        sqlwrite(app.conn,'imaging_studies',imagingStudies);
                    end
                    %% Increase patient index
                    app.increasePatientIndex();
                    uialert(app.UIFigure,app.saveSuccess,'Correcto'...
                        ,'Icon',app.successIconPath,'CloseFcn',@app.viewRecord)
                catch 
                    uialert(app.UIFigure,app.saveError,app.saveErrorTitle...
                        ,"Icon",app.errorIconPath)
                end
                

            end

        end
        
        function  [lastCounter,dataToSave] = cipherGeneralInformation(app,generalInformation,key,initCounter)
            %% Cipher data
            [cipheredData,counters] = cipherCellArray(generalInformation,key,initCounter);
            
            generalInfoInitCounter = counters(1);

            %% Save counter for clinic history table 
            lastCounter = counters(end);

            counters = getByteStreamFromArray(counters);
            generalInformationData = [{app.patientID},generalInfoInitCounter,...
                counters,cipheredData];
            
            colnames = {'patient_id','init_counter','init_counters','name',...
                'last_name','second_last_name','biological_sex','blood_type',...
                'birth_date','nationality_key','place_of_birth_key'...
                ,'place_of_residence_key','curp','indigenous_language'...
                ,'postal_code','address_value','phone_number','email','created_at'};

            dataToSave = array2table(generalInformationData,'VariableNames',colnames);
        
        end
        
        function [lastCounter,dataToSave] = cipherClinicHistory(app,clinicHistory,key,initCounter)
            %% Cipher data
            [cipheredData,counters] = cipherCellArray(clinicHistory,key,initCounter);
            
            clinicHistoryInitCounter = counters(1);

            %% Save counter for imaging studies
            lastCounter = counters(end);

            counters = getByteStreamFromArray(counters);
            clinicHistoryData = [{app.patientID},clinicHistoryInitCounter,...
                counters,cipheredData];

            colnames = {'history_id','init_counter','init_counters',...
                'inherited_phatological_history','pathological_personal_history',...
                'non_pathological_personal_history','physical_exploration'};

            dataToSave = array2table(clinicHistoryData,'VariableNames',colnames);
        end
        
        function initCounter = genInitCounter(app)
            %% Generate initial counter
            rng("shuffle")
            initCounter = randi(255,1,16);

            %% Check if initial counter already exists in database
            hexCounter = reshape(dec2hex(initCounter)',1,[]);
            hexCounter = append('0x',hexCounter);
            
            query = sprintf(app.checkIC,hexCounter);
            results = fetch(app.conn,query);

            if isempty(results)
                return
            else
                %% Repeat until initial counter uniqueness
                while ~isempty(results)
                    rng("shuffle")
                    initCounter = randi(255,1,16);
                    hexCounter = reshape(dec2hex(initCounter)',1,[]);
                    hexCounter = append('0x',hexCounter);
                    query = sprintf(app.checkIC,hexCounter);
                    results = fetch(app.conn,query);
                end
            end
        end
        
        function ID = generatePatientID(app)
            adminUserName = app.conn.UserName;
            
            %% Get patient index
            query = sprintf(app.getPatientIndex,adminUserName);
            patientIndex = fetch(app.conn,query);
            patientIndex = string(table2array(patientIndex));

            %% Get admin ID
            query = sprintf(app.getAdminID,adminUserName);
            adminID = fetch(app.conn,query);
            adminID = table2array(adminID);
            adminID = dec2hex(adminID,4);

            %% Get creation year
            creationYear = string(year(datetime()));

            %% Create patient ID
            ID = sprintf("%04s%05s%04s%s",adminID,patientIndex,...
                creationYear,app.institutionName);            
        end
        
        function loadInstitutionName(app)
            try %% Load file
                path = fullfile(app.originPath,"settings","institutionName.mat");
                institution = load(path);
                app.institutionName = institution.institutionNameSaved;
            catch %% Create file with default institution name (IIIII)
                path = fullfile(app.originPath,"settings","institutionName.mat");
                institutionNameSaved = "IIIII";
                save(path,"institutionNameSaved")
            end
        end
        
        function increasePatientIndex(app)
            adminUserName = app.conn.UserName;
            query = sprintf(app.increaseIndex,adminUserName);
            fetch(app.conn,query);
        end
        
        function saveSettings(app)
            %% Save settings 
            windowState = app.currentWindowState;
            theme = app.currentTheme;
            language = app.currentLanguage;
            lastSessionPath = fullfile(app.originPath,"settings","lastSessionSettings.mat");
            save(lastSessionPath,'windowState','theme','language')
        end
        
        function [lastCounter,dataToSave] = cipherImagingStudies(app,imagePaths,...
                key,initCounter)
            initCounter = uint8(initCounter);
            app.patientID = char(app.generatePatientID());
            [~,fileNames,~] = fileparts(imagePaths);
            
            colNames = {'patient_id','init_counter','init_counters','modality',...
                'study_date','study_description','body_part_examined',...
                'image_study_name','dicom_metadata','image_data'};
            dataToSave = cell(length(imagePaths),length(colNames));
            dataToSave = cell2table(dataToSave,"VariableNames",colNames);
            disp(dataToSave)

            for i = 1 : length(imagePaths)
                %% Get dicom meta data and image data
                dicomMetadata = dicominfo(imagePaths{i});
                imageData = dicomread(imagePaths{i});
                imageData = getByteStreamFromArray(imageData);
                extractedData = app.findDicomData(dicomMetadata);

                %% Cipher data
                dataToCipher = [extractedData,fileNames(i),dicomMetadata];
                [cipheredData,counters,lastCounter] = cipherCellArray(dataToCipher...
                    ,key,initCounter);
                initCounter = counters(1);
                disp(cipheredData)
                counters = {getByteStreamFromArray(counters)};
                dataToSave(i,:) = array2table([{app.patientID},initCounter,counters,...
                    cipheredData,imageData]);
                initCounter = lastCounter;
            end
            
        end
        
        function viewRecord(app,~,~)
            %% Go to view record app
            viewRecordV2(app.currentWindowState,app.currentTheme,app.currentLanguage,...
                app.conn,app.patientID)
            delete(app)
        end
    end
    
    methods (Access = public)
        
        function updateConfigurations(app,dbSource,language)
            %% Update database source and language 
            app.dbSource = dbSource;
            app.setLanguage(language)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, windowState, theme, language, connection)
            %% Initial settings
            if isdeployed
                app.originPath = fullfile(ctfroot,"login");
            else
                app.originPath = pwd;
            end

            %% Get icon paths
            app.infoIconPath = fullfile(app.originPath,"icons\sign-info-svgrepo-com.svg");
            app.successIconPath = fullfile(app.originPath,"icons\success-icon.svg");
            app.warningIconPath = fullfile(app.originPath,"icons\warning-svgrepo-com.svg");
            app.errorIconPath = fullfile(app.originPath,"icons\error-svgrepo-com.svg");

            %% get connection object
            app.conn = connection;

            %% Load language file
            languagePath = fullfile(app.originPath,"language\recordPatientLanguage.xlsx");
            app.recordPatientLanguage = readtable(languagePath); 

            %% Load catalogs and variables
            nationalitiesPath = fullfile(app.originPath,"catalogs\CAT_NATIONALITIES.xlsx");
            app.nationalities = readtable(nationalitiesPath);

            indigenousLanguagePath = fullfile(app.originPath,"catalogs\INDIGENOUS_LANGUAGE_2018.xlsx");
            app.indigenousLanguages = readtable(indigenousLanguagePath);
            
            federalEntitiesPath = fullfile(app.originPath,"catalogs\FEDERAL_ENTITIES_201602.xlsx");
            app.federalEntities = readtable(federalEntitiesPath);
    
            bloodTypePath = fullfile(app.originPath,"settings","bloodTypes.mat");
            app.bloodTypes = load(bloodTypePath);

            biologicalSexPath = fullfile(app.originPath,"language\biologicalSex.xlsx");
            app.biologicalSexItems = readtable(biologicalSexPath);
            
            fieldsToExtractPath = fullfile(app.originPath,"settings","dicomFieldsToExtract.mat");
            app.fieldsToExtract = load(fieldsToExtractPath);
            
            
            %% Setting up place of birth, nationality place of residence and biological sex to default values
            app.birthEntityKey = '00';
            app.biologicalSex = 'M';
            app.bloodType = 'A+';
            app.indigenousLanguage = 'NA';
            app.nationalityKey = 'MEX';
            app.residenceEntityKey = '00';


            %% Setting up language
            app.setLanguage(language)
            
            %% Adjusting window state from last app 

            if(windowState)
                app.screenToolOn;
            else
                app.screenToolOff;
            end

            %% Obtaining ui componentes
            app.buttons = findobj(app.UIFigure,'Type','uibutton');
            app.checkBoxes = findobj(app.UIFigure,'Type','uicheckbox');
            app.dropDowns = findobj(app.UIFigure,'Type','uidropdown');
            app.efields = findobj(app.UIFigure,'Type','uieditfield');
            app.labels = findobj(app.UIFigure,'Type','uilabel');
            app.listBoxes = findobj(app.UIFigure,'Type','uilistbox');
            app.nefields = findobj(app.UIFigure,'Tag','nefield');
            app.panels = findobj(app.UIFigure,'Type','uipanel');
            app.textAreas = findobj(app.UIFigure,'Type','uitextarea');

            %% Updating theme from last app
            if(theme)
                app.setDarkTheme
            else
                app.setLightTheme
            end

            app.bloodTypeDropDown.Items = app.bloodTypes.bloodTypes;
            
            %% Converting catalog chars to strings 
            app.nationalities.nationalityKey = convertCharsToStrings(app.nationalities.nationalityKey);
            app.federalEntities.stateKey = convertCharsToStrings(app.federalEntities.stateKey);

            %% Setting up default nationality as Mexican
            defaultNationality = app.getNationalityFromKey("MEX");
            app.nationalityDropDown.Value = defaultNationality;
            app.nationalityKey = app.getKeyFromNationality(defaultNationality);

            %% Setting up indigenous languages items
            app.indigenousLanguageDropDown.Items = app.indigenousLanguages.indigenousLanguage;

            %% Setting up mexican federal entities
            app.placeOfBirthDropDown.Items = app.federalEntities.(app.currentLanguage);
            app.placeOfResidenceDropDown.Items = app.federalEntities.(app.currentLanguage);

            %% Set limits to date picker
            app.birthDatePicker.Limits = [datetime('01/01/0000',InputFormat='MM/dd/uuuu'),datetime("today")];

            %% Load institution name for patient ID generation
            app.loadInstitutionName();

        end

        % On callback: screenTool
        function screenToolOn(app, event)
            %% Fullscreen
            app.currentWindowState = 1;
            app.screenTool.State = "on";
            app.UIFigure.WindowState = "fullscreen";
        end

        % Off callback: screenTool
        function screenToolOff(app, event)
            %% Maximized screen
            app.currentWindowState = 0;
            app.screenTool.State = "off";
            app.UIFigure.WindowState = "maximized";
        end

        % On callback: themeTool
        function themeToolOn(app, event)
            app.setDarkTheme
        end

        % Off callback: themeTool
        function themeToolOff(app, event)
            app.setLightTheme
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %% Save settings for next boot
            app.saveSettings
            
            %% Stop timers and close app
            close(app.configurationsApp)
            app.closeFigureForPreview();
            delete(timerfindall)
            delete(app)
        end

        % Value changed function: indigenousLanguageSpeakerCheckBox
        function indigenousLanguageSpeakerCheckBoxValueChanged(app, event)
            %% Enable or disable indigenous language speaker drop down
            value = app.indigenousLanguageSpeakerCheckBox.Value;
            if value
                app.indigenousLanguageDropDown.Enable = "on";
                app.indigenousLanguageDropDownLabel.Enable = "on";
                app.indigenousLanguage = app.indigenousLanguageDropDown.Value;
            else
                app.indigenousLanguageDropDown.Enable = "off";
                app.indigenousLanguageDropDownLabel.Enable = "off";
                app.indigenousLanguage = 'NA';
            end
        end

        % Callback function: configTool
        function configToolClicked(app, event)
            %% Open configurations app and wait until its closed
            app.configurationsApp = configurations(app.currentTheme...
                ,app.currentLanguage,recordPatient,app.canModify);
            uiwait(app.configurationsApp.UIFigure)
        end

        % Value changed function: nationalityDropDown
        function nationalityDropDownValueChanged(app, event)
            %% Disable place of birth and assign a not applicable value if nationality is not mexican
            value = app.nationalityDropDown.Value;
            app.nationalityKey = app.getKeyFromNationality(value);
            if (app.nationalityKey == "MEX")
                app.placeOfBirthDropDown.Enable = "on";
                app.placeOfBirthDropDown.Value = app.getFederalEntityFromKey(app.birthEntityKey);
            else
                app.placeOfBirthDropDown.Enable = "off";
                app.birthEntityKey = '88';
                app.placeOfBirthDropDown.Value = app.getFederalEntityFromKey(app.birthEntityKey);
            end
        end

        % Value changed function: birthDatePicker
        function birthDatePickerValueChanged(app, event)
            %% Calculate age and show it into age edit field
            value = app.birthDatePicker.Value;
            app.age = floor(years(datetime("today") - value));
            value = char(value);
            value([5,8]) = [];
            app.birthDate = value;
            app.ageEditField.Value = app.age;
        end

        % Value changed function: placeOfBirthDropDown
        function placeOfBirthDropDownValueChanged(app, event)
            %% Update place of birth (federal entity key)
            value = app.placeOfBirthDropDown.Value;
            app.birthEntityKey = app.getKeyFromFederalEntity(value);
        end

        % Value changed function: sLastNameCheckBox
        function sLastNameCheckBoxValueChanged(app, event)
            %% Enable or disable second last name edit field
            value = app.sLastNameCheckBox.Value;
            if value
                app.sLastNameEditField.Enable = "off";
                app.sLastNameEditFieldLabel.Enable = "off";
                app.secondLastName = 'NE';
            else
                app.sLastNameEditField.Enable = "on";
                app.sLastNameEditFieldLabel.Enable = "on";
                app.secondLastName = app.sLastNameEditField.Value;
            end
        end

        % Value changed function: curpCheckBox
        function curpCheckBoxValueChanged(app, event)
            %% Enable or disable curp edit field
            value = app.curpCheckBox.Value;
            if value
                app.curpEditField.Enable = "off";
                app.curpEditFieldLabel.Enable = "off";
                app.curp = 'NE';
            else
                app.curpEditField.Enable = "on";
                app.curpEditFieldLabel.Enable = "on";
                app.curp = app.curpEditField.Value;
            end
        end

        % Button pushed function: fileSelectorButton
        function fileSelectorButtonPushed(app, event)
            %% Open file selector and save path and file names
            f = figure();
            f.Visible = "off";

            [files,path] = uigetfile('*.dcm',"MultiSelect","on");
            figure(app.UIFigure)
            delete(f)
            
            if iscell(files)
                [excludedFiles,includedFiles] = app.checkDicom(path,files);
            else
                if files == 0
                    return
                else
                    [excludedFiles,includedFiles] = app.checkDicom(path,files);
                end
            end

            app.includeFiles(includedFiles)

            %% Enable or disable delete file and delete all files buttons
            if ~isempty(app.fileSelectorListBox.Items)
                app.addFileButton.Enable = "on";
                app.allImagePreviewButton.Enable = "on";
                app.deleteAllFilesButton.Enable = "on";
                app.deleteFileButton.Enable = "on";
                app.imagePreviewButton.Enable = "on";
            end

            %% Show uialert with excluded files
            app.showExcludedFiles(excludedFiles)

            %% Obtain dicom information
            if isempty(app.fileSelectorEditField.Value)
                return
            else
                dicomData = dicominfo(app.fileSelectorEditField.Value);
                app.displayDicomInfo(dicomData)
            end


        end

        % Button pushed function: deleteAllFilesButton
        function deleteAllFilesButtonPushed(app, event)
            %% Delete all selected files in list box and disable add file, delete file and delete all files buttons
            app.fileSelectorListBox.Items = {};
            app.fileSelectorEditField.Value = "";
            app.allImagePreviewButton.Enable = "off";
            app.addFileButton.Enable = "off";
            app.deleteAllFilesButton.Enable = "off";
            app.deleteFileButton.Enable = "off";
            app.imagePreviewButton.Enable = "off";

            %% Disable dicom information fields
            app.disableDicomInfoFields

            %% Close figure for image preview
            app.closeFigureForPreview()
        end

        % Button pushed function: deleteFileButton
        function deleteFileButtonPushed(app, event)
            if (isempty(app.fileSelectorEditField.Value))
                return
            end
            
            %% Delete selected file
            items = app.fileSelectorListBox.Items;
            items = convertCharsToStrings(items);
            itemsLen = length(app.fileSelectorListBox.Items);

            if itemsLen > 1
                items(items == app.fileToDelete) = [];
                app.fileSelectorListBox.Items = items;
            else
                app.fileSelectorListBox.Items = {};
                app.fileSelectorEditField.Value = "";
            end
            
            app.disableDicomInfoFields
            app.fileSelectorEditField.Value = "";
            app.imagePreviewButton.Enable = "off";
            
            %% Disable delete file and delete all files buttons
            if isempty(app.fileSelectorListBox.Items)
                app.addFileButton.Enable = "off";
                app.allImagePreviewButton.Enable = "off";
                app.deleteAllFilesButton.Enable = "off";
                app.deleteFileButton.Enable = "off";
                app.imagePreviewButton.Enable = "off";
                app.disableDicomInfoFields
            end

            app.deleteFileButton.Enable = "off";
        end

        % Callback function
        function fileSelectorListBoxClicked(app, event)
            %% Update file to delete when an click occurs  
            itemIndex = event.InteractionInformation.Item;  
            item = convertCharsToStrings(app.fileSelectorListBox.Items(itemIndex));
            app.fileToDelete = item;
            app.fileSelectorEditField.Value = item;

            if isempty(app.fileSelectorEditField.Value)
                app.imagePreviewButton.Enable = "off";
                app.disableDicomInfoFields
            else
                app.imagePreviewButton.Enable = "on";
                dicomData = dicominfo(app.fileSelectorEditField.Value);
                app.displayDicomInfo(dicomData)
            end
        end

        % Callback function: closeTool
        function closeToolClicked(app, event)
            %% Stop and delete timers and app
            delete(app.configurationsApp)
            delete(app.figureForPreview)
            app.UIFigureCloseRequest
        end

        % Button pushed function: addFileButton
        function addFileButtonPushed(app, event)
            %% Open file selector and save path and file names
            f = figure();
            f.Visible = "off";

            [files,path] = uigetfile('*.dcm',"MultiSelect","on");
            figure(app.UIFigure)
            delete(f)
                
            if iscell(files)
                [dicomExcludedFiles,includedFiles] = app.checkDicom(path,files);
            else
                if files == 0
                    return
                else
                    [dicomExcludedFiles,includedFiles] = app.checkDicom(path,files);
                end
            end

            %% Get current items of file list box
            items = convertCharsToStrings(app.fileSelectorListBox.Items);

            %% Check if files are already loaded if so exclude files, else append it to file list items 
            alreadyLoaded = ismember(items,includedFiles);

            excludedFiles = items(alreadyLoaded);

            if isempty(excludedFiles)
                %% Update items
                app.fileSelectorListBox.Items = [app.fileSelectorListBox.Items,includedFiles'];
            else
                %% Delete duplicated paths
                for i = 1 : length(excludedFiles)
                    includedFiles(includedFiles == excludedFiles(i)) = [];
                end
                %% Update items and show duplicated files that were not loaded
                app.fileSelectorListBox.Items = [app.fileSelectorListBox.Items,includedFiles'];
                str = append(app.TeXFormat," ",excludedFiles');
                str = vertcat(app.excludeFileMessage,"",str);
                uialert(app.UIFigure,str,app.excludeFileTitle,"Icon",app.infoIconPath ...
                    ,"Interpreter","tex")
            end
            
            app.showExcludedFiles(dicomExcludedFiles)

        end

        % Callback function: logoutTool
        function logoutToolClicked(app, event)
            %% Close current app and open log in app
            delete(app.configurationsApp)
            delete(app.figureForPreview)
            stop(timerfindall)
            delete(timerfindall)
            login(app.currentWindowState,app.currentTheme,app.currentLanguage)
            delete(app)
        end

        % Button pushed function: imagePreviewButton
        function imagePreviewButtonPushed(app, event)
            if isempty(app.fileSelectorEditField.Value)
                return
            else
                %% avoid multiple figure opening and disable image preview tool (focus)
                delete(app.figureForPreview)
                app.imagePreviewTool.Enable = "off";
                app.imagePreviewTool.Visible = "off";
                %% Read file and show the image in a figure
                imagePath = convertCharsToStrings(app.fileSelectorEditField.Value);
                try
                    image = dicomread(imagePath);
                    image = imadjust(image);
                catch
                    str = append(app.TeXFormat," ",imagePath);
                    str = vertcat(app.dicomLoadError,"",str);
                    uialert(app.UIFigure,str,app.dicomLoadErrorTitle,"Icon",app.errorIconPath...
                        ,"Interpreter","tex")
                    return
                end
                app.figureForPreview = figure('ToolBar','none','MenuBar',...
                    'none','Color',app.figureColor,'CreateFcn',@app.createFigureForPreview,...
                    'CloseRequestFcn',@app.closeFigureForPreview,'Name',...
                    app.figurePreviewTitle,'NumberTitle','off');
                imshow(image)
            end
        end

        % Callback function: imagePreviewTool
        function imagePreviewToolClicked(app, event)
            figure(app.figureForPreview)
        end

        % Button pushed function: allImagePreviewButton
        function allImagePreviewButtonPushed(app, event)
            %% avoid multiple figure opening and disable image preview tool (focus)
            delete(app.figureForPreview)
            app.imagePreviewTool.Enable = "off";
            app.imagePreviewTool.Visible = "off";
            
            %% Read files and show images in a figure
            imagePaths = convertCharsToStrings(app.fileSelectorListBox.Items)';
            lenImagePaths = length(imagePaths);
            cellForImages = cell(1,lenImagePaths);

            for i = 1 : lenImagePaths
                try
                    image = dicomread(imagePaths(i));
                    image = imadjust(image);
                catch
                    continue
                end
                cellForImages{i} = image; 
            end
            
            app.figureForPreview = figure('ToolBar','none','MenuBar',...
                'none','Color',app.figureColor,'CreateFcn',@app.createFigureForPreview,...
                'CloseRequestFcn',@app.closeFigureForPreview,'Name',...
                app.figurePreviewTitle,'NumberTitle','off');
            montage(cellForImages)
        end

        % Callback function: mainMenuTool
        function mainMenuToolClicked(app, event)
            delete(app.configurationsApp)
            delete(app.figureForPreview)
            delete(timerfindall)
            selectAction(app.currentWindowState,app.currentTheme,app.currentLanguage,app.conn)
            delete(app)
        end

        % Value changed function: nameEditField
        function nameEditFieldValueChanged(app, event)
            value = app.nameEditField.Value;
            app.name = value;
        end

        % Value changed function: sLastNameEditField
        function sLastNameEditFieldValueChanged(app, event)
            value = app.sLastNameEditField.Value;
            app.secondLastName = value;
        end

        % Value changed function: biologicalSexDropDown
        function biologicalSexDropDownValueChanged(app, event)
            value = app.biologicalSexDropDown.Value;
            biologicalSexForLanguage = convertCharsToStrings(app.biologicalSexItems.(app.currentLanguage));
            biologicalSexKey = app.biologicalSexItems.key{biologicalSexForLanguage == value};
            app.biologicalSex = biologicalSexKey;
        end

        % Value changed function: curpEditField
        function curpEditFieldValueChanged(app, event)
            value = app.curpEditField.Value;
            app.curp = value;
        end

        % Value changed function: indigenousLanguageDropDown
        function indigenousLanguageDropDownValueChanged(app, event)
            value = app.indigenousLanguageDropDown.Value;
            app.indigenousLanguage = value;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.validateInformation
        end

        % Value changed function: fLastNameEditField
        function fLastNameEditFieldValueChanged(app, event)
            value = app.fLastNameEditField.Value;
            app.firstLastName = value;
        end

        % Value changed function: bloodTypeDropDown
        function bloodTypeDropDownValueChanged(app, event)
            value = app.bloodTypeDropDown.Value;
            app.bloodType = value;
        end

        % Clicked callback: fileSelectorListBox
        function fileSelectorListBoxClicked2(app, event)
            %% Update file to delete when a click occurs  
            itemIndex = event.InteractionInformation.Item;  

            %% Delete file button behavior
            if isempty(itemIndex)
                app.deleteFileButton.Enable = "off";
            else
                app.deleteFileButton.Enable = "on";
            end

            item = convertCharsToStrings(app.fileSelectorListBox.Items(itemIndex));
            app.fileToDelete = item;
            app.fileSelectorEditField.Value = item;

            if isempty(app.fileSelectorEditField.Value)
                app.imagePreviewButton.Enable = "off";
                app.disableDicomInfoFields
            else
                app.imagePreviewButton.Enable = "on";
                dicomData = dicominfo(app.fileSelectorEditField.Value);
                app.displayDicomInfo(dicomData)
            end
            
        end

        % Value changed function: postalCodeEditField
        function postalCodeEditFieldValueChanged(app, event)
            value = app.postalCodeEditField.Value;
            app.postalCode = value;
        end

        % Value changed function: addressTextArea
        function addressTextAreaValueChanged(app, event)
            value = app.addressTextArea.Value;
            value = cell2mat(value);
            app.address = value;
        end

        % Value changed function: phoneNumberEditField
        function phoneNumberEditFieldValueChanged(app, event)
            value = app.phoneNumberEditField.Value;
            app.phoneNumber = value;
        end

        % Value changed function: emailEditField
        function emailEditFieldValueChanged(app, event)
            value = app.emailEditField.Value;
            app.email = value;
        end

        % Value changed function: inheritedPhatologicalHistoryTextArea
        function inheritedPhatologicalHistoryTextAreaValueChanged(app, event)
            value = app.inheritedPhatologicalHistoryTextArea.Value;
            %value = cell2mat(value);
            app.inheritedPhatologicalHistory = value;
            disp(value)
        end

        % Value changed function: nonPhatologicalPersonalHistoryTextArea
        function nonPhatologicalPersonalHistoryTextAreaValueChanged(app, event)
            value = app.nonPhatologicalPersonalHistoryTextArea.Value;
            %value = cell2mat(value);
            app.nonPhatologicalPersonalHistory = value;
        end

        % Value changed function: phatologicalPersonalHistoryTextArea
        function phatologicalPersonalHistoryTextAreaValueChanged(app, event)
            value = app.phatologicalPersonalHistoryTextArea.Value;
            %value = cell2mat(value);
            app.phatologicalPersonalHistory = value;
        end

        % Value changed function: physicalExplorationTextArea
        function physicalExplorationTextAreaValueChanged(app, event)
            value = app.physicalExplorationTextArea.Value;
            %value = cell2mat(value);
            app.physicalExploration = value;
        end

        % Value changed function: placeOfResidenceDropDown
        function placeOfResidenceDropDownValueChanged(app, event)
            value = app.placeOfResidenceDropDown.Value;
            app.residenceEntityKey = app.getKeyFromFederalEntity(value);
        end

        % Callback function: infoTool
        function infoToolClicked(app, event)
            uialert(app.UIFigure,app.infoToolMessage,app.infoToolTitle...
                ,"Icon",app.infoIconPath)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.infoTool = uipushtool(app.Toolbar);
            app.infoTool.ClickedCallback = createCallbackFcn(app, @infoToolClicked, true);
            app.infoTool.Icon = fullfile(pathToMLAPP, 'icons', 'sign-info-svgrepo-com.svg');
            app.infoTool.Separator = 'on';
            app.screenTool = uitoggletool(app.Toolbar);
            app.screenTool.Icon = fullfile(pathToMLAPP, 'icons', 'full-screen-svgrepo-com.svg');
            app.screenTool.Separator = 'on';
            app.screenTool.OffCallback = createCallbackFcn(app, @screenToolOff, true);
            app.screenTool.OnCallback = createCallbackFcn(app, @screenToolOn, true);
            app.themeTool = uitoggletool(app.Toolbar);
            app.themeTool.Icon = fullfile(pathToMLAPP, 'icons', 'moon-stars-svgrepo-com-LC-Black.svg');
            app.themeTool.OffCallback = createCallbackFcn(app, @themeToolOff, true);
            app.themeTool.OnCallback = createCallbackFcn(app, @themeToolOn, true);
            app.configTool = uipushtool(app.Toolbar);
            app.configTool.ClickedCallback = createCallbackFcn(app, @configToolClicked, true);
            app.configTool.Icon = fullfile(pathToMLAPP, 'icons', 'cogwheel-svgrepo-com.svg');
            app.logoutTool = uipushtool(app.Toolbar);
            app.logoutTool.ClickedCallback = createCallbackFcn(app, @logoutToolClicked, true);
            app.logoutTool.Icon = fullfile(pathToMLAPP, 'icons', 'power-button-power-svgrepo-com.svg');
            app.logoutTool.Separator = 'on';
            app.closeTool = uipushtool(app.Toolbar);
            app.closeTool.ClickedCallback = createCallbackFcn(app, @closeToolClicked, true);
            app.closeTool.Icon = fullfile(pathToMLAPP, 'icons', 'cross-close-svgrepo-com.svg');
            app.mainMenuTool = uipushtool(app.Toolbar);
            app.mainMenuTool.ClickedCallback = createCallbackFcn(app, @mainMenuToolClicked, true);
            app.mainMenuTool.Icon = fullfile(pathToMLAPP, 'icons', 'visualization-grid-svgrepo-com.svg');
            app.imagePreviewTool = uipushtool(app.Toolbar);
            app.imagePreviewTool.Visible = 'off';
            app.imagePreviewTool.ClickedCallback = createCallbackFcn(app, @imagePreviewToolClicked, true);
            app.imagePreviewTool.Icon = fullfile(pathToMLAPP, 'icons', 'image-photo-svgrepo-com.svg');
            app.imagePreviewTool.Separator = 'on';
            app.selectFilesPanel = uipanel(app.UIFigure);
            app.selectFilesPanel.Title = 'Panel3';
            app.Image3 = uiimage(app.selectFilesPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'attachImage.png');
            app.fileSelectorEditField = uieditfield(app.selectFilesPanel, 'text');
            app.fileSelectorEditField.Editable = 'off';
            app.fileSelectorButton = uibutton(app.selectFilesPanel, 'push');
            app.fileSelectorButton.ButtonPushedFcn = createCallbackFcn(app, @fileSelectorButtonPushed, true);
            app.fileSelectorButton.Icon = fullfile(pathToMLAPP, 'icons', 'folder-svgrepo-com.svg');
            app.fileSelectorButton.Text = '';
            app.deleteFileButton = uibutton(app.selectFilesPanel, 'push');
            app.deleteFileButton.ButtonPushedFcn = createCallbackFcn(app, @deleteFileButtonPushed, true);
            app.deleteFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'delete-stop-svgrepo-com.svg');
            app.deleteFileButton.Enable = 'off';
            app.deleteFileButton.Text = '';
            app.deleteAllFilesButton = uibutton(app.selectFilesPanel, 'push');
            app.deleteAllFilesButton.ButtonPushedFcn = createCallbackFcn(app, @deleteAllFilesButtonPushed, true);
            app.deleteAllFilesButton.Icon = fullfile(pathToMLAPP, 'icons', 'trash-svgrepo-com.svg');
            app.deleteAllFilesButton.Enable = 'off';
            app.deleteAllFilesButton.Text = '';
            app.fileExplorerLabel = uilabel(app.selectFilesPanel);
            app.filesToIncludeLabel = uilabel(app.selectFilesPanel);
            app.filesToIncludeLabel.Text = 'Label2';
            app.addFileButton = uibutton(app.selectFilesPanel, 'push');
            app.addFileButton.ButtonPushedFcn = createCallbackFcn(app, @addFileButtonPushed, true);
            app.addFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'add-svgrepo-com.svg');
            app.addFileButton.Enable = 'off';
            app.addFileButton.Text = '';
            app.imagePreviewButton = uibutton(app.selectFilesPanel, 'push');
            app.imagePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @imagePreviewButtonPushed, true);
            app.imagePreviewButton.Icon = fullfile(pathToMLAPP, 'icons', 'image-viewer-svgrepo-com.svg');
            app.imagePreviewButton.Enable = 'off';
            app.imagePreviewButton.Text = '';
            app.allImagePreviewButton = uibutton(app.selectFilesPanel, 'push');
            app.allImagePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @allImagePreviewButtonPushed, true);
            app.allImagePreviewButton.Icon = fullfile(pathToMLAPP, 'icons', 'gallery-svgrepo-com.svg');
            app.allImagePreviewButton.Enable = 'off';
            app.allImagePreviewButton.Text = '';
            app.modalityEditFieldLabel = uilabel(app.selectFilesPanel);
            app.modalityEditFieldLabel.Enable = 'off';
            app.modalityEditFieldLabel.Text = 'Edit Field5';
            app.modalityEditField = uieditfield(app.selectFilesPanel, 'text');
            app.modalityEditField.Editable = 'off';
            app.modalityEditField.Enable = 'off';
            app.studyDateEditFieldLabel = uilabel(app.selectFilesPanel);
            app.studyDateEditFieldLabel.Enable = 'off';
            app.studyDateEditFieldLabel.Text = 'Edit Field5';
            app.studyDateEditField = uieditfield(app.selectFilesPanel, 'text');
            app.studyDateEditField.Editable = 'off';
            app.studyDateEditField.Enable = 'off';
            app.studyDescriptionEditFieldLabel = uilabel(app.selectFilesPanel);
            app.studyDescriptionEditFieldLabel.Enable = 'off';
            app.studyDescriptionEditFieldLabel.Text = 'Edit Field5';
            app.studyDescriptionEditField = uieditfield(app.selectFilesPanel, 'text');
            app.studyDescriptionEditField.Editable = 'off';
            app.studyDescriptionEditField.Enable = 'off';
            app.bodyPartExaminedEditFieldLabel = uilabel(app.selectFilesPanel);
            app.bodyPartExaminedEditFieldLabel.Enable = 'off';
            app.bodyPartExaminedEditFieldLabel.Text = 'Edit Field5';
            app.bodyPartExaminedEditField = uieditfield(app.selectFilesPanel, 'text');
            app.bodyPartExaminedEditField.Editable = 'off';
            app.bodyPartExaminedEditField.Enable = 'off';
            app.fileSelectorListBox = uilistbox(app.selectFilesPanel);
            app.fileSelectorListBox.Items = {};
            app.fileSelectorListBox.ClickedFcn = createCallbackFcn(app, @fileSelectorListBoxClicked2, true);
            app.fileSelectorListBox.Value = {};
            app.customClock = customClock(app.UIFigure);
            app.clockImage = uiimage(app.UIFigure);
            app.clockImage.Tag = 'image';
            app.clockImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'calendar-clock-svgrepo-com.svg');
            app.generalInformationPanel = uipanel(app.UIFigure);
            app.generalInformationPanel.Title = 'Panel';
            app.nameEditFieldLabel = uilabel(app.generalInformationPanel);
            app.nameEditFieldLabel.Text = 'Edit Field2';
            app.nameEditField = uieditfield(app.generalInformationPanel, 'text');
            app.nameEditField.CharacterLimits = [0 50];
            app.nameEditField.ValueChangedFcn = createCallbackFcn(app, @nameEditFieldValueChanged, true);
            app.nameEditField.Tooltip = {'El valor solo puede contener letras'};
            app.fLastNameEditFieldLabel = uilabel(app.generalInformationPanel);
            app.fLastNameEditFieldLabel.Text = 'Edit Field2';
            app.fLastNameEditField = uieditfield(app.generalInformationPanel, 'text');
            app.fLastNameEditField.CharacterLimits = [0 50];
            app.fLastNameEditField.ValueChangedFcn = createCallbackFcn(app, @fLastNameEditFieldValueChanged, true);
            app.sLastNameEditFieldLabel = uilabel(app.generalInformationPanel);
            app.sLastNameEditFieldLabel.Text = 'Edit Field2';
            app.sLastNameEditField = uieditfield(app.generalInformationPanel, 'text');
            app.sLastNameEditField.CharacterLimits = [0 50];
            app.sLastNameEditField.ValueChangedFcn = createCallbackFcn(app, @sLastNameEditFieldValueChanged, true);
            app.biologicalSexDropDownLabel = uilabel(app.generalInformationPanel);
            app.biologicalSexDropDownLabel.Text = 'Drop Down';
            app.biologicalSexDropDown = uidropdown(app.generalInformationPanel);
            app.biologicalSexDropDown.ValueChangedFcn = createCallbackFcn(app, @biologicalSexDropDownValueChanged, true);
            app.birthDatePickerLabel = uilabel(app.generalInformationPanel);
            app.birthDatePickerLabel.Text = 'Date picker';
            app.birthDatePicker = uidatepicker(app.generalInformationPanel);
            app.birthDatePicker.Limits = [datetime([0 1 1]) datetime([2099 2 28])];
            app.birthDatePicker.DisplayFormat = 'uuuu/MM/dd';
            app.birthDatePicker.ValueChangedFcn = createCallbackFcn(app, @birthDatePickerValueChanged, true);
            app.nationalityDropDownLabel = uilabel(app.generalInformationPanel);
            app.nationalityDropDownLabel.Text = 'Drop Down2';
            app.nationalityDropDown = uidropdown(app.generalInformationPanel);
            app.nationalityDropDown.ValueChangedFcn = createCallbackFcn(app, @nationalityDropDownValueChanged, true);
            app.placeOfBirthDropDownLabel = uilabel(app.generalInformationPanel);
            app.placeOfBirthDropDownLabel.Text = 'Drop Down2';
            app.placeOfBirthDropDown = uidropdown(app.generalInformationPanel);
            app.placeOfBirthDropDown.ValueChangedFcn = createCallbackFcn(app, @placeOfBirthDropDownValueChanged, true);
            app.indigenousLanguageSpeakerCheckBox = uicheckbox(app.generalInformationPanel);
            app.indigenousLanguageSpeakerCheckBox.ValueChangedFcn = createCallbackFcn(app, @indigenousLanguageSpeakerCheckBoxValueChanged, true);
            app.indigenousLanguageDropDownLabel = uilabel(app.generalInformationPanel);
            app.indigenousLanguageDropDownLabel.Enable = 'off';
            app.indigenousLanguageDropDownLabel.Text = 'Drop Down3';
            app.indigenousLanguageDropDown = uidropdown(app.generalInformationPanel);
            app.indigenousLanguageDropDown.ValueChangedFcn = createCallbackFcn(app, @indigenousLanguageDropDownValueChanged, true);
            app.indigenousLanguageDropDown.Enable = 'off';
            app.bloodTypeDropDownLabel = uilabel(app.generalInformationPanel);
            app.bloodTypeDropDownLabel.Text = 'Drop Down';
            app.bloodTypeDropDown = uidropdown(app.generalInformationPanel);
            app.bloodTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @bloodTypeDropDownValueChanged, true);
            app.Image = uiimage(app.generalInformationPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'blood-drop-svgrepo-com.svg');
            app.Image_2 = uiimage(app.generalInformationPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'calendar-svgrepo-com.svg');
            app.Image_3 = uiimage(app.generalInformationPanel);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'icons', 'globe-showing-americas-svgrepo-com.svg');
            app.Image_4 = uiimage(app.generalInformationPanel);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'icons', 'genders-gender-svgrepo-com.svg');
            app.Image_5 = uiimage(app.generalInformationPanel);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'icons', 'people-speak-svgrepo-com.svg');
            app.ageEditFieldLabel = uilabel(app.generalInformationPanel);
            app.ageEditFieldLabel.Text = 'Edit Field';
            app.ageEditField = uieditfield(app.generalInformationPanel, 'numeric');
            app.ageEditField.Limits = [0 Inf];
            app.ageEditField.Editable = 'off';
            app.sLastNameCheckBox = uicheckbox(app.generalInformationPanel);
            app.sLastNameCheckBox.ValueChangedFcn = createCallbackFcn(app, @sLastNameCheckBoxValueChanged, true);
            app.sLastNameCheckBox.Text = 'Check Box2';
            app.placeOfResidenceDropDownLabel = uilabel(app.generalInformationPanel);
            app.placeOfResidenceDropDownLabel.Text = 'Drop Down2';
            app.placeOfResidenceDropDown = uidropdown(app.generalInformationPanel);
            app.placeOfResidenceDropDown.ValueChangedFcn = createCallbackFcn(app, @placeOfResidenceDropDownValueChanged, true);
            app.curpEditFieldLabel = uilabel(app.generalInformationPanel);
            app.curpEditFieldLabel.Text = 'Edit Field2';
            app.curpEditField = uieditfield(app.generalInformationPanel, 'text');
            app.curpEditField.CharacterLimits = [0 18];
            app.curpEditField.InputType = 'alphanumerics';
            app.curpEditField.ValueChangedFcn = createCallbackFcn(app, @curpEditFieldValueChanged, true);
            app.curpCheckBox = uicheckbox(app.generalInformationPanel);
            app.curpCheckBox.ValueChangedFcn = createCallbackFcn(app, @curpCheckBoxValueChanged, true);
            app.Image_6 = uiimage(app.generalInformationPanel);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'icons', 'user-7-svgrepo-com.svg');
            app.Image_7 = uiimage(app.generalInformationPanel);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'icons', 'baby-symbol-svgrepo-com.svg');
            app.Image_8 = uiimage(app.generalInformationPanel);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'icons', 'maps-and-flags-pin-svgrepo-com.svg');
            app.idivPanel = uipanel(app.UIFigure);
            app.idivPanel.Title = 'Panel2';
            app.Image2 = uiimage(app.idivPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'icons', 'id-card-svgrepo-com.svg');
            app.idEditFieldLabel = uilabel(app.idivPanel);
            app.idEditFieldLabel.Text = 'Edit Field';
            app.idEditField = uieditfield(app.idivPanel, 'text');
            app.idEditField.Editable = 'off';
            app.createdAtEditFieldLabel = uilabel(app.idivPanel);
            app.createdAtEditFieldLabel.Text = 'Edit Field';
            app.createdAtEditField = uieditfield(app.idivPanel, 'text');
            app.createdAtEditField.Editable = 'off';
            app.clinicHistoryPanel = uipanel(app.UIFigure);
            app.clinicHistoryPanel.Title = 'Panel4';
            app.inheritedPhatologicalHistoryTextAreaLabel = uilabel(app.clinicHistoryPanel);
            app.inheritedPhatologicalHistoryTextAreaLabel.Text = 'Text Area';
            app.inheritedPhatologicalHistoryTextArea = uitextarea(app.clinicHistoryPanel);
            app.inheritedPhatologicalHistoryTextArea.ValueChangedFcn = createCallbackFcn(app, @inheritedPhatologicalHistoryTextAreaValueChanged, true);
            app.nonPhatologicalPersonalHistoryTextAreaLabel = uilabel(app.clinicHistoryPanel);
            app.nonPhatologicalPersonalHistoryTextAreaLabel.Text = 'Text Area';
            app.nonPhatologicalPersonalHistoryTextArea = uitextarea(app.clinicHistoryPanel);
            app.nonPhatologicalPersonalHistoryTextArea.ValueChangedFcn = createCallbackFcn(app, @nonPhatologicalPersonalHistoryTextAreaValueChanged, true);
            app.phatologicalPersonalHistoryTextAreaLabel = uilabel(app.clinicHistoryPanel);
            app.phatologicalPersonalHistoryTextAreaLabel.Text = 'Text Area';
            app.phatologicalPersonalHistoryTextArea = uitextarea(app.clinicHistoryPanel);
            app.phatologicalPersonalHistoryTextArea.ValueChangedFcn = createCallbackFcn(app, @phatologicalPersonalHistoryTextAreaValueChanged, true);
            app.physicalExplorationTextAreaLabel = uilabel(app.clinicHistoryPanel);
            app.physicalExplorationTextAreaLabel.Text = 'Text Area';
            app.physicalExplorationTextArea = uitextarea(app.clinicHistoryPanel);
            app.physicalExplorationTextArea.ValueChangedFcn = createCallbackFcn(app, @physicalExplorationTextAreaValueChanged, true);
            app.Image4 = uiimage(app.clinicHistoryPanel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'icons', 'stethoscope-svgrepo-com.svg');
            app.Image4_2 = uiimage(app.clinicHistoryPanel);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'family-institution-svgrepo-com.svg');
            app.Image4_3 = uiimage(app.clinicHistoryPanel);
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'icons', 'man-with-sore-throat-svgrepo-com.svg');
            app.Image4_4 = uiimage(app.clinicHistoryPanel);
            app.Image4_4.ImageSource = fullfile(pathToMLAPP, 'icons', 'hospital-svgrepo-com.svg');
            app.patientRecordLabel = uilabel(app.UIFigure);
            app.patientRecordLabel.Text = 'Label3';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'save-svgrepo-com.svg');
            app.contactInformationPanel = uipanel(app.UIFigure);
            app.contactInformationPanel.Title = 'Panel5';
            app.phoneNumberEditFieldLabel = uilabel(app.contactInformationPanel);
            app.phoneNumberEditFieldLabel.Text = 'Edit Field3';
            app.phoneNumberEditField = uieditfield(app.contactInformationPanel, 'text');
            app.phoneNumberEditField.InputType = 'digits';
            app.phoneNumberEditField.ValueChangedFcn = createCallbackFcn(app, @phoneNumberEditFieldValueChanged, true);
            app.emailEditFieldLabel = uilabel(app.contactInformationPanel);
            app.emailEditFieldLabel.Text = 'Edit Field3';
            app.emailEditField = uieditfield(app.contactInformationPanel, 'text');
            app.emailEditField.ValueChangedFcn = createCallbackFcn(app, @emailEditFieldValueChanged, true);
            app.postalCodeEditFieldLabel = uilabel(app.contactInformationPanel);
            app.postalCodeEditFieldLabel.Text = 'Edit Field4';
            app.postalCodeEditField = uieditfield(app.contactInformationPanel, 'numeric');
            app.postalCodeEditField.Limits = [0 Inf];
            app.postalCodeEditField.ValueDisplayFormat = '%.0f';
            app.postalCodeEditField.ValueChangedFcn = createCallbackFcn(app, @postalCodeEditFieldValueChanged, true);
            app.postalCodeEditField.Tag = 'nefield';
            app.Image5 = uiimage(app.contactInformationPanel);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'icons', 'email-mail-svgrepo-com.svg');
            app.Image5_2 = uiimage(app.contactInformationPanel);
            app.Image5_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'phone-number-svgrepo-com.svg');
            app.Image5_3 = uiimage(app.contactInformationPanel);
            app.Image5_3.ImageSource = fullfile(pathToMLAPP, 'icons', 'map-map-marker-svgrepo-com.svg');
            app.addressTextAreaLabel = uilabel(app.contactInformationPanel);
            app.addressTextAreaLabel.Text = 'Text Area2';
            app.addressTextArea = uitextarea(app.contactInformationPanel);
            app.addressTextArea.ValueChangedFcn = createCallbackFcn(app, @addressTextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = recordPatient(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end