classdef searchRecord < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Toolbar           matlab.ui.container.Toolbar
        infoTool          matlab.ui.container.toolbar.PushTool
        screenTool        matlab.ui.container.toolbar.ToggleTool
        themeTool         matlab.ui.container.toolbar.ToggleTool
        configTool        matlab.ui.container.toolbar.PushTool
        logoutTool        matlab.ui.container.toolbar.PushTool
        closeTool         matlab.ui.container.toolbar.PushTool
        mainMenuTool      matlab.ui.container.toolbar.PushTool
        Image4            matlab.ui.control.Image
        customClock       customClock
        Image             matlab.ui.control.Image
        searchButton      matlab.ui.control.Button
        idEditField       matlab.ui.control.EditField
        idEditFieldLabel  matlab.ui.control.Label
        idListBox         matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        %% Theme ,window and language control
        currentTheme           % 0 for light theme and 1 for dark theme
        currentWindowState     % 0 for maximized screen and 1 for fullscren
        currentLanguage
        
        %% Setting colors for dark theme and light theme
        darkThemeColorUIF =  [0,0,0]; 
        darkThemeColorToolBar = [15,15,15]/100;
        darkThemeColorBGLabels = "none";
        darkThemeColorBGContainers= [20,20,20]/100;
        darkThemeFontColor = [94,94,94]/100; 

        lightThemeColorUIF = [94, 94, 94]/100;
        lightThemeColorToolBar = '#3db52d';
        lightThemeColorBGLabels = "none";
        lightThemeColorBGContainers= [1,1,1];
        lightThemeFontColor = [0,0,0];  

        %% Declaring properties to save labels and text
        infoToolMessage
        infoToolTitle

        %% Database managing properties
        dbSource
        db
        conn
        %% Configurations app control
        configurationsApp

        %% Control of language labels
        searchRecordLanguage

        %% Origin paths for files
        originPath

        %% IDs variables
        idList

        %% Icon paths
        infoIconPath
        successIconPath
        warningIconPath
        errorIconPath

        %% Progress bar messages
        searchingIDMessage
        obtainingMedicalRecord

        %% Error messages
        idNotFoundError
        idNotFoundTitle


    end
    
    properties (SetAccess = immutable )
        %% SQL querys
        getIDs = "SELECT patient_id FROM identification_data"

        %% Check id query
        checkID = "SELECT 1 FROM identification_data WHERE patient_id = '%s'"

        %% Configurations app control
        canModify = 0
    end


    methods (Access = private)
        
        function [] = setDarkTheme(app)
            %% Updating current theme an icon
            app.themeTool.State = "on";
            app.currentTheme = 1;
            iconPath = fullfile(app.originPath,"icons\sun-svgrepo-com-LC-White.svg");
            app.themeTool.Icon = iconPath;
            
            %% Changing colors and icons for dark theme
            app.UIFigure.Color = app.darkThemeColorUIF;
            app.Toolbar.BackgroundColor = app.darkThemeColorToolBar;
            app.customClock.BackgroundColor = app.darkThemeColorUIF;
            app.customClock.fontColor = app.darkThemeFontColor;

            app.idEditFieldLabel.FontColor = app.darkThemeFontColor;
            app.idEditField.FontColor = app.darkThemeFontColor;
            app.idEditField.BackgroundColor = app.darkThemeColorBGContainers;
            app.idListBox.BackgroundColor = app.darkThemeColorBGContainers;
            app.idListBox.FontColor = app.darkThemeFontColor;
        end

        function [] = setLightTheme(app)
            %% Updating state, current theme an icon
            app.themeTool.State = "off";
            app.currentTheme = 0;
            iconPath = fullfile(app.originPath,"icons\moon-stars-svgrepo-com-LC-Black.svg");
            app.themeTool.Icon = iconPath;
            
            %% Changing colors and icons for light theme
            app.UIFigure.Color = app.lightThemeColorUIF;
            app.Toolbar.BackgroundColor = app.lightThemeColorToolBar;
            app.customClock.BackgroundColor = app.lightThemeColorUIF;
            app.customClock.fontColor = app.lightThemeFontColor;

            app.idEditFieldLabel.FontColor = app.lightThemeFontColor;
            app.idEditField.FontColor = app.lightThemeFontColor;
            app.idEditField.BackgroundColor = app.lightThemeColorBGContainers;
            app.idListBox.BackgroundColor = app.lightThemeColorBGContainers;
            app.idListBox.FontColor = app.lightThemeFontColor;
        end
        
        
        function [] = setLanguage(app,languageValue)
            %% Loading language table
            app.currentLanguage = languageValue;
            component = app.searchRecordLanguage.componentName;
            property = app.searchRecordLanguage.propertyName;
            label = app.searchRecordLanguage.(languageValue);
            
            %% Changing language
            for i = 1 : length(component)
                if(property{i} == "none")
                    app.(component{i}) = label{i};
                else
                    app.(component{i}).(property{i}) = label{i};
                end
            end
        end
        
        function IDs = getAllIDs(app)
            IDs = fetch(app.conn,app.getIDs);
            IDs = convertCharsToStrings(table2array(IDs));
        end
        
        function saveSettings(app)
            %% Save settings 
            windowState = app.currentWindowState;
            theme = app.currentTheme;
            language = app.currentLanguage;
            lastSessionPath = fullfile(app.originPath,"settings","lastSessionSettings.mat");
            save(lastSessionPath,'windowState','theme','language')
        end
    end
    
    methods (Access = public)
        
        function updateConfigurations(app,dbSource,language)
            app.dbSource = dbSource;
            app.setLanguage(language)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, windowState, theme, language, connection)
            %% Initial settings
            
            if isdeployed
                app.originPath = fullfile(ctfroot,"login");
            else
                app.originPath = pwd;
            end

            %% Get icon paths
            app.infoIconPath = fullfile(app.originPath,"icons\sign-info-svgrepo-com.svg");
            app.successIconPath = fullfile(app.originPath,"icons\success-icon.svg");
            app.warningIconPath = fullfile(app.originPath,"icons\warning-svgrepo-com.svg");
            app.errorIconPath = fullfile(app.originPath,"icons\error-svgrepo-com.svg");

            %% get connection object
            app.conn = connection;
            
            %% Load language file
            languagePath = fullfile(app.originPath,"language\searchRecordLanguage.xlsx");
            app.searchRecordLanguage = readtable(languagePath); 

            
            %% get patients ID's
            app.idList = app.getAllIDs;
            
            %% app.idListBox.Items = app.idList;
            app.idListBox.Items = app.idList;
            
            %% Updating window state from last app
            if(windowState)
                app.screenToolOn
            else
                app.screenToolOff
            end

            %% Updating theme from last app
            if(theme)
                app.setDarkTheme
            else
                app.setLightTheme
            end

            app.setLanguage(language)
        end

        % On callback: screenTool
        function screenToolOn(app, event)
            app.currentWindowState = 1;
            app.screenTool.State = "on";
            app.UIFigure.WindowState = "fullscreen";
        end

        % Off callback: screenTool
        function screenToolOff(app, event)
            app.currentWindowState = 0;
            app.screenTool.State = "off";
            app.UIFigure.WindowState = "maximized";
        end

        % On callback: themeTool
        function themeToolOn(app, event)
            app.setDarkTheme
        end

        % Off callback: themeTool
        function themeToolOff(app, event)
            app.setLightTheme
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %% Save settings for next boot
            app.saveSettings
            
            %% Delete all timers and close the app
            delete(app.configurationsApp)
            delete(timerfindall)
            delete(app)
        end

        % Value changing function: idEditField
        function idEditFieldValueChanging(app, event)
                id = event.Value;
                lenID = length(id);

                %% Show ids availables
                if id == ""
                    app.idListBox.Items = app.idList; 
                else
                    app.idListBox.Items = app.idList(contains(app.idList,id));            
                end
                
                %% Enable or disable search button
                if lenID == 18
                   app.searchButton.Enable = "on";
                else
                   app.searchButton.Enable = "off";
                end
        end

        % Double-clicked callback: idListBox
        function idListBoxDoubleClicked(app, event)
            % Selecting patient id and assigning value to text field
            item = app.idListBox.Value;
            app.idEditField.Value = item;
            
            id = app.idEditField.Value;
            lenID = length(id);
            
            %% Enable or disable search button
            if lenID == 18
               app.searchButton.Enable = "on";
            else
               app.searchButton.Enable = "off";
            end

        end

        % Callback function: infoTool
        function infoToolClicked(app, event)
            uialert(app.UIFigure,app.infoToolMessage,app.infoToolTitle...
                ,"Icon",app.infoIconPath)
        end

        % Callback function: logoutTool
        function logoutToolClicked(app, event)
            delete(app.configurationsApp)
            delete(timerfindall)
            login(app.currentWindowState,app.currentTheme,app.currentLanguage)
            delete(app)
        end

        % Callback function: closeTool
        function closeToolClicked(app, event)
            app.UIFigureCloseRequest
        end

        % Callback function: configTool
        function configToolClicked(app, event)
            app.configurationsApp = configurations(app.currentTheme...
                ,app.currentLanguage,searchRecord,app.canModify);
            uiwait(app.configurationsApp.UIFigure)
        end

        % Callback function: mainMenuTool
        function mainMenuToolClicked(app, event)
            delete(app.configurationsApp)
            delete(timerfindall)
            selectAction(app.currentWindowState,app.currentTheme,app.currentLanguage,app.conn)
            delete(app)
        end

        % Button pushed function: searchButton
        function searchButtonPushed(app, event)
            ID = app.idEditField.Value;
            progressBar = uiprogressdlg(app.UIFigure,"Indeterminate","on");
            progressBar.Message = app.searchingIDMessage;
            pause(1)
            try
                query = sprintf(app.checkID,ID);
                existID = fetch(app.conn,query);

                if ~isempty(existID)
                    
                    %% Delete app and save configurations
                    delete(app.configurationsApp)
                    delete(timerfindall)
                    progressBar.Message = app.obtainingMedicalRecord;

                    viewRecordV2(app.currentWindowState,app.currentTheme,app.currentLanguage,...
                        app.conn,ID)

                    delete(app)
                else
                    %% show error message
                    str = sprintf(app.idNotFoundError,ID);
                    uialert(app.UIFigure,str,app.idNotFoundTitle,"icon",app.errorIconPath)
                end
            catch Me
                disp(Me.message)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.infoTool = uipushtool(app.Toolbar);
            app.infoTool.ClickedCallback = createCallbackFcn(app, @infoToolClicked, true);
            app.infoTool.Icon = fullfile(pathToMLAPP, 'icons', 'sign-info-svgrepo-com.svg');
            app.infoTool.Separator = 'on';
            app.screenTool = uitoggletool(app.Toolbar);
            app.screenTool.Icon = fullfile(pathToMLAPP, 'icons', 'full-screen-svgrepo-com.svg');
            app.screenTool.Separator = 'on';
            app.screenTool.OffCallback = createCallbackFcn(app, @screenToolOff, true);
            app.screenTool.OnCallback = createCallbackFcn(app, @screenToolOn, true);
            app.themeTool = uitoggletool(app.Toolbar);
            app.themeTool.Icon = fullfile(pathToMLAPP, 'icons', 'moon-stars-svgrepo-com-LC-Black.svg');
            app.themeTool.OffCallback = createCallbackFcn(app, @themeToolOff, true);
            app.themeTool.OnCallback = createCallbackFcn(app, @themeToolOn, true);
            app.configTool = uipushtool(app.Toolbar);
            app.configTool.ClickedCallback = createCallbackFcn(app, @configToolClicked, true);
            app.configTool.Icon = fullfile(pathToMLAPP, 'icons', 'cogwheel-svgrepo-com.svg');
            app.logoutTool = uipushtool(app.Toolbar);
            app.logoutTool.ClickedCallback = createCallbackFcn(app, @logoutToolClicked, true);
            app.logoutTool.Icon = fullfile(pathToMLAPP, 'icons', 'power-button-power-svgrepo-com.svg');
            app.logoutTool.Separator = 'on';
            app.closeTool = uipushtool(app.Toolbar);
            app.closeTool.ClickedCallback = createCallbackFcn(app, @closeToolClicked, true);
            app.closeTool.Icon = fullfile(pathToMLAPP, 'icons', 'cross-close-svgrepo-com.svg');
            app.mainMenuTool = uipushtool(app.Toolbar);
            app.mainMenuTool.ClickedCallback = createCallbackFcn(app, @mainMenuToolClicked, true);
            app.mainMenuTool.Icon = fullfile(pathToMLAPP, 'icons', 'visualization-grid-svgrepo-com.svg');
            app.idListBox = uilistbox(app.UIFigure);
            app.idListBox.DoubleClickedFcn = createCallbackFcn(app, @idListBoxDoubleClicked, true);
            app.idEditFieldLabel = uilabel(app.UIFigure);
            app.idEditFieldLabel.Text = 'Edit Field';
            app.idEditField = uieditfield(app.UIFigure, 'text');
            app.idEditField.CharacterLimits = [0 18];
            app.idEditField.ValueChangingFcn = createCallbackFcn(app, @idEditFieldValueChanging, true);
            app.searchButton = uibutton(app.UIFigure, 'push');
            app.searchButton.ButtonPushedFcn = createCallbackFcn(app, @searchButtonPushed, true);
            app.searchButton.Icon = fullfile(pathToMLAPP, 'icons', 'search-file-svgrepo-com.svg');
            app.searchButton.Enable = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'searchPatient.png');
            app.customClock = customClock(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.Tag = 'image';
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'icons', 'calendar-clock-svgrepo-com.svg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = searchRecord(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end