classdef selectAction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Toolbar         matlab.ui.container.Toolbar
        infoTool        matlab.ui.container.toolbar.PushTool
        screenTool      matlab.ui.container.toolbar.ToggleTool
        themeTool       matlab.ui.container.toolbar.ToggleTool
        configTool      matlab.ui.container.toolbar.PushTool
        logoutTool      matlab.ui.container.toolbar.PushTool
        closeTool       matlab.ui.container.toolbar.PushTool
        Image5_6        matlab.ui.control.Image
        modifyButton    matlab.ui.control.Button
        Image5_5        matlab.ui.control.Image
        searchButton    matlab.ui.control.Button
        Image5_4        matlab.ui.control.Image
        scheduleButton  matlab.ui.control.Button
        Image5          matlab.ui.control.Image
        recordButton    matlab.ui.control.Button
        Image4          matlab.ui.control.Image
        customClock     customClock
    end

    
    properties (Access = private)
        %% Theme ,window and language control
        currentTheme           % 0 for light theme and 1 for dark theme
        currentWindowState     % 0 for maximized screen and 1 for fullscren
        currentLanguage

        %% Setting colors for dark theme and light theme
        darkThemeColorUIF =  [0,0,0]; 
        darkThemeColorToolBar = [15,15,15]/100;
        darkThemeColorBGLabels = "none";
        darkThemeColorBGContainers= [20,20,20]/100;
        darkThemeFontColor = [94,94,94]/100; 

        lightThemeColorUIF = [94, 94, 94]/100;
        lightThemeColorToolBar = '#3db52d';
        lightThemeColorBGLabels = "none";
        lightThemeColorBGContainers= [1,1,1];
        lightThemeFontColor = [0,0,0]; 

        %% Declaring properties to save labels and text
        infoToolMessage
        infoToolTitle
        loadingRecordApp

        %% Database properties
        dbSource
        conn

        %% Configurations app control
        configurationsApp

        %% Control of language labels
        selectActionLanguage

        %% Origin paths for files
        originPath
        
        %% Icon paths
        infoIconPath
        successIconPath
        warningIconPath
        errorIconPath

    end

    properties(SetAccess = immutable)
        canModify = 0
    end
    
    methods (Access = private)
        
        function [] = setDarkTheme(app)
            %% Updating current theme an icon
            app.themeTool.State = "on";
            app.currentTheme = 1;
            iconPath = fullfile(app.originPath,"icons\sun-svgrepo-com-LC-White.svg");
            app.themeTool.Icon = iconPath;
            
            %% Changing colors and icons for dark theme
            app.UIFigure.Color = app.darkThemeColorUIF;
            app.Toolbar.BackgroundColor = app.darkThemeColorToolBar;
            app.customClock.BackgroundColor = app.darkThemeColorUIF;
            app.customClock.fontColor = app.darkThemeFontColor;
            
            %% Obtaining every button component and changing its colors
            buttons = findobj(app.UIFigure,'Tag','button');

            for i = 1 : length(buttons)
                buttons(i).BackgroundColor = app.darkThemeColorBGContainers;
                buttons(i).FontColor = app.darkThemeFontColor;
            end
        end
        
        function [] = setLightTheme(app)
            %% Updating state, current theme an icon
            app.themeTool.State = "off";
            app.currentTheme = 0;
            iconPath = fullfile(app.originPath,"icons\moon-stars-svgrepo-com-LC-Black.svg");
            app.themeTool.Icon = iconPath;
            
            %% Changing colors and icons for light theme
            app.UIFigure.Color = app.lightThemeColorUIF;
            app.Toolbar.BackgroundColor = app.lightThemeColorToolBar;
            app.customClock.BackgroundColor = app.lightThemeColorUIF;
            app.customClock.fontColor = app.lightThemeFontColor;

            %% Obtaining every button component and changing its colors
            buttons = findobj(app.UIFigure,'Tag','button');

            for i = 1 : length(buttons)
                buttons(i).BackgroundColor = app.lightThemeColorBGContainers;
                buttons(i).FontColor = app.lightThemeFontColor;
            end

        end
        
        function [] = setLanguage(app,languageValue)
            %% Loading language table
            app.currentLanguage = languageValue;
            component = app.selectActionLanguage.componentName;
            property = app.selectActionLanguage.propertyName;
            label = app.selectActionLanguage.(languageValue);
            
            %% Changing language
            for i = 1 : length(component)
                if(property{i} == "none")
                    app.(component{i}) = label{i};
                else
                    app.(component{i}).(property{i}) = label{i};
                end
            end

        end
        
        %% Disable all buttons and toolbar while configurations app is executing
        function [] = controlInteractivity(app,control)
            
            buttons = findobj(app.UIFigure,'Type','uibutton');

            if control == "enable"
                state = "on";
            else
                state = "off";
            end

            for i = 1 : length(buttons)
                buttons(i).Enable = state;
            end
            
        end
        
        function saveSettings(app)
            %% Save settings 
            windowState = app.currentWindowState;
            theme = app.currentTheme;
            language = app.currentLanguage;
            lastSessionPath = fullfile(app.originPath,"settings","lastSessionSettings.mat");
            save(lastSessionPath,'windowState','theme','language')
        end
    end
    
    methods (Access = public)
        %% Updating language and database source from configurations app
        function updateConfigurations(app,dbSource,language)
            app.dbSource = dbSource;
            app.setLanguage(language)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, windowState, theme, language, connection)
            %% Initial settings
            
            if isdeployed
                app.originPath = fullfile(ctfroot,"login");
            else
                app.originPath = pwd;
            end

             %% Get icon paths
            app.infoIconPath = fullfile(app.originPath,"icons\sign-info-svgrepo-com.svg");
            app.successIconPath = fullfile(app.originPath,"icons\success-icon.svg");
            app.warningIconPath = fullfile(app.originPath,"icons\warning-svgrepo-com.svg");
            app.errorIconPath = fullfile(app.originPath,"icons\error-svgrepo-com.svg");

            %% get connection object
            app.conn = connection;
            
            %% Load language file
            languagePath = fullfile(app.originPath,"language\selectActionLanguage.xlsx");
            app.selectActionLanguage = readtable(languagePath); 
            
            %% Starting app with parameters from last app
            
            % Setting up language
            app.currentLanguage = language;
            app.setLanguage(app.currentLanguage)

            % Setting up window state
            if windowState
                app.screenToolOn;
            else
                app.screenToolOff;
            end

            % Setting up theme
            if theme
                app.setDarkTheme
            else
                app.setLightTheme
            end


        end

        % On callback: screenTool
        function screenToolOn(app, event)
            %% Fullscreen
            app.currentWindowState = 1;
            app.screenTool.State = "on";
            app.UIFigure.WindowState = "fullscreen";
        end

        % Off callback: screenTool
        function screenToolOff(app, event)
            %% Maximized screen
            app.currentWindowState = 0;
            app.screenTool.State = "off";
            app.UIFigure.WindowState = "maximized";
        end

        % On callback: themeTool
        function themeToolOn(app, event)
            app.setDarkTheme
        end

        % Off callback: themeTool
        function themeToolOff(app, event)
            app.setLightTheme
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %% Save settings for next boot
            app.saveSettings
            
            %% Delete all timers and close the app
            delete(app.configurationsApp)
            delete(timerfindall)
            delete(app)
        end

        % Callback function: infoTool
        function infoToolClicked(app, event)
            uialert(app.UIFigure,app.infoToolMessage,app.infoToolTitle,"Icon","info")
        end

        % Button pushed function: searchButton
        function searchButtonPushed(app, event)
            %% Open search record app and close and delete timers and current app
            delete(app.configurationsApp)
            delete(timerfindall)
            searchRecord(app.currentWindowState,app.currentTheme,app.currentLanguage,app.conn)
            delete(app)
        end

        % Callback function: logoutTool
        function logoutToolClicked(app, event)
            %% Open login app and close and delete timers and current app
            delete(app.configurationsApp)
            try
                delete(timerfindall)
            catch
            end
            login(app.currentWindowState,app.currentTheme,app.currentLanguage)
            delete(app)
        end

        % Callback function: configTool
        function configToolClicked(app, event)
            %% Open configurations app
            app.configurationsApp = configurations(app.currentTheme...
                ,app.currentLanguage,selectAction,app.canModify);

        end

        % Button pushed function: recordButton
        function recordButtonPushed(app, event)
            progressBar = uiprogressdlg(app.UIFigure,"Indeterminate","on");
            progressBar.Message = app.loadingRecordApp;
            delete(app.configurationsApp)
            delete(timerfindall)
            recordPatient(app.currentWindowState,app.currentTheme,app.currentLanguage,app.conn)
            delete(app)
        end

        % Callback function: closeTool
        function closeToolClicked(app, event)
            %% Close all windows 
            delete(app.configurationsApp)
            app.UIFigureCloseRequest
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'visualization-grid-svgrepo-com.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.infoTool = uipushtool(app.Toolbar);
            app.infoTool.ClickedCallback = createCallbackFcn(app, @infoToolClicked, true);
            app.infoTool.Icon = fullfile(pathToMLAPP, 'icons', 'sign-info-svgrepo-com.svg');
            app.infoTool.Separator = 'on';
            app.screenTool = uitoggletool(app.Toolbar);
            app.screenTool.Icon = fullfile(pathToMLAPP, 'icons', 'full-screen-svgrepo-com.svg');
            app.screenTool.Separator = 'on';
            app.screenTool.OffCallback = createCallbackFcn(app, @screenToolOff, true);
            app.screenTool.OnCallback = createCallbackFcn(app, @screenToolOn, true);
            app.themeTool = uitoggletool(app.Toolbar);
            app.themeTool.Icon = fullfile(pathToMLAPP, 'icons', 'moon-stars-svgrepo-com-LC-Black.svg');
            app.themeTool.OffCallback = createCallbackFcn(app, @themeToolOff, true);
            app.themeTool.OnCallback = createCallbackFcn(app, @themeToolOn, true);
            app.configTool = uipushtool(app.Toolbar);
            app.configTool.ClickedCallback = createCallbackFcn(app, @configToolClicked, true);
            app.configTool.Icon = fullfile(pathToMLAPP, 'icons', 'cogwheel-svgrepo-com.svg');
            app.logoutTool = uipushtool(app.Toolbar);
            app.logoutTool.Tooltip = {'Log out'};
            app.logoutTool.ClickedCallback = createCallbackFcn(app, @logoutToolClicked, true);
            app.logoutTool.Icon = fullfile(pathToMLAPP, 'icons', 'power-button-power-svgrepo-com.svg');
            app.logoutTool.Separator = 'on';
            app.closeTool = uipushtool(app.Toolbar);
            app.closeTool.ClickedCallback = createCallbackFcn(app, @closeToolClicked, true);
            app.closeTool.Icon = fullfile(pathToMLAPP, 'icons', 'cross-close-svgrepo-com.svg');
            app.customClock = customClock(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.Tag = 'image';
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'icons', 'calendar-clock-svgrepo-com.svg');
            app.recordButton = uibutton(app.UIFigure, 'push');
            app.recordButton.ButtonPushedFcn = createCallbackFcn(app, @recordButtonPushed, true);
            app.recordButton.Tag = 'button';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'images', 'recordPatient.png');
            app.scheduleButton = uibutton(app.UIFigure, 'push');
            app.scheduleButton.Tag = 'button';
            app.Image5_4 = uiimage(app.UIFigure);
            app.Image5_4.ImageSource = fullfile(pathToMLAPP, 'images', 'calendar.png');
            app.searchButton = uibutton(app.UIFigure, 'push');
            app.searchButton.ButtonPushedFcn = createCallbackFcn(app, @searchButtonPushed, true);
            app.searchButton.Tag = 'button';
            app.Image5_5 = uiimage(app.UIFigure);
            app.Image5_5.ImageSource = fullfile(pathToMLAPP, 'images', 'searchPatient.png');
            app.modifyButton = uibutton(app.UIFigure, 'push');
            app.modifyButton.Tag = 'button';
            app.Image5_6 = uiimage(app.UIFigure);
            app.Image5_6.ImageSource = fullfile(pathToMLAPP, 'images', 'modifyRecord.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selectAction(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end