classdef usersManagement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Toolbar                      matlab.ui.container.Toolbar
        infoTool                     matlab.ui.container.toolbar.PushTool
        screenTool                   matlab.ui.container.toolbar.ToggleTool
        themeTool                    matlab.ui.container.toolbar.ToggleTool
        configTool                   matlab.ui.container.toolbar.PushTool
        logoutTool                   matlab.ui.container.toolbar.PushTool
        closeTool                    matlab.ui.container.toolbar.PushTool
        changeInstitutionCheckBox    matlab.ui.control.CheckBox
        institutionNamePanel         matlab.ui.container.Panel
        changeInstitutionNameButton  matlab.ui.control.Button
        createExampleIDButton        matlab.ui.control.Button
        idExampleDLabel              matlab.ui.control.Label
        idExampleLabel               matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        institutionWarnLabel         matlab.ui.control.Label
        institutionNameEditField     matlab.ui.control.EditField
        institutionNameLabel         matlab.ui.control.Label
        adminRolesPanel              matlab.ui.container.Panel
        loginActionsPanel            matlab.ui.container.Panel
        selectedAdminEditField       matlab.ui.control.EditField
        selectedAdminLabel           matlab.ui.control.Label
        changePasswordPanel          matlab.ui.container.Panel
        changePasswordButton         matlab.ui.control.Button
        confirmNewPasswordLabel      matlab.ui.control.Label
        newPasswordLabel             matlab.ui.control.Label
        ccPasswordField              wt.PasswordField
        cPasswordField               wt.PasswordField
        changePasswordCheckBox       matlab.ui.control.CheckBox
        deleteLoginButton            matlab.ui.control.Button
        enableLoginButton            matlab.ui.control.Button
        disableLoginButton           matlab.ui.control.Button
        adminTableLabel              matlab.ui.control.Label
        refreshAdminButton           matlab.ui.control.Button
        adminRolesUITable            matlab.ui.control.Table
        createUserPanel              matlab.ui.container.Panel
        refreshDatabasesLabel        matlab.ui.control.Label
        refreshDatabasesButton       matlab.ui.control.Button
        defaultDatabaseDropDown      matlab.ui.control.DropDown
        defaultDatabaseLabel         matlab.ui.control.Label
        adminRoleDropDown            matlab.ui.control.DropDown
        adminRoleLabel               matlab.ui.control.Label
        adminSLNameEditField         matlab.ui.control.EditField
        adminSLNameLabel             matlab.ui.control.Label
        adminFLNameEditField         matlab.ui.control.EditField
        adminFLNameLabel             matlab.ui.control.Label
        adminNameEditField           matlab.ui.control.EditField
        adminNameLabel               matlab.ui.control.Label
        recordAdminButton            matlab.ui.control.Button
        confirmPasswordLabel         matlab.ui.control.Label
        passwordLabel                matlab.ui.control.Label
        confirmPasswordField         wt.PasswordField
        PasswordField                wt.PasswordField
    end

    
    properties (Access = private)
        %% Theme ,window and language control
        currentTheme           % 0 for light theme and 1 for dark theme
        currentWindowState     % 0 for maximized screen and 1 for fullscren
        currentLanguage
        
        %% Dark and light themes colors
        darkThemeColorUIF =  [0,0,0]; 
        darkThemeColorToolBar = [15,15,15]/100;
        darkThemeColorBGLabels = "none";
        darkThemeColorBGContainers= [12,12,12]/100;
        darkThemeColorBGPanels = [7,7,7]/100;
        darkThemeFontColor = [94,94,94]/100;

        lightThemeColorUIF = [94, 94, 94]/100;
        lightThemeColorToolBar = '#3db52d';
        lightThemeColorBGLabels = "none";
        lightThemeColorBGContainers= [1,1,1];
        lightThemeColorBGPanels = [90,90,90]/100;
        lightThemeFontColor = [0,0,0]; 

        %% Language control
        usersManagementLanguage
        adminTypesLanguage


        %% Origin path control
        originPath

        %% Data base management
        conn
        defaultDatabase
        dbSource

        %% Column names
        adminIDCN
        usernameCN
        disabledCN
        nameCN
        lastNameCN
        secondLastNameCN
        patientIndexCN
        loginTypeCN
        serverRoleCN
        roleCodeCN


        %% Admin control
        selectedAdmin
        selectedAdminRoleCode
        adminName
        adminFLName
        adminSLName
        password 
        confirmationPassword
        newPassword
        confirmationNewPassword

        adminTypes 
        roleCode
        roleCodes
        adminRoleCode

        tempInstitutionName
        institutionName


        %% Fields control
        emptyNameFlag = true;
        emptyFLNameFlag = true;
        emptySLNameFlag = true;
        emptyPasswordFlag = true;
        emptyConfPassword = true;

        %% Empty fields messages
        emptyNameMessage
        emptyFLNameMessage
        emptySLNameMessage
        emptyPasswordMessage
        emptyConfPasswordMessage
        emptyNPasswordMessage
        emptyNConfPasswordMessage
        emptyFieldsMessage
        emptyFieldsTitle
        TeXFormat
        passwordMatchError
        passwordMatchTitle

        %% Error messages
        adminQueryError
        adminQueryErrorTitle
        loginCreationError
        loginCreationErrorTitle
        disableLoginError
        disableLoginErrorTitle
        enableLoginError
        enableLoginErrorTitle
        noneSelectedAdmin
        noneSelectedAdminTitle
        errorMessage
        passwordChangeError
        passwordChangeErrorTitle
        deleteLoginError
        deleteLoginErrorTitle


        %% Success messages
        loginCreationSuccess
        loginCreationSuccessTitle
        validatingInfo
        creatingLogin
        disableLoginSuccess
        enableLoginSuccess
        passwordChangeSuccess
        disableLoginSuccessTitle
        enableLoginSuccessTitle
        passwordChangeSuccessTitle
        changingPasswordMessage
        changeInstitutionNameSuccess
        changeInstitutionNameStop
        executingActionMessage
        changingInstitutionNameMessage
        enablingLoginMessage
        disablingLoginMessage
        deletingLoginMessage
        deleteLoginSuccess
        deleteLoginSuccessTitle


        %% UI confirm messages
        changeInstitutionQuestion
        changeInstitutionQuestionTitle
        deleteLoginQuestion
        deleteLoginQuestionTitle
        yesToQuestion
        noToQuestion
        deleteLoginCancel

        %% UI messages
        infoTitle
        infoToolMessage

        %% UI components
        buttons 
        dropDowns 
        efields 
        labels 
        panels 
        checkBoxes

        %% Configurations app control
        configurationsApp

        %% Icon paths
        infoIconPath
        successIconPath
        warningIconPath
        errorIconPath
        enableLoginIconPath
        disableLoginIconPath
        deleteLoginIconPath
        questionIconPath

    end

    properties(SetAccess = immutable)
        %% SQL querys
        rolesQuery = "SELECT admin_id,username,disabled,name,last_name," + ...
            "second_lastname,patient_index,LoginType,ServerRole,admin_role_code " + ...
            "FROM admins " + ...
            "INNER JOIN(" + ...
            "SELECT " + ...
            "sp.name AS LoginName," + ...
            "sp.type_desc AS LoginType," + ...
            "sp.is_disabled AS disabled," + ...
            "ISNULL(srvroles.name, 'Public') AS ServerRole " + ...
            "FROM sys.server_principals sp " + ...
            "LEFT JOIN sys.server_role_members srm ON sp.principal_id = srm.member_principal_id " + ...
            "LEFT JOIN sys.server_principals srvroles ON srm.role_principal_id = srvroles.principal_id " + ...
            "WHERE sp.type IN ('S') " + ...
            "AND sp.name NOT LIKE '##%s' " + ...
            "AND sp.name NOT LIKE '%s'" + ...
            ") AS Subquery ON admins.username = Subquery.LoginName " + ...
            "ORDER BY username ASC;"

        createAdmin = "INSERT INTO admins VALUES('%s','%s','%s',%g,1)"

        createSysLogin = "BEGIN TRY " + ...
            "BEGIN TRAN " + ...
            "CREATE LOGIN %s WITH PASSWORD = '%s'" + ...
            ", CHECK_POLICY = OFF, CHECK_EXPIRATION = OFF ;" + ...
            "ALTER SERVER ROLE sysadmin ADD MEMBER %s; " + ...
            "COMMIT TRAN; " + ...
            "END TRY " + ...
            "BEGIN CATCH " + ...
            "ROLLBACK TRAN; " + ...
            "END CATCH"

        createDBLogin = "BEGIN TRY " + ...
            "BEGIN TRAN " + ...
            "CREATE LOGIN %s WITH PASSWORD = '%s'" + ...
            ",DEFAULT_DATABASE = %s, CHECK_POLICY = OFF, CHECK_EXPIRATION = OFF ; " + ...
            "CREATE USER %s FOR LOGIN %s; " + ...
            "ALTER ROLE db_datareader ADD MEMBER %s; " + ...
            "ALTER ROLE db_datawriter ADD MEMBER %s; " + ...
            "COMMIT TRAN; " + ...
            "END TRY " + ...
            "BEGIN CATCH " + ...
            "ROLLBACK TRAN; " + ...
            "END CATCH"

        getUsername = "SELECT username FROM admins WHERE admin_id = (SELECT max(admin_id) FROM admins)"

        getDefaultDatabases = "SELECT name FROM master.dbo.sysdatabases"

        getUserDefaultDatabase = "SELECT default_database_name FROM sys.sql_logins WHERE name = '%s'"

        enableLogin = "ALTER LOGIN %s ENABLE"
        disableLogin = "ALTER LOGIN %s DISABLE"
        changePassword = "ALTER LOGIN %s WITH PASSWORD = '%s'"
        deleteDBLogin = "BEGIN TRY " + ...
            "BEGIN TRAN " + ...
            "DROP USER %s; " + ...
            "DROP LOGIN %s; " + ...
            "DELETE FROM admins WHERE username = '%s'; " + ...
            "COMMIT TRAN; " + ...
            "END TRY " + ...
            "BEGIN CATCH " + ...
            "ROLLBACK TRAN; " + ...
            "END CATCH "

        deleteSysLogin = "BEGIN TRY " + ...
            "BEGIN TRAN " + ...
            "DROP LOGIN %s; " + ...
            "DELETE FROM admins WHERE username = '%s'; " + ...
            "COMMIT TRAN; " + ...
            "END TRY " + ...
            "BEGIN CATCH " + ...
            "ROLLBACK TRAN; " + ...
            "END CATCH "

        %% Configurations app control
        canModify = 0;
    end
    
    methods (Access = private)
        %% Get all admins with their roles
        function adminsAndRoles = getAllAdmins(app)
            colNames = {app.adminIDCN,app.usernameCN,app.disabledCN,...
                app.nameCN,app.lastNameCN,app.secondLastNameCN,...
                app.patientIndexCN,app.loginTypeCN,app.serverRoleCN,app.roleCodeCN};
                app.adminRolesUITable.ColumnName = colNames;
            try
                query = sprintf(app.rolesQuery,"%",app.conn.UserName);
                adminsAndRoles = fetch(app.conn,query);
                app.adminRolesUITable.Data = adminsAndRoles;
            catch
                %% Manage error
                uialert(app.UIFigure,app.adminQueryError,app.adminQueryErrorTitle,...
                    "Icon",app.errorIconPath)
            end
        end

        %% Set language

            function setLanguage(app,languageValue)
                %% Loading language table
                app.currentLanguage = languageValue;
                component = app.usersManagementLanguage.componentName;
                property = app.usersManagementLanguage.propertyName;
                label = app.usersManagementLanguage.(languageValue);
                app.adminTypesLanguage = app.adminTypes.(languageValue);
                adminTypesItems = app.adminTypesLanguage ;
                app.roleCodes = app.adminTypes.roleCode;
                app.adminRoleDropDown.Items = adminTypesItems;
                app.adminRoleDropDown.Value = app.getAdminTypeByCode(app.adminRoleCode);
                
                %% Changing language
                for i = 1 : length(component)
                    if(property{i} == "none")
                        app.(component{i}) = label{i};
                    else
                        app.(component{i}).(property{i}) = label{i};
                    end
                end

                app.getAllAdmins();
            end
        
        function setDarkTheme(app)
             %% Updating current theme an icon
            app.themeTool.State = "on";
            app.currentTheme = 1;
            iconPath = fullfile(app.originPath,"icons\sun-svgrepo-com-LC-White.svg");
            app.themeTool.Icon = iconPath;
            
            %% Changing colors 
            app.UIFigure.Color = app.darkThemeColorUIF;
            app.Toolbar.BackgroundColor = app.darkThemeColorToolBar;
            app.adminRolesUITable.BackgroundColor = [app.darkThemeColorBGContainers;app.darkThemeColorBGContainers];
            app.adminRolesUITable.ForegroundColor = app.darkThemeFontColor;
            app.PasswordField.BackgroundColor = app.darkThemeColorBGContainers;
            app.confirmPasswordField.BackgroundColor = app.darkThemeColorBGContainers;

            for i = 1 : length(app.buttons)
                app.buttons(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.buttons(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.checkBoxes)
                app.checkBoxes(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.dropDowns)
                app.dropDowns(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.dropDowns(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.efields)
                app.efields(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.efields(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.labels)
                app.labels(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.panels)
                app.panels(i).BackgroundColor = app.darkThemeColorBGPanels;
                app.panels(i).ForegroundColor = app.darkThemeFontColor;
            end

            app.selectedAdminEditField.BackgroundColor = [0,1,0];
            app.selectedAdminEditField.FontColor = [1,1,1];

        end
        
        function setLightTheme(app)
             %% Updating state, current theme an icon
            app.themeTool.State = "off";
            app.currentTheme = 0;
            iconPath = fullfile(app.originPath,"icons\moon-stars-svgrepo-com-LC-Black.svg");
            app.themeTool.Icon = iconPath;

            %% Changing colors
            app.UIFigure.Color = app.lightThemeColorUIF;
            app.Toolbar.BackgroundColor = app.lightThemeColorToolBar;
            app.adminRolesUITable.BackgroundColor = [app.lightThemeColorBGContainers;app.lightThemeColorBGContainers];
            app.adminRolesUITable.ForegroundColor = app.lightThemeFontColor;
            app.PasswordField.BackgroundColor = app.lightThemeColorBGContainers;
            app.confirmPasswordField.BackgroundColor = app.lightThemeColorBGContainers;

            for i = 1 : length(app.buttons)
                app.buttons(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.buttons(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.checkBoxes)
                app.checkBoxes(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.dropDowns)
                app.dropDowns(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.dropDowns(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.efields)
                app.efields(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.efields(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.labels)
                app.labels(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.panels)
                app.panels(i).BackgroundColor = app.lightThemeColorBGPanels;
                app.panels(i).ForegroundColor = app.lightThemeFontColor;
            end

            app.selectedAdminEditField.BackgroundColor = [0,1,0];
            app.selectedAdminEditField.FontColor = [0,0,0];
        end
        
        
        function adminType = getAdminTypeByCode(app,roleCode)
            adminType = app.adminTypesLanguage(app.roleCodes == roleCode);
        end
        
        function roleCode = getCodeByAdminType(app,adminType)
            roleCode = app.roleCodes(app.adminTypesLanguage == string(adminType));
        end
        
        function resetRegistAdminPanel(app)
            %% Clean admin information
            app.adminName = [];
            app.adminFLName = [];
            app.adminSLName = [];
            app.password = [];
            app.confirmationPassword = [];

            %% Clean edit fields
            app.adminNameEditField.Value = '';
            app.adminFLNameEditField.Value = '';
            app.adminSLNameEditField.Value = '';
            app.PasswordField.Value = '';
            app.confirmPasswordField.Value = '';

            app.adminRoleCode = 1;
            app.adminRoleDropDown.Value = app.getAdminTypeByCode(app.adminRoleCode);

            %% Disable default database drop down
            app.defaultDatabaseDropDown.Enable = "off";
            app.defaultDatabaseDropDown.Value = 'master';

        end
        
        function setDefaultDatabases(app)
            defaultDatabases = fetch(app.conn,app.getDefaultDatabases);
            defaultDatabases = table2array(defaultDatabases);
            app.defaultDatabaseDropDown.Items = defaultDatabases;
        end
        
        function saveSettings(app)
            %% Save settings 
            windowState = app.currentWindowState;
            theme = app.currentTheme;
            language = app.currentLanguage;
            lastSessionPath = fullfile(app.originPath,"settings","lastSessionSettings.mat");
            save(lastSessionPath,'windowState','theme','language')
        end
        
        function disableAdminActions(app)
            % app.enableLoginButton.Enable = "off";
            % app.disableLoginButton.Enable = "off";
            % app.deleteLoginButton.Enable = "off";

            app.loginActionsPanel.Enable = "off";
        end

        function enableAdminActions(app)
            % app.enableLoginButton.Enable = "on";
            % app.disableLoginButton.Enable = "on";
            % app.deleteLoginButton.Enable = "on";

            app.loginActionsPanel.Enable = "on";
        end

        %% Set and get methods for change password
        function setNewPassword(app,password)
            app.newPassword = password;
        end
        
       function password = getNewPassword(app)
            password = app.newPassword;
        end
    
        function setConfirmationNewPassword(app,password)
           app.confirmationNewPassword = password;
        end
    
        function password = getConfirmationNewPassword(app)
            password = app.confirmationNewPassword;
        end

        %% Set and get methods for register admin passwords
        function setPassword(app,password)
            app.password = password;
        end
        
       function password = getPassword(app)
            password = app.password;
        end
    
        function setConfirmationPassword(app,password)
           app.confirmationPassword = password;
        end
    
        function password = getConfirmationPassword(app)
            password = app.confirmationPassword;
        end
        
        function resetChangePasswordPanel(app)
            app.newPassword = '';
            app.confirmationNewPassword = '';
            
            app.cPasswordField.Value = app.newPassword;
            app.ccPasswordField.Value = app.confirmationNewPassword;
            app.changePasswordCheckBox.Value = 0;
            app.changePasswordPanel.Enable = "off";
        end
        
        function createExampleID(app)
            eInstitutionName = app.tempInstitutionName;

            %% Generate random admin id and arbitrary patient number
            eAdminID = dec2hex(randi(65535,1));
            ePatientNumber = string(1);
            eRegistYear = string(year(datetime));
            
            %% Create and show ID
            ePatientID = sprintf("%04s%05s%04s%s",eAdminID,ePatientNumber,...
                eRegistYear,eInstitutionName);
            app.idExampleDLabel.Text = ePatientID;

            
        end
        
        function institutionAction(app,~,event)
            %% Create bar to indicate progress 
            progressBar = uiprogressdlg(app.UIFigure,"Indeterminate","on");
            progressBar.Message = app.executingActionMessage;
            pause(0.5)

            %% Executing action
            if event.SelectedOptionIndex == 1 % Change institution name
                progressBar.Message = app.changingInstitutionNameMessage;
                pause(0.5)
                institutionNameSaved = app.tempInstitutionName;
                
                %% Save new institution name
                path = fullfile(app.originPath,"settings","institutionName.mat");
                save(path,'institutionNameSaved');

                %% Create and show success message
                str = sprintf(app.changeInstitutionNameSuccess,institutionNameSaved);
                uialert(app.UIFigure,str,app.changeInstitutionQuestionTitle,...
                    "Icon",app.successIconPath)
                app.resetInstitutionNamePanel();

            else  % Do not change institution name
                app.loadInstitutionName();
                app.createExampleID();
                str = sprintf(app.changeInstitutionNameStop,app.institutionName);
                uialert(app.UIFigure,str,app.changeInstitutionQuestionTitle,...
                    "Icon",app.infoIconPath)
               return
            end
        end
        
        function loadInstitutionName(app)
            
            try %% Load file
                path = fullfile(app.originPath,"settings","institutionName.mat");
                institution = load(path);
                app.institutionName = institution.institutionNameSaved;
            catch %% Create file with default institution name (IIIII)
                path = fullfile(app.originPath,"settings","institutionName.mat");
                institutionNameSaved = "IIIII";
                save(path,"institutionNameSaved")
            end

            app.institutionNameEditField.Value = app.institutionName;
            app.tempInstitutionName = app.institutionNameEditField.Value;
            app.changeInstitutionNameButton.Enable = "off";

        end
        
        function resetInstitutionNamePanel(app)
            app.institutionNamePanel.Enable = "off";
            app.idExampleDLabel.Text = '';
            app.changeInstitutionCheckBox.Value = 0;
            app.loadInstitutionName();
        end
        
        function useAdminDefaultDB(app,admin)
            %% Use selected admin default database
            query = sprintf(app.getUserDefaultDatabase,admin);
            userDefaultDatabase = fetch(app.conn,query);
            userDefaultDatabase = table2cell(userDefaultDatabase);
            userDefaultDatabase = userDefaultDatabase{:};
            query = sprintf("USE %s;",userDefaultDatabase);
            execute(app.conn,query);
        end
        
        function deleteLoginAction(app,~,event)
            %% Show progress and messages to user
            progressBar = uiprogressdlg(app.UIFigure,"Indeterminate","on");
            progressBar.Message = app.executingActionMessage;
            pause(0.1)
            if event.SelectedOptionIndex == 1 % Delete login    
                try
                    if isempty(app.selectedAdmin)
                        uialert(app.UIFigure,app.noneSelectedAdmin,app.noneSelectedAdminTitle,...
                            "Icon",app.warningIconPath)
                        return
                    else
                        progressBar.Message = app.deletingLoginMessage;
                        pause(0.1)
    
                        if app.selectedAdminRoleCode == 1
    
                            %% Execute query
                            query = sprintf(app.deleteSysLogin,app.selectedAdmin,app.selectedAdmin);
                            execute(app.conn,query);
                            str = sprintf(app.deleteLoginSuccess,app.selectedAdmin);
        
                            %% Show success message
                            uialert(app.UIFigure,str,app.deleteLoginSuccessTitle,...
                                "Icon",app.infoIconPath)
                            app.refreshAdminButtonPushed
    
                        elseif app.selectedAdminRoleCode == 2
    
                            %% Execute query
                            query = sprintf(app.deleteDBLogin,app.selectedAdmin,app.selectedAdmin,app.selectedAdmin);
                            execute(app.conn,query);
                            str = sprintf(app.deleteLoginSuccess,app.selectedAdmin);
        
                            %% Show success message
                            uialert(app.UIFigure,str,app.deleteLoginSuccessTitle,...
                                "Icon",app.infoIconPath)
                            app.refreshAdminButtonPushed
                            
                        end
                    end
                catch Me
                    %% Create and show error message
                    str = sprintf(app.deleteLoginError,app.selectedAdmin);
                    str = vertcat(str,"");
                    meError = append(app.errorMessage," ",Me.message);
                    str = vertcat(str,meError);
                    uialert(app.UIFigure,str,app.deleteLoginErrorTitle,...
                        "Icon",app.errorIconPath)
                end
            else % Do not delete login
                pause(0.2)
                str = sprintf(app.deleteLoginCancel,app.selectedAdmin);
                uialert(app.UIFigure,str,app.deleteLoginQuestionTitle,...
                    "Icon",app.infoIconPath)
            end
        end

    end
    
        
    methods (Access = public)
        %% Updating language and database source from configurations app
        function updateConfigurations(app,dbSource,language)
            app.setLanguage(language)

            if ~isempty(dbSource)
                app.dbSource = dbSource;
                dbSourcePath = fullfile(app.originPath,"settings","dbSource.mat");
                save(dbSourcePath,'dbSource');
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, windowState, theme, language, database)

            %% Initial settings
            if isdeployed
                app.originPath = fullfile(ctfroot,"login");
            else
                app.originPath = pwd;
            end

            %% Get icon paths
            app.infoIconPath = fullfile(app.originPath,"icons\sign-info-svgrepo-com.svg");
            app.successIconPath = fullfile(app.originPath,"icons\success-icon.svg");
            app.warningIconPath = fullfile(app.originPath,"icons\warning-svgrepo-com.svg");
            app.errorIconPath = fullfile(app.originPath,"icons\error-svgrepo-com.svg");
            app.enableLoginIconPath = fullfile(app.originPath,"icons\enable-svgrepo-com.svg");
            app.disableLoginIconPath = fullfile(app.originPath,"icons\disable-svgrepo-com.svg");
            app.deleteLoginIconPath = fullfile(app.originPath,"icons\delete-user-svgrepo-com.svg");
            app.questionIconPath = fullfile(app.originPath,"icons\question-svgrepo-com.svg");

            %% Get connection object and configure initual values
            app.conn = database;
            app.adminRoleCode = 1;
            app.defaultDatabase = 'master';

            %% Load language file and institution name
            languagePath = fullfile(app.originPath,"language\usersManagementLanguage.xlsx");
            app.usersManagementLanguage = readtable(languagePath,"Sheet","language");
            app.adminTypes = readtable(languagePath,"Sheet","adminTypes");

            app.loadInstitutionName();


            %% Setting up window state
            if windowState
                app.screenToolOn
            else
                app.screenToolOff
            end
            
            %% Setting up theme
            % Obtaining ui componentes
            app.buttons = findobj(app.UIFigure,'Type','uibutton');
            app.checkBoxes = findobj(app.UIFigure,'Type','uicheckbox');
            app.dropDowns = findobj(app.UIFigure,'Type','uidropdown');
            app.efields = findobj(app.UIFigure,'Type','uieditfield');
            app.labels = findobj(app.UIFigure,'Type','uilabel');
            app.panels = findobj(app.UIFigure,'Type','uipanel');

            if theme
                app.setDarkTheme
            else
                app.setLightTheme
            end
                
            %% Setting up language
            app.setLanguage(language)

            %% Get all admins and roles
            app.getAllAdmins();

            %% set default databases
            app.setDefaultDatabases();

            %% Enable or disable default database drop down
            if app.adminRoleCode == 2
                app.defaultDatabaseDropDown.Enable = "on";
            else
                app.defaultDatabaseDropDown.Enable = "off";
            end

            app.disableAdminActions();
            app.changePasswordPanel.Enable = "off";

            %% Disable change institution name panel
            app.institutionNamePanel.Enable = "off";
        end

        % Callback function: PasswordField
        function PasswordFieldValueChanging(app, event)
            changingValue = event.Value;
            app.password = changingValue;
        end

        % Callback function: confirmPasswordField
        function confirmPasswordFieldValueChanging(app, event)
            changingValue = event.Value;
            app.confirmationPassword = changingValue;
        end

        % Button pushed function: recordAdminButton
        function recordAdminButtonPushed(app, event)
            progressBar = uiprogressdlg(app.UIFigure,"Indeterminate","on");
            progressBar.Message = app.validatingInfo;
            pause(0.5)
            
            %% Check if all fields are filled
            emptyFieldStr = vertcat(app.emptyFieldsMessage,"");
            emptyFieldFlag = false;

            if app.emptyNameFlag || isempty(app.adminName)
                emptyFieldFlag = true;
                str = append(app.TeXFormat," ",app.emptyNameMessage);
                emptyFieldStr = vertcat(emptyFieldStr,str);
            end

            if app.emptyFLNameFlag || isempty(app.adminFLName)
                emptyFieldFlag = true;
                str = append(app.TeXFormat," ",app.emptyFLNameMessage);
                emptyFieldStr = vertcat(emptyFieldStr,str);
            end

            if app.emptySLNameFlag || isempty(app.adminSLName)
                emptyFieldFlag = true;
                str = append(app.TeXFormat," ",app.emptySLNameMessage);
                emptyFieldStr = vertcat(emptyFieldStr,str);
            end

            if isempty(convertStringsToChars(app.getPassword))
                emptyFieldFlag = true;
                str = append(app.TeXFormat," ",app.emptyPasswordMessage);
                emptyFieldStr = vertcat(emptyFieldStr,str);
            end

            if isempty(convertStringsToChars(app.getConfirmationPassword))
                emptyFieldFlag = true;
                str = append(app.TeXFormat," ",app.emptyConfPasswordMessage);
                emptyFieldStr = vertcat(emptyFieldStr,str);
            end

            if emptyFieldFlag
                uialert(app.UIFigure,emptyFieldStr,app.emptyFieldsTitle...
                    ,"Interpreter","tex","Icon",app.warningIconPath)
                return
            end
            

            %% Check if passwords match
            if app.password == app.confirmationPassword
                progressBar.Message = app.creatingLogin;
                pause(0.5)
                if app.adminRoleCode == 1
                    try
                        %% Regist administrator in admins table
                        query = sprintf(app.createAdmin,app.adminName,...
                            app.adminFLName,app.adminSLName,app.adminRoleCode);
                        % 
                        execute(app.conn,query);
                        username = fetch(app.conn,app.getUsername);
                        username = table2array(username);
                        username = username{:};

                        %% Create login 
                        query = sprintf(app.createSysLogin,username,app.password...
                            ,username);
                        execute(app.conn,query);
                        %% Show successfully creation message
                        adminType = string(app.getAdminTypeByCode(app.adminRoleCode));
                        creationSuccess = sprintf(app.loginCreationSuccess,...
                            lower(adminType),username);
                        uialert(app.UIFigure,creationSuccess,app.loginCreationSuccessTitle...
                            ,"icon",app.successIconPath)
                        app.getAllAdmins();
                        app.resetRegistAdminPanel();
                    catch 
                        uialert(app.UIFigure,app.loginCreationError,app.loginCreationErrorTitle,...
                            "Icon",app.errorIconPath)
                    end
                else
                    try
                        %% Regist administrator in admins table
                        query = sprintf(app.createAdmin,app.adminName,...
                            app.adminFLName,app.adminSLName,app.adminRoleCode);
                        % 
                        execute(app.conn,query);
                        username = fetch(app.conn,app.getUsername);
                        username = table2array(username);
                        username = username{:};
    
                        %% Create login and user
                        query = sprintf(app.createDBLogin,username,app.password...
                            ,app.defaultDatabase,username,username,username,username);
                        execute(app.conn,query);
                        %% Show successfully creation message
                        adminType = string(app.getAdminTypeByCode(app.adminRoleCode));
                        creationSuccess = sprintf(app.loginCreationSuccess,...
                            lower(adminType),username);
                        uialert(app.UIFigure,creationSuccess,app.loginCreationSuccessTitle,...
                            "icon",app.successIconPath)
                        app.getAllAdmins();
                        app.resetRegistAdminPanel();
                    catch 
                        uialert(app.UIFigure,app.loginCreationError,app.loginCreationErrorTitle,...
                            "Icon",app.errorIconPath)
                    end
                end
            else
                %% Password matching error
                uialert(app.UIFigure,app.passwordMatchError,app.passwordMatchTitle,...
                    "Icon",app.warningIconPath)
                return
            end
        end

        % Cell selection callback: adminRolesUITable
        function adminRolesUITableCellSelection(app, event)
            %% Get indices of selected admin and check if the field is empty
            app.resetChangePasswordPanel();
            indices = event.Indices;
            if isempty(indices)
                app.disableAdminActions
                app.selectedAdmin = [];
                app.selectedAdminEditField.Value = '';
                app.selectedAdminRoleCode = [];
                return
            end

            %% Get selected admin ID
            app.selectedAdmin = app.adminRolesUITable.Data(indices(1),2);
            app.selectedAdmin = table2array(app.selectedAdmin);
            app.selectedAdmin = app.selectedAdmin{:};
            app.selectedAdminEditField.Value = app.selectedAdmin;

            %% Get is disabled field
            isDisabled = app.adminRolesUITable.Data(indices(1),3);
            isDisabled = table2array(isDisabled);

            %% Get admin role code 
            app.selectedAdminRoleCode = app.adminRolesUITable.Data(indices(1),10);
            app.selectedAdminRoleCode = table2array(app.selectedAdminRoleCode);

            if isempty(app.selectedAdmin)
                app.disableAdminActions
            else
                app.enableAdminActions
                if isDisabled
                    app.enableLoginButton.Enable = "on";
                    app.disableLoginButton.Enable = "off";
                else
                    app.enableLoginButton.Enable = "off";
                    app.disableLoginButton.Enable = "on";
                end
            end
        end

        % Button pushed function: refreshAdminButton
        function refreshAdminButtonPushed(app, event)
            %% disable change password panel and delete data from table
            app.resetChangePasswordPanel();
            app.adminRolesUITable.Data = [];
            app.selectedAdmin = [];

            %% delete selected admin and disable admin actions panel
            app.selectedAdminEditField.Value = '';
            app.disableAdminActions();

            %% Enable enable/disable login buttons
            app.enableLoginButton.Enable = "on";
            app.disableLoginButton.Enable = "on";

            %% Get all admins again and fill the table
            pause(0.1)
            app.getAllAdmins();
        end

        % Value changed function: adminNameEditField
        function adminNameEditFieldValueChanged(app, event)
            value = app.adminNameEditField.Value;
            app.adminName = value;
            if isempty(app.adminName)
                app.emptyNameFlag = true;
            else
                app.emptyNameFlag = false;
            end
        end

        % Value changed function: adminFLNameEditField
        function adminFLNameEditFieldValueChanged(app, event)
            value = app.adminFLNameEditField.Value;
            app.adminFLName = value;
            if isempty(app.adminFLName)
                app.emptyFLNameFlag = true;
            else
                app.emptyFLNameFlag = false;
            end
        end

        % Value changed function: adminSLNameEditField
        function adminSLNameEditFieldValueChanged(app, event)
            value = app.adminSLNameEditField.Value;
            app.adminSLName = value;
            if isempty(app.adminSLName)
                app.emptySLNameFlag = true;
            else
                app.emptySLNameFlag = false;
            end
        end

        % On callback: screenTool
        function screenToolOn(app, event)
            app.currentWindowState = 1;
            app.screenTool.State = "on";
            app.UIFigure.WindowState = "fullscreen";
        end

        % Off callback: screenTool
        function screenToolOff(app, event)
            app.currentWindowState = 0;
            app.screenTool.State = "off";
            app.UIFigure.WindowState = "maximized";
        end

        % Callback function: logoutTool
        function logoutToolClicked(app, event)
            %% Close current app and open log in app
            delete(timerfindall)
            login(app.currentWindowState,app.currentTheme,app.currentLanguage)
            delete(app)
        end

        % On callback: themeTool
        function themeToolOn(app, event)
            app.setDarkTheme
        end

        % Off callback: themeTool
        function themeToolOff(app, event)
            app.setLightTheme
        end

        % Value changed function: adminRoleDropDown
        function adminRoleDropDownValueChanged(app, event)
            value = app.adminRoleDropDown.Value;
            app.adminRoleCode = app.getCodeByAdminType(value);

            if app.adminRoleCode == 2
                app.defaultDatabaseDropDown.Enable = "on";
                app.defaultDatabase = app.defaultDatabaseDropDown.Value;
            else
                app.defaultDatabaseDropDown.Enable = "off";
                app.defaultDatabase = 'master';
            end
        end

        % Callback function: configTool
        function configToolClicked(app, event)
            %% Open configurations app
            app.configurationsApp = configurations(app.currentTheme...
                ,app.currentLanguage,usersManagement,app.canModify);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %% Save settings for next boot
            app.saveSettings

            %% Delete timers and apps
            delete(app.configurationsApp)
            delete(timerfindall)
            delete(app)
        end

        % Callback function: closeTool
        function closeToolClicked(app, event)
            app.UIFigureCloseRequest
        end

        % Button pushed function: refreshDatabasesButton
        function refreshDatabasesButtonPushed(app, event)
            app.setDefaultDatabases
        end

        % Value changed function: defaultDatabaseDropDown
        function defaultDatabaseDropDownValueChanged(app, event)
            value = app.defaultDatabaseDropDown.Value;
            app.defaultDatabase = value;
        end

        % Button pushed function: disableLoginButton
        function disableLoginButtonPushed(app, event)
            progressBar = uiprogressdlg(app.UIFigure,"Indeterminate","on");
            progressBar.Message = app.executingActionMessage;
            pause(0.1)

            %% Disable login
            try
                if isempty(app.selectedAdmin)
                    uialert(app.UIFigure,app.noneSelectedAdmin,app.noneSelectedAdminTitle,...
                        "Icon",app.warningIconPath)
                    return
                else
                    progressBar.Message = app.disablingLoginMessage;
                    pause(0.1)
                    %% Fetch query
                    query = sprintf(app.disableLogin,app.selectedAdmin);
                    fetch(app.conn,query);
                    str = sprintf(app.disableLoginSuccess,app.selectedAdmin);

                    %% Show success message
                    uialert(app.UIFigure,str,app.disableLoginSuccessTitle,...
                        "Icon",app.disableLoginIconPath)
                    app.getAllAdmins();
                    app.enableLoginButton.Enable = "on";
                    app.disableLoginButton.Enable = "off";
                end
            catch Me
                %% Create and show error message
                str = sprintf(app.disableLoginError,app.selectedAdmin);
                str = vertcat(str,"");
                meError = append(app.errorMessage," ",Me.message);
                str = vertcat(str,meError);
                uialert(app.UIFigure,str,app.disableLoginErrorTitle,...
                    "Icon",app.errorIconPath)
            end
        end

        % Button pushed function: enableLoginButton
        function enableLoginButtonPushed(app, event)
            progressBar = uiprogressdlg(app.UIFigure,"Indeterminate","on");
            progressBar.Message = app.executingActionMessage;
            pause(0.1)
            %% enable login
            try
                if isempty(app.selectedAdmin)
                    uialert(app.UIFigure,app.noneSelectedAdmin,app.noneSelectedAdminTitle,...
                        "Icon",app.warningIconPath)
                    return
                else
                    progressBar.Message = app.enablingLoginMessage;
                    pause(0.1)
                    %% Fetch query
                    query = sprintf(app.enableLogin,app.selectedAdmin);
                    fetch(app.conn,query);
                    str = sprintf(app.enableLoginSuccess,app.selectedAdmin);

                    %% Show success message
                    uialert(app.UIFigure,str,app.enableLoginSuccessTitle,...
                        "Icon",app.enableLoginIconPath)
                    app.getAllAdmins();
                    app.enableLoginButton.Enable = "off";
                    app.disableLoginButton.Enable = "on";
                end
            catch Me
                %% Create and show error message
                str = sprintf(app.enableLoginError,app.selectedAdmin);
                str = vertcat(str,"");
                meError = append(app.errorMessage," ",Me.message);
                str = vertcat(str,meError);
                uialert(app.UIFigure,str,app.enableLoginErrorTitle,...
                    "Icon",app.errorIconPath)
            end

        end

        % Value changed function: changePasswordCheckBox
        function changePasswordCheckBoxValueChanged(app, event)
            value = app.changePasswordCheckBox.Value;
            if value
                app.changePasswordPanel.Enable = "on";
            else
                app.changePasswordPanel.Enable = "off";
            end
        end

        % Button pushed function: changePasswordButton
        function changePasswordButtonPushed(app, event)
            %% Show progress to user
            progressBar = uiprogressdlg(app.UIFigure,"Indeterminate","on");
            progressBar.Message = app.validatingInfo;
            pause(0.5)
            
            %% Get passwords and initialize flags
            nPassword = app.getNewPassword();
            nConfirmationPassword = app.getConfirmationNewPassword();

            emptyFieldStr = vertcat(app.emptyFieldsMessage,"");
            newPasswordEF = 0;
            confirmNewPasswordEF = 0;

            %% Check if password fields are empty 
            if isempty(convertStringsToChars(nPassword))
                newPasswordEF = 1;
                str = append(app.TeXFormat," ",app.emptyNPasswordMessage);
                emptyFieldStr = vertcat(emptyFieldStr,str);
            end

            if isempty(convertStringsToChars(nConfirmationPassword))
                confirmNewPasswordEF = 1;
                str = append(app.TeXFormat," ",app.emptyNConfPasswordMessage);
                emptyFieldStr = vertcat(emptyFieldStr,str);
            end

            if newPasswordEF || confirmNewPasswordEF
                close(progressBar)
                uialert(app.UIFigure,emptyFieldStr,app.emptyFieldsTitle...
                    ,"Interpreter","tex","Icon",app.warningIconPath)
                return
            end

            %% Check if passwords match
            if nPassword == nConfirmationPassword
                progressBar.Message = app.changingPasswordMessage;
                pause(0.5)
                try
                    %% update password
                    query = sprintf(app.changePassword,app.selectedAdmin,app.getNewPassword);
                    fetch(app.conn, query);
    
                    %% Show succes message
                    str = sprintf(app.passwordChangeSuccess,app.selectedAdmin);
                    uialert(app.UIFigure,str,app.passwordChangeSuccessTitle,...
                        "Icon",app.successIconPath)
                    app.resetChangePasswordPanel();
                catch Me
                    %% Show error message
                    str = sprintf(app.passwordChangeError,app.selectedAdmin);
                    str = vertcat(str,"");
                    errorStr = append(app.errorMessage," ",Me.message);
                    str = vertcat(str,errorStr);
                    uialert(app.UIFigure,str,app.passwordChangeErrorTitle...
                        ,"Icon",app.errorIconPath)
                    app.resetChangePasswordPanel();
                end
            else
                %% Password matching error
                uialert(app.UIFigure,app.passwordMatchError,app.passwordMatchTitle,...
                    "Icon",app.warningIconPath)
                return
            end
        end

        % Callback function: cPasswordField
        function cPasswordFieldValueChanging(app, event)
            changingValue = event.Value;
            app.setNewPassword(changingValue);
        end

        % Callback function: ccPasswordField
        function ccPasswordFieldValueChanging(app, event)
            changingValue = event.Value;
            app.setConfirmationNewPassword(changingValue);
        end

        % Button pushed function: createExampleIDButton
        function createExampleIDButtonPushed(app, event)
            app.createExampleID();
        end

        % Value changed function: changeInstitutionCheckBox
        function changeInstitutionCheckBoxValueChanged(app, event)
            value = app.changeInstitutionCheckBox.Value;
            if value
                app.loadInstitutionName();
                app.institutionNamePanel.Enable = "on";
                app.createExampleID();
            else
                app.loadInstitutionName();
                app.institutionNamePanel.Enable = "off";
                app.idExampleDLabel.Text = '';
            end
        end

        % Button pushed function: changeInstitutionNameButton
        function changeInstitutionNameButtonPushed(app, event)
            %% Ask to confirm institution name change 
            str = sprintf(app.changeInstitutionQuestion,app.institutionName,...
                app.tempInstitutionName);
            uiconfirm(app.UIFigure,str,...
                app.changeInstitutionQuestionTitle,"Icon",app.questionIconPath,...
                "CloseFcn",@app.institutionAction,"Options",{app.yesToQuestion,...
                app.noToQuestion},"CancelOption",2);
        end

        % Value changed function: institutionNameEditField
        function institutionNameEditFieldValueChanged(app, event)
            %% Store institution name for possible change
            value = app.institutionNameEditField.Value;
            len = length(value);
            if len <= 5
                toFill = 5 - len;
                fillOut = repelem('X',toFill);
                value = [fillOut,value];
            end
            
            app.tempInstitutionName = value;
            
            %% Enable or disable change institution name
            if string(app.tempInstitutionName) == string(app.institutionName)
                app.changeInstitutionNameButton.Enable = "off";
                app.createExampleID();
            else
                app.changeInstitutionNameButton.Enable = "on";
                app.createExampleID();
            end

        end

        % Button pushed function: deleteLoginButton
        function deleteLoginButtonPushed(app, event)
            %% Check if an admin has been selected
            if isempty(app.selectedAdmin)
                    uialert(app.UIFigure,app.noneSelectedAdmin,app.noneSelectedAdminTitle,...
                        "Icon",app.warningIconPath)
                    return
            end

            %% Ask to confirm delete login 
            str = sprintf(app.deleteLoginQuestion,app.selectedAdmin);
            uiconfirm(app.UIFigure,str,...
                app.deleteLoginQuestionTitle,"Icon",app.questionIconPath,...
                "CloseFcn",@app.deleteLoginAction,"Options",{app.yesToQuestion,...
                app.noToQuestion},"CancelOption",2);
            
        end

        % Callback function: infoTool
        function infoToolClicked(app, event)
            message = sprintf(app.infoToolMessage);
            uialert(app.UIFigure,message,app.infoTitle,...
                "Icon",app.infoIconPath)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.infoTool = uipushtool(app.Toolbar);
            app.infoTool.ClickedCallback = createCallbackFcn(app, @infoToolClicked, true);
            app.infoTool.Icon = fullfile(pathToMLAPP, 'icons', 'sign-info-svgrepo-com.svg');
            app.screenTool = uitoggletool(app.Toolbar);
            app.screenTool.Icon = fullfile(pathToMLAPP, 'icons', 'full-screen-svgrepo-com.svg');
            app.screenTool.Separator = 'on';
            app.screenTool.OffCallback = createCallbackFcn(app, @screenToolOff, true);
            app.screenTool.OnCallback = createCallbackFcn(app, @screenToolOn, true);
            app.themeTool = uitoggletool(app.Toolbar);
            app.themeTool.Icon = fullfile(pathToMLAPP, 'icons', 'moon-stars-svgrepo-com-LC-Black.svg');
            app.themeTool.OffCallback = createCallbackFcn(app, @themeToolOff, true);
            app.themeTool.OnCallback = createCallbackFcn(app, @themeToolOn, true);
            app.configTool = uipushtool(app.Toolbar);
            app.configTool.ClickedCallback = createCallbackFcn(app, @configToolClicked, true);
            app.configTool.Icon = fullfile(pathToMLAPP, 'icons', 'cogwheel-svgrepo-com.svg');
            app.logoutTool = uipushtool(app.Toolbar);
            app.logoutTool.ClickedCallback = createCallbackFcn(app, @logoutToolClicked, true);
            app.logoutTool.Icon = fullfile(pathToMLAPP, 'icons', 'power-button-power-svgrepo-com.svg');
            app.logoutTool.Separator = 'on';
            app.closeTool = uipushtool(app.Toolbar);
            app.closeTool.ClickedCallback = createCallbackFcn(app, @closeToolClicked, true);
            app.closeTool.Icon = fullfile(pathToMLAPP, 'icons', 'cross-close-svgrepo-com.svg');
            app.createUserPanel = uipanel(app.UIFigure);
            app.createUserPanel.Title = 'Panel';
            app.PasswordField = wt.PasswordField(app.createUserPanel);
            app.PasswordField.ValueChangingFcn = createCallbackFcn(app, @PasswordFieldValueChanging, true);
            app.confirmPasswordField = wt.PasswordField(app.createUserPanel);
            app.confirmPasswordField.ValueChangingFcn = createCallbackFcn(app, @confirmPasswordFieldValueChanging, true);
            app.passwordLabel = uilabel(app.createUserPanel);
            app.confirmPasswordLabel = uilabel(app.createUserPanel);
            app.recordAdminButton = uibutton(app.createUserPanel, 'push');
            app.recordAdminButton.ButtonPushedFcn = createCallbackFcn(app, @recordAdminButtonPushed, true);
            app.recordAdminButton.Icon = fullfile(pathToMLAPP, 'icons', 'add-user-add-svgrepo-com.svg');
            app.adminNameLabel = uilabel(app.createUserPanel);
            app.adminNameLabel.Text = 'Edit Field';
            app.adminNameEditField = uieditfield(app.createUserPanel, 'text');
            app.adminNameEditField.ValueChangedFcn = createCallbackFcn(app, @adminNameEditFieldValueChanged, true);
            app.adminFLNameLabel = uilabel(app.createUserPanel);
            app.adminFLNameLabel.Text = 'Edit Field';
            app.adminFLNameEditField = uieditfield(app.createUserPanel, 'text');
            app.adminFLNameEditField.ValueChangedFcn = createCallbackFcn(app, @adminFLNameEditFieldValueChanged, true);
            app.adminSLNameLabel = uilabel(app.createUserPanel);
            app.adminSLNameLabel.Text = 'Edit Field';
            app.adminSLNameEditField = uieditfield(app.createUserPanel, 'text');
            app.adminSLNameEditField.ValueChangedFcn = createCallbackFcn(app, @adminSLNameEditFieldValueChanged, true);
            app.adminRoleLabel = uilabel(app.createUserPanel);
            app.adminRoleLabel.Text = 'Drop Down';
            app.adminRoleDropDown = uidropdown(app.createUserPanel);
            app.adminRoleDropDown.ValueChangedFcn = createCallbackFcn(app, @adminRoleDropDownValueChanged, true);
            app.defaultDatabaseLabel = uilabel(app.createUserPanel);
            app.defaultDatabaseLabel.Text = 'Drop Down';
            app.defaultDatabaseDropDown = uidropdown(app.createUserPanel);
            app.defaultDatabaseDropDown.ValueChangedFcn = createCallbackFcn(app, @defaultDatabaseDropDownValueChanged, true);
            app.refreshDatabasesButton = uibutton(app.createUserPanel, 'push');
            app.refreshDatabasesButton.ButtonPushedFcn = createCallbackFcn(app, @refreshDatabasesButtonPushed, true);
            app.refreshDatabasesButton.Icon = fullfile(pathToMLAPP, 'icons', 'refresh-svgrepo-com.svg');
            app.refreshDatabasesButton.Text = '';
            app.refreshDatabasesLabel = uilabel(app.createUserPanel);
            app.refreshDatabasesLabel.Text = 'Label2';
            app.adminRolesPanel = uipanel(app.UIFigure);
            app.adminRolesPanel.Title = 'Panel2';
            app.adminRolesUITable = uitable(app.adminRolesPanel);
            app.adminRolesUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'};
            app.adminRolesUITable.RowName = {};
            app.adminRolesUITable.SelectionType = 'row';
            app.adminRolesUITable.CellSelectionCallback = createCallbackFcn(app, @adminRolesUITableCellSelection, true);
            app.adminRolesUITable.Multiselect = 'off';
            app.refreshAdminButton = uibutton(app.adminRolesPanel, 'push');
            app.refreshAdminButton.ButtonPushedFcn = createCallbackFcn(app, @refreshAdminButtonPushed, true);
            app.refreshAdminButton.Icon = fullfile(pathToMLAPP, 'icons', 'refresh-svgrepo-com.svg');
            app.refreshAdminButton.Text = '';
            app.adminTableLabel = uilabel(app.adminRolesPanel);
            app.adminTableLabel.Text = 'Label2';
            app.loginActionsPanel = uipanel(app.adminRolesPanel);
            app.loginActionsPanel.Title = 'Panel3';
            app.disableLoginButton = uibutton(app.loginActionsPanel, 'push');
            app.disableLoginButton.ButtonPushedFcn = createCallbackFcn(app, @disableLoginButtonPushed, true);
            app.disableLoginButton.Icon = fullfile(pathToMLAPP, 'icons', 'disable-svgrepo-com.svg');
            app.disableLoginButton.Text = 'Button3';
            app.enableLoginButton = uibutton(app.loginActionsPanel, 'push');
            app.enableLoginButton.ButtonPushedFcn = createCallbackFcn(app, @enableLoginButtonPushed, true);
            app.enableLoginButton.Icon = fullfile(pathToMLAPP, 'icons', 'enable-svgrepo-com.svg');
            app.enableLoginButton.Text = 'Button3';
            app.deleteLoginButton = uibutton(app.loginActionsPanel, 'push');
            app.deleteLoginButton.ButtonPushedFcn = createCallbackFcn(app, @deleteLoginButtonPushed, true);
            app.deleteLoginButton.Icon = fullfile(pathToMLAPP, 'icons', 'delete-user-svgrepo-com.svg');
            app.deleteLoginButton.Text = 'Button3';
            app.changePasswordCheckBox = uicheckbox(app.loginActionsPanel);
            app.changePasswordCheckBox.ValueChangedFcn = createCallbackFcn(app, @changePasswordCheckBoxValueChanged, true);
            app.changePasswordPanel = uipanel(app.loginActionsPanel);
            app.cPasswordField = wt.PasswordField(app.changePasswordPanel);
            app.cPasswordField.ValueChangingFcn = createCallbackFcn(app, @cPasswordFieldValueChanging, true);
            app.ccPasswordField = wt.PasswordField(app.changePasswordPanel);
            app.ccPasswordField.ValueChangingFcn = createCallbackFcn(app, @ccPasswordFieldValueChanging, true);
            app.newPasswordLabel = uilabel(app.changePasswordPanel);
            app.newPasswordLabel.Text = 'Label3';
            app.confirmNewPasswordLabel = uilabel(app.changePasswordPanel);
            app.confirmNewPasswordLabel.Text = 'Label3';
            app.changePasswordButton = uibutton(app.changePasswordPanel, 'push');
            app.changePasswordButton.ButtonPushedFcn = createCallbackFcn(app, @changePasswordButtonPushed, true);
            app.changePasswordButton.Text = 'Button4';
            app.selectedAdminLabel = uilabel(app.loginActionsPanel);
            app.selectedAdminLabel.Text = 'Edit Field2';
            app.selectedAdminEditField = uieditfield(app.loginActionsPanel, 'text');
            app.selectedAdminEditField.Editable = 'off';
            app.institutionNamePanel = uipanel(app.UIFigure);
            app.institutionNamePanel.Title = 'Panel4';
            app.institutionNameLabel = uilabel(app.institutionNamePanel);
            app.institutionNameLabel.Text = 'Edit Field3';
            app.institutionNameEditField = uieditfield(app.institutionNamePanel, 'text');
            app.institutionNameEditField.CharacterLimits = [0 5];
            app.institutionNameEditField.ValueChangedFcn = createCallbackFcn(app, @institutionNameEditFieldValueChanged, true);
            app.institutionWarnLabel = uilabel(app.institutionNamePanel);
            app.institutionWarnLabel.Text = 'Label4';
            app.Image = uiimage(app.institutionNamePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'warning-svgrepo-com.svg');
            app.idExampleLabel = uilabel(app.institutionNamePanel);
            app.idExampleLabel.Text = 'Label5';
            app.idExampleDLabel = uilabel(app.institutionNamePanel);
            app.idExampleDLabel.Text = '';
            app.createExampleIDButton = uibutton(app.institutionNamePanel, 'push');
            app.createExampleIDButton.ButtonPushedFcn = createCallbackFcn(app, @createExampleIDButtonPushed, true);
            app.createExampleIDButton.Icon = fullfile(pathToMLAPP, 'icons', 'refresh-svgrepo-com.svg');
            app.createExampleIDButton.Text = '';
            app.changeInstitutionNameButton = uibutton(app.institutionNamePanel, 'push');
            app.changeInstitutionNameButton.ButtonPushedFcn = createCallbackFcn(app, @changeInstitutionNameButtonPushed, true);
            app.changeInstitutionNameButton.Text = 'Button5';
            app.changeInstitutionCheckBox = uicheckbox(app.UIFigure);
            app.changeInstitutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @changeInstitutionCheckBoxValueChanged, true);
            app.changeInstitutionCheckBox.Text = 'Check Box2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = usersManagement(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end