classdef viewRecord < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        screenTool                      matlab.ui.container.toolbar.ToggleTool
        themeTool                       matlab.ui.container.toolbar.ToggleTool
        configTool                      matlab.ui.container.toolbar.PushTool
        patientRecordLabel              matlab.ui.control.Label
        selectFilesPanel                matlab.ui.container.Panel
        imageViewerButton               matlab.ui.control.Button
        bodyPartExaminedEditField       matlab.ui.control.EditField
        bodyPartExaminedEditFieldLabel  matlab.ui.control.Label
        studyDescriptionEditField       matlab.ui.control.EditField
        studyDescriptionEditFieldLabel  matlab.ui.control.Label
        studyDateEditField              matlab.ui.control.EditField
        studyDateEditFieldLabel         matlab.ui.control.Label
        modalityEditField               matlab.ui.control.EditField
        modalityEditFieldLabel          matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        filesToIncludeLabel             matlab.ui.control.Label
        fileExplorerLabel               matlab.ui.control.Label
        fileSelectorEditField           matlab.ui.control.EditField
        Image3                          matlab.ui.control.Image
        clinicHistoryPanel              matlab.ui.container.Panel
        Image4_4                        matlab.ui.control.Image
        Image4_3                        matlab.ui.control.Image
        Image4_2                        matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        physicalExplorationTextArea     matlab.ui.control.TextArea
        physicalExplorationTextAreaLabel  matlab.ui.control.Label
        phatologicalPersonalHistoryTextArea  matlab.ui.control.TextArea
        phatologicalPersonalHistoryTextAreaLabel  matlab.ui.control.Label
        nonPhatologicalPersonalHistoryTextArea  matlab.ui.control.TextArea
        nonPhatologicalPersonalHistoryTextAreaLabel  matlab.ui.control.Label
        inheritedPhatologicalHistoryTextArea  matlab.ui.control.TextArea
        inheritedPhatologicalHistoryTextAreaLabel  matlab.ui.control.Label
        contactInformationPanel         matlab.ui.container.Panel
        addressTextArea                 matlab.ui.control.TextArea
        addressTextAreaLabel            matlab.ui.control.Label
        Image5_3                        matlab.ui.control.Image
        Image5_2                        matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        postalCodeEditField             matlab.ui.control.NumericEditField
        postalCodeEditFieldLabel        matlab.ui.control.Label
        emailEditField                  matlab.ui.control.EditField
        emailEditFieldLabel             matlab.ui.control.Label
        phoneNumberEditField            matlab.ui.control.EditField
        phoneNumberEditFieldLabel       matlab.ui.control.Label
        clockImage                      matlab.ui.control.Image
        customClock                     customClock
        idivPanel                       matlab.ui.container.Panel
        ivEditField                     matlab.ui.control.EditField
        ivEditFieldLabel                matlab.ui.control.Label
        idEditField                     matlab.ui.control.EditField
        idEditFieldLabel                matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        generalInformationPanel         matlab.ui.container.Panel
        indigenousLanguageEditField     matlab.ui.control.EditField
        indigenousLanguageEditFieldLabel  matlab.ui.control.Label
        bloodTypeEditField              matlab.ui.control.EditField
        bloodTypeEditFieldLabel         matlab.ui.control.Label
        birthDateEditField              matlab.ui.control.EditField
        birthDateEditFieldLabel         matlab.ui.control.Label
        biologicalSexEditField          matlab.ui.control.EditField
        biologicalSexEditFieldLabel     matlab.ui.control.Label
        placeOfResidenceEditField       matlab.ui.control.EditField
        placeOfResidenceEditFieldLabel  matlab.ui.control.Label
        placeOfBirthEditField           matlab.ui.control.EditField
        placeOfBirthEditFieldLabel      matlab.ui.control.Label
        nationalityEditField            matlab.ui.control.EditField
        nationalityEditFieldLabel       matlab.ui.control.Label
        Image_8                         matlab.ui.control.Image
        Image_7                         matlab.ui.control.Image
        Image_6                         matlab.ui.control.Image
        curpCheckBox                    matlab.ui.control.CheckBox
        curpEditField                   matlab.ui.control.EditField
        curpEditFieldLabel              matlab.ui.control.Label
        sLastNameCheckBox               matlab.ui.control.CheckBox
        ageEditField                    matlab.ui.control.NumericEditField
        ageEditFieldLabel               matlab.ui.control.Label
        Image_5                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        indigenousLanguageSpeakerCheckBox  matlab.ui.control.CheckBox
        sLastNameEditField              matlab.ui.control.EditField
        sLastNameEditFieldLabel         matlab.ui.control.Label
        fLastNameEditField              matlab.ui.control.EditField
        fLastNameEditFieldLabel         matlab.ui.control.Label
        nameEditField                   matlab.ui.control.EditField
        nameEditFieldLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        %% Theme ,window and language control
        currentTheme           % 0 for light theme and 1 for dark theme
        currentWindowState     % 0 for maximized screen and 1 for fullscren
        currentLanguage
        

        %% Setting colors for dark theme and light theme
        figureColor

        darkThemeColorUIF =  [0,0,0]; 
        darkThemeColorToolBar = [15,15,15]/100;
        darkThemeColorBGLabels = "none";
        darkThemeColorBGContainers= [12,12,12]/100;
        darkThemeColorBGPanels = [7,7,7]/100;
        darkThemeFontColor = [94,94,94]/100;

        lightThemeColorUIF = [94, 94, 94]/100;
        lightThemeColorToolBar = '#3db52d';
        lightThemeColorBGLabels = "none";
        lightThemeColorBGContainers= [1,1,1];
        lightThemeColorBGPanels = [90,90,90]/100;
        lightThemeFontColor = [0,0,0];  


        %% Origin paths for files
        originPath 

        %% Control of language labels
        viewRecordLanguage

        %% Icon paths
        infoIconPath
        successIconPath
        warningIconPath
        errorIconPath

        %% Database connection
        conn
        dbSource
        
        %% Error flags
        errorFlags

        %% Components
        buttons 
        checkBoxes 
        dropDowns 
        efields 
        labels 
        listBoxes 
        nefields 
        panels
        textAreas

        %% Patient information structure
        patientRecord

        %% Configurations app
        configurationsApp
        
    end

    properties (SetAccess = immutable)
        checkIC = "SELECT init_counter FROM identification_data WHERE init_counter = %s"
        getPatientIndex = "SELECT patient_index FROM admins WHERE username = '%s'"
        getAdminID = "SELECT admin_id FROM admins WHERE username = '%s'"
        increaseIndex = "UPDATE admins " + ...
            "SET patient_index = patient_index + 1 " + ...
            "WHERE username = '%s' " 

        %% SQL Server querys
        getIdentificationData = "SELECT * FROM identification_data WHERE " + ...
            "patient_id = '%s'"
        getClinicHistory ="SELECT * FROM clinic_history WHERE patient_id = '%s'"

        %% Configurations app control
        canModify = 0
    end
    
    methods (Access = private)

         function setDarkTheme(app)
            
            %% Updating current theme an icon
            app.themeTool.State = "on";
            app.figureColor = [0,0,0];
            app.currentTheme = 1;
            iconPath = fullfile(app.originPath,"icons\sun-svgrepo-com-LC-White.svg");
            app.themeTool.Icon = iconPath;
            
            %% Changing colors 
            app.UIFigure.Color = app.darkThemeColorUIF;
            app.Toolbar.BackgroundColor = app.darkThemeColorToolBar;
            app.customClock.BackgroundColor = app.darkThemeColorUIF;
            app.customClock.fontColor = app.darkThemeFontColor;
            % app.birthDatePicker.BackgroundColor = app.darkThemeColorBGContainers;
            % app.birthDatePicker.FontColor = app.darkThemeFontColor;
            app.ageEditField.BackgroundColor = app.darkThemeColorBGContainers;
            app.ageEditField.FontColor = app.darkThemeFontColor;
            
            for i = 1 : length(app.buttons)
                app.buttons(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.buttons(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.checkBoxes)
                app.checkBoxes(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.dropDowns)
                app.dropDowns(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.dropDowns(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.efields)
                app.efields(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.efields(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.labels)
                app.labels(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.listBoxes)
                app.listBoxes(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.listBoxes(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.nefields)
                app.nefields(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.nefields(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.panels)
                app.panels(i).BackgroundColor = app.darkThemeColorBGPanels;
                app.panels(i).ForegroundColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.textAreas)
                app.textAreas(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.textAreas(i).FontColor = app.darkThemeFontColor;
            end
        end
        
        function setLightTheme(app)
            %% Updating state, current theme an icon
            app.themeTool.State = "off";
            app.figureColor = [1,1,1];
            app.currentTheme = 0;
            iconPath = fullfile(app.originPath,"icons\moon-stars-svgrepo-com-LC-Black.svg");
            app.themeTool.Icon = iconPath;

            %% Changing colors
            app.UIFigure.Color = app.lightThemeColorUIF;
            app.Toolbar.BackgroundColor = app.lightThemeColorToolBar;
            app.customClock.BackgroundColor = app.lightThemeColorUIF;
            app.customClock.fontColor = app.lightThemeFontColor;
            % app.birthDatePicker.BackgroundColor = app.lightThemeColorBGContainers;
            % app.birthDatePicker.FontColor = app.lightThemeFontColor;
            app.ageEditField.BackgroundColor = app.lightThemeColorBGContainers;
            app.ageEditField.FontColor = app.lightThemeFontColor;
            
            for i = 1 : length(app.buttons)
                app.buttons(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.buttons(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.checkBoxes)
                app.checkBoxes(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.dropDowns)
                app.dropDowns(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.dropDowns(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.efields)
                app.efields(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.efields(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.labels)
                app.labels(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.listBoxes)
                app.listBoxes(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.listBoxes(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.nefields)
                app.nefields(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.nefields(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.panels)
                app.panels(i).BackgroundColor = app.lightThemeColorBGPanels;
                app.panels(i).ForegroundColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.textAreas)
                app.textAreas(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.textAreas(i).FontColor = app.lightThemeFontColor;
            end
        end


        function setLanguage(app,languageValue)
            
            %% Loading language table
            app.currentLanguage = languageValue;
            component = app.viewRecordLanguage.componentName;
            property = app.viewRecordLanguage.propertyName;
            label = app.viewRecordLanguage.(languageValue);
            
            %% Changing language
            app.errorFlags = [];
            for i = 1 : length(component)
                if(property{i} == "none")
                    app.(component{i}) = label{i};
                elseif (property{i} == "errorFlag")
                    app.errorFlags = [app.errorFlags,label(i)];
                else
                    app.(component{i}).(property{i}) = label{i};
                end
            end

        end

        function saveSettings(app)
            %% Save settings 
            windowState = app.currentWindowState;
            theme = app.currentTheme;
            language = app.currentLanguage;
            lastSessionPath = fullfile(app.originPath,"settings","lastSessionSettings.mat");
            save(lastSessionPath,'windowState','theme','language')
        end
        
        function record = loadRecord(app,ID)
            %% Get identification data
            query = sprintf(app.getIdentificationData,ID);
            identificationData = fetch(app.conn,query);
            identificationData = table2cell(identificationData);

            %% Decrypt data
            data2decrypt = identificationData(4:end);
            IC = identificationData{2};

            decipherCellArray(data2decrypt,key,IC)

            %% Get clinic history
            query = sprintf(app.getClinicHistory,ID);
            clinicHistory = fetch(app.conn,query);

            %% Decrypt data 
            data2decrypt = clinicHistory(4:end);
            IC = clinicHistory{2};

            decipherCellArray(data2decrypt,key,IC)

        end
        
        function disableFields(app)

            app.postalCodeEditField.Editable = "off";

            for i = 1 : length(app.efields)
                app.efields(i).Editable = "off";
            end

            for i = 1 : length(app.dropDowns)
                app.dropDowns(i).Editable = "off";
            end

            for i = 1 : length(app.textAreas)
                app.textAreas(i).Editable = "off";
            end

            for i = 1 : length(app.checkBoxes)
                app.checkBoxes(i).Enable = "off";
            end

        end

    end

    methods (Access = public)
        %% Updating language and database source from configurations app
        function updateConfigurations(app,dbSource,language)
            app.setLanguage(language)

            if ~isempty(dbSource)
                app.dbSource = dbSource;
                dbSourcePath = fullfile(app.originPath,"settings","dbSource.mat");
                save(dbSourcePath,'dbSource');
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, windowState, theme, language, connection, ID)
            %% Initial settings
            if isdeployed
                app.originPath = fullfile(ctfroot,"login");
            else
                app.originPath = pwd;
            end

             %% Get icon paths
            app.infoIconPath = fullfile(app.originPath,"icons\sign-info-svgrepo-com.svg");
            app.successIconPath = fullfile(app.originPath,"icons\success-icon.svg");
            app.warningIconPath = fullfile(app.originPath,"icons\warning-svgrepo-com.svg");
            app.errorIconPath = fullfile(app.originPath,"icons\error-svgrepo-com.svg");

            %% get connection object
            app.conn = connection;

            %% Load language file
            languagePath = fullfile(app.originPath,"language\viewRecordLanguage.xlsx");
            app.viewRecordLanguage = readtable(languagePath); 

             %% Setting up language
            app.setLanguage(language)
            
            %% Adjusting window state from last app 

            if(windowState)
                app.screenToolOn;
            else
                app.screenToolOff;
            end

            %% Obtaining ui componentes
            app.buttons = findobj(app.UIFigure,'Type','uibutton');
            app.checkBoxes = findobj(app.UIFigure,'Type','uicheckbox');
            app.dropDowns = findobj(app.UIFigure,'Type','uidropdown');
            app.efields = findobj(app.UIFigure,'Type','uieditfield');
            app.labels = findobj(app.UIFigure,'Type','uilabel');
            app.listBoxes = findobj(app.UIFigure,'Type','uilistbox');
            app.nefields = findobj(app.UIFigure,'Tag','nefield');
            app.panels = findobj(app.UIFigure,'Type','uipanel');
            app.textAreas = findobj(app.UIFigure,'Type','uitextarea');

            %% Disable components
            app.disableFields()

            %% Updating theme from last app
            if(theme)
                app.setDarkTheme
            else
                app.setLightTheme
            end

            %app.loadRecord(ID);

        end

        % On callback: screenTool
        function screenToolOn(app, event)
            %% Fullscreen
            app.currentWindowState = 1;
            app.screenTool.State = "on";
            app.UIFigure.WindowState = "fullscreen";
        end

        % Off callback: screenTool
        function screenToolOff(app, event)
            %% Maximized screen
            app.currentWindowState = 0;
            app.screenTool.State = "off";
            app.UIFigure.WindowState = "maximized";
        end

        % On callback: themeTool
        function themeToolOn(app, event)
            app.setDarkTheme()
        end

        % Off callback: themeTool
        function themeToolOff(app, event)
            app.setLightTheme()
        end

        % Callback function: configTool
        function configToolClicked(app, event)
            %% Open configurations app
            app.configurationsApp = configurations(app.currentTheme...
                ,app.currentLanguage,viewRecord,app.canModify);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icons', 'sign-info-svgrepo-com.svg');
            app.screenTool = uitoggletool(app.Toolbar);
            app.screenTool.Icon = fullfile(pathToMLAPP, 'icons', 'full-screen-svgrepo-com.svg');
            app.screenTool.OffCallback = createCallbackFcn(app, @screenToolOff, true);
            app.screenTool.OnCallback = createCallbackFcn(app, @screenToolOn, true);
            app.themeTool = uitoggletool(app.Toolbar);
            app.themeTool.Icon = fullfile(pathToMLAPP, 'icons', 'moon-stars-svgrepo-com-LC-Black.svg');
            app.themeTool.OffCallback = createCallbackFcn(app, @themeToolOff, true);
            app.themeTool.OnCallback = createCallbackFcn(app, @themeToolOn, true);
            app.configTool = uipushtool(app.Toolbar);
            app.configTool.ClickedCallback = createCallbackFcn(app, @configToolClicked, true);
            app.configTool.Icon = fullfile(pathToMLAPP, 'icons', 'cogwheel-svgrepo-com.svg');
            app.generalInformationPanel = uipanel(app.UIFigure);
            app.generalInformationPanel.Title = 'Panel';
            app.nameEditFieldLabel = uilabel(app.generalInformationPanel);
            app.nameEditFieldLabel.Text = 'Edit Field2';
            app.nameEditField = uieditfield(app.generalInformationPanel, 'text');
            app.nameEditField.CharacterLimits = [0 50];
            app.nameEditField.InputType = 'alphanumerics';
            app.nameEditField.Tooltip = {'El valor solo puede contener letras'};
            app.fLastNameEditFieldLabel = uilabel(app.generalInformationPanel);
            app.fLastNameEditFieldLabel.Text = 'Edit Field2';
            app.fLastNameEditField = uieditfield(app.generalInformationPanel, 'text');
            app.fLastNameEditField.CharacterLimits = [0 50];
            app.fLastNameEditField.InputType = 'alphanumerics';
            app.sLastNameEditFieldLabel = uilabel(app.generalInformationPanel);
            app.sLastNameEditFieldLabel.Text = 'Edit Field2';
            app.sLastNameEditField = uieditfield(app.generalInformationPanel, 'text');
            app.sLastNameEditField.CharacterLimits = [0 50];
            app.sLastNameEditField.InputType = 'alphanumerics';
            app.indigenousLanguageSpeakerCheckBox = uicheckbox(app.generalInformationPanel);
            app.Image = uiimage(app.generalInformationPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'blood-drop-svgrepo-com.svg');
            app.Image_2 = uiimage(app.generalInformationPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'calendar-svgrepo-com.svg');
            app.Image_3 = uiimage(app.generalInformationPanel);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'icons', 'globe-showing-americas-svgrepo-com.svg');
            app.Image_4 = uiimage(app.generalInformationPanel);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'icons', 'genders-gender-svgrepo-com.svg');
            app.Image_5 = uiimage(app.generalInformationPanel);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'icons', 'people-speak-svgrepo-com.svg');
            app.ageEditFieldLabel = uilabel(app.generalInformationPanel);
            app.ageEditFieldLabel.Text = 'Edit Field';
            app.ageEditField = uieditfield(app.generalInformationPanel, 'numeric');
            app.ageEditField.Limits = [0 Inf];
            app.ageEditField.Editable = 'off';
            app.sLastNameCheckBox = uicheckbox(app.generalInformationPanel);
            app.sLastNameCheckBox.Text = 'Check Box2';
            app.curpEditFieldLabel = uilabel(app.generalInformationPanel);
            app.curpEditFieldLabel.Text = 'Edit Field2';
            app.curpEditField = uieditfield(app.generalInformationPanel, 'text');
            app.curpEditField.CharacterLimits = [0 18];
            app.curpEditField.InputType = 'alphanumerics';
            app.curpCheckBox = uicheckbox(app.generalInformationPanel);
            app.Image_6 = uiimage(app.generalInformationPanel);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'icons', 'user-7-svgrepo-com.svg');
            app.Image_7 = uiimage(app.generalInformationPanel);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'icons', 'baby-symbol-svgrepo-com.svg');
            app.Image_8 = uiimage(app.generalInformationPanel);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'icons', 'maps-and-flags-pin-svgrepo-com.svg');
            app.nationalityEditFieldLabel = uilabel(app.generalInformationPanel);
            app.nationalityEditFieldLabel.Text = 'Edit Field6';
            app.nationalityEditField = uieditfield(app.generalInformationPanel, 'text');
            app.placeOfBirthEditFieldLabel = uilabel(app.generalInformationPanel);
            app.placeOfBirthEditFieldLabel.Text = 'Edit Field7';
            app.placeOfBirthEditField = uieditfield(app.generalInformationPanel, 'text');
            app.placeOfResidenceEditFieldLabel = uilabel(app.generalInformationPanel);
            app.placeOfResidenceEditFieldLabel.Text = 'Edit Field8';
            app.placeOfResidenceEditField = uieditfield(app.generalInformationPanel, 'text');
            app.biologicalSexEditFieldLabel = uilabel(app.generalInformationPanel);
            app.biologicalSexEditFieldLabel.Text = 'Edit Field9';
            app.biologicalSexEditField = uieditfield(app.generalInformationPanel, 'text');
            app.birthDateEditFieldLabel = uilabel(app.generalInformationPanel);
            app.birthDateEditFieldLabel.Text = 'Edit Field10';
            app.birthDateEditField = uieditfield(app.generalInformationPanel, 'text');
            app.bloodTypeEditFieldLabel = uilabel(app.generalInformationPanel);
            app.bloodTypeEditFieldLabel.Text = 'Edit Field11';
            app.bloodTypeEditField = uieditfield(app.generalInformationPanel, 'text');
            app.indigenousLanguageEditFieldLabel = uilabel(app.generalInformationPanel);
            app.indigenousLanguageEditFieldLabel.Text = 'Edit Field12';
            app.indigenousLanguageEditField = uieditfield(app.generalInformationPanel, 'text');
            app.idivPanel = uipanel(app.UIFigure);
            app.idivPanel.Title = 'Panel2';
            app.Image2 = uiimage(app.idivPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'icons', 'id-card-svgrepo-com.svg');
            app.idEditFieldLabel = uilabel(app.idivPanel);
            app.idEditFieldLabel.Text = 'Edit Field';
            app.idEditField = uieditfield(app.idivPanel, 'text');
            app.idEditField.Editable = 'off';
            app.ivEditFieldLabel = uilabel(app.idivPanel);
            app.ivEditFieldLabel.Text = 'Edit Field';
            app.ivEditField = uieditfield(app.idivPanel, 'text');
            app.ivEditField.Editable = 'off';
            app.customClock = customClock(app.UIFigure);
            app.clockImage = uiimage(app.UIFigure);
            app.clockImage.Tag = 'image';
            app.clockImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'calendar-clock-svgrepo-com.svg');
            app.contactInformationPanel = uipanel(app.UIFigure);
            app.contactInformationPanel.Title = 'Panel5';
            app.phoneNumberEditFieldLabel = uilabel(app.contactInformationPanel);
            app.phoneNumberEditFieldLabel.Text = 'Edit Field3';
            app.phoneNumberEditField = uieditfield(app.contactInformationPanel, 'text');
            app.phoneNumberEditField.InputType = 'digits';
            app.emailEditFieldLabel = uilabel(app.contactInformationPanel);
            app.emailEditFieldLabel.Text = 'Edit Field3';
            app.emailEditField = uieditfield(app.contactInformationPanel, 'text');
            app.postalCodeEditFieldLabel = uilabel(app.contactInformationPanel);
            app.postalCodeEditFieldLabel.Text = 'Edit Field4';
            app.postalCodeEditField = uieditfield(app.contactInformationPanel, 'numeric');
            app.postalCodeEditField.Limits = [0 Inf];
            app.postalCodeEditField.ValueDisplayFormat = '%.0f';
            app.postalCodeEditField.Tag = 'nefield';
            app.Image5 = uiimage(app.contactInformationPanel);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'icons', 'email-mail-svgrepo-com.svg');
            app.Image5_2 = uiimage(app.contactInformationPanel);
            app.Image5_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'phone-number-svgrepo-com.svg');
            app.Image5_3 = uiimage(app.contactInformationPanel);
            app.Image5_3.ImageSource = fullfile(pathToMLAPP, 'icons', 'map-map-marker-svgrepo-com.svg');
            app.addressTextAreaLabel = uilabel(app.contactInformationPanel);
            app.addressTextAreaLabel.Text = 'Text Area2';
            app.addressTextArea = uitextarea(app.contactInformationPanel);
            app.clinicHistoryPanel = uipanel(app.UIFigure);
            app.clinicHistoryPanel.Title = 'Panel4';
            app.inheritedPhatologicalHistoryTextAreaLabel = uilabel(app.clinicHistoryPanel);
            app.inheritedPhatologicalHistoryTextAreaLabel.Text = 'Text Area';
            app.inheritedPhatologicalHistoryTextArea = uitextarea(app.clinicHistoryPanel);
            app.nonPhatologicalPersonalHistoryTextAreaLabel = uilabel(app.clinicHistoryPanel);
            app.nonPhatologicalPersonalHistoryTextAreaLabel.Text = 'Text Area';
            app.nonPhatologicalPersonalHistoryTextArea = uitextarea(app.clinicHistoryPanel);
            app.phatologicalPersonalHistoryTextAreaLabel = uilabel(app.clinicHistoryPanel);
            app.phatologicalPersonalHistoryTextAreaLabel.Text = 'Text Area';
            app.phatologicalPersonalHistoryTextArea = uitextarea(app.clinicHistoryPanel);
            app.physicalExplorationTextAreaLabel = uilabel(app.clinicHistoryPanel);
            app.physicalExplorationTextAreaLabel.Text = 'Text Area';
            app.physicalExplorationTextArea = uitextarea(app.clinicHistoryPanel);
            app.Image4 = uiimage(app.clinicHistoryPanel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'icons', 'stethoscope-svgrepo-com.svg');
            app.Image4_2 = uiimage(app.clinicHistoryPanel);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'family-institution-svgrepo-com.svg');
            app.Image4_3 = uiimage(app.clinicHistoryPanel);
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'icons', 'man-with-sore-throat-svgrepo-com.svg');
            app.Image4_4 = uiimage(app.clinicHistoryPanel);
            app.Image4_4.ImageSource = fullfile(pathToMLAPP, 'icons', 'hospital-svgrepo-com.svg');
            app.selectFilesPanel = uipanel(app.UIFigure);
            app.selectFilesPanel.Title = 'Panel6';
            app.Image3 = uiimage(app.selectFilesPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'attachImage.png');
            app.fileSelectorEditField = uieditfield(app.selectFilesPanel, 'text');
            app.fileSelectorEditField.Editable = 'off';
            app.fileExplorerLabel = uilabel(app.selectFilesPanel);
            app.filesToIncludeLabel = uilabel(app.selectFilesPanel);
            app.filesToIncludeLabel.Text = 'Label2';
            app.ListBox = uilistbox(app.selectFilesPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.modalityEditFieldLabel = uilabel(app.selectFilesPanel);
            app.modalityEditFieldLabel.Enable = 'off';
            app.modalityEditFieldLabel.Text = 'Edit Field5';
            app.modalityEditField = uieditfield(app.selectFilesPanel, 'text');
            app.modalityEditField.Editable = 'off';
            app.modalityEditField.Enable = 'off';
            app.studyDateEditFieldLabel = uilabel(app.selectFilesPanel);
            app.studyDateEditFieldLabel.Enable = 'off';
            app.studyDateEditFieldLabel.Text = 'Edit Field5';
            app.studyDateEditField = uieditfield(app.selectFilesPanel, 'text');
            app.studyDateEditField.Editable = 'off';
            app.studyDateEditField.Enable = 'off';
            app.studyDescriptionEditFieldLabel = uilabel(app.selectFilesPanel);
            app.studyDescriptionEditFieldLabel.Enable = 'off';
            app.studyDescriptionEditFieldLabel.Text = 'Edit Field5';
            app.studyDescriptionEditField = uieditfield(app.selectFilesPanel, 'text');
            app.studyDescriptionEditField.Editable = 'off';
            app.studyDescriptionEditField.Enable = 'off';
            app.bodyPartExaminedEditFieldLabel = uilabel(app.selectFilesPanel);
            app.bodyPartExaminedEditFieldLabel.Enable = 'off';
            app.bodyPartExaminedEditFieldLabel.Text = 'Edit Field5';
            app.bodyPartExaminedEditField = uieditfield(app.selectFilesPanel, 'text');
            app.bodyPartExaminedEditField.Editable = 'off';
            app.bodyPartExaminedEditField.Enable = 'off';
            app.imageViewerButton = uibutton(app.selectFilesPanel, 'push');
            app.patientRecordLabel = uilabel(app.UIFigure);
            app.patientRecordLabel.Text = 'Label3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewRecord(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end