classdef viewRecordV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        infoTool                        matlab.ui.container.toolbar.PushTool
        screenTool                      matlab.ui.container.toolbar.ToggleTool
        themeTool                       matlab.ui.container.toolbar.ToggleTool
        configTool                      matlab.ui.container.toolbar.PushTool
        logoutTool                      matlab.ui.container.toolbar.PushTool
        closeTool                       matlab.ui.container.toolbar.PushTool
        mainMenuTool                    matlab.ui.container.toolbar.PushTool
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        recordTab                       matlab.ui.container.Tab
        patientRecordLabel              matlab.ui.control.Label
        modifyButton                    matlab.ui.control.Button
        clinicHistoryPanel              matlab.ui.container.Panel
        Image4_4                        matlab.ui.control.Image
        Image4_3                        matlab.ui.control.Image
        Image4_2                        matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        physicalExplorationTextArea     matlab.ui.control.TextArea
        physicalExplorationTextAreaLabel  matlab.ui.control.Label
        pathologicalPersonalHistoryTextArea  matlab.ui.control.TextArea
        phatologicalPersonalHistoryTextAreaLabel  matlab.ui.control.Label
        nonPathologicalPersonalHistoryTextArea  matlab.ui.control.TextArea
        nonPhatologicalPersonalHistoryTextAreaLabel  matlab.ui.control.Label
        inheritedPathologicalHistoryTextArea  matlab.ui.control.TextArea
        inheritedPhatologicalHistoryTextAreaLabel  matlab.ui.control.Label
        selectFilesPanel                matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        imageViewerButton               matlab.ui.control.Button
        filesAvailableLabel             matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        contactInformationPanel         matlab.ui.container.Panel
        addressTextArea                 matlab.ui.control.TextArea
        addressTextAreaLabel            matlab.ui.control.Label
        Image5_3                        matlab.ui.control.Image
        Image5_2                        matlab.ui.control.Image
        Image5                          matlab.ui.control.Image
        postalCodeEditField             matlab.ui.control.NumericEditField
        postalCodeEditFieldLabel        matlab.ui.control.Label
        emailEditField                  matlab.ui.control.EditField
        emailEditFieldLabel             matlab.ui.control.Label
        phoneNumberEditField            matlab.ui.control.EditField
        phoneNumberEditFieldLabel       matlab.ui.control.Label
        generalInformationPanel         matlab.ui.container.Panel
        indigenousLanguageEditField     matlab.ui.control.EditField
        indigenousLanguageEditFieldLabel  matlab.ui.control.Label
        bloodTypeEditField              matlab.ui.control.EditField
        bloodTypeEditFieldLabel         matlab.ui.control.Label
        birthDateEditField              matlab.ui.control.EditField
        birthDateEditFieldLabel         matlab.ui.control.Label
        biologicalSexEditField          matlab.ui.control.EditField
        biologicalSexEditFieldLabel     matlab.ui.control.Label
        placeOfResidenceEditField       matlab.ui.control.EditField
        placeOfResidenceEditFieldLabel  matlab.ui.control.Label
        placeOfBirthEditField           matlab.ui.control.EditField
        placeOfBirthEditFieldLabel      matlab.ui.control.Label
        nationalityEditField            matlab.ui.control.EditField
        nationalityEditFieldLabel       matlab.ui.control.Label
        Image_8                         matlab.ui.control.Image
        Image_7                         matlab.ui.control.Image
        Image_6                         matlab.ui.control.Image
        curpCheckBox                    matlab.ui.control.CheckBox
        curpEditField                   matlab.ui.control.EditField
        curpEditFieldLabel              matlab.ui.control.Label
        sLastNameCheckBox               matlab.ui.control.CheckBox
        ageEditField                    matlab.ui.control.NumericEditField
        ageEditFieldLabel               matlab.ui.control.Label
        Image_5                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        indigenousLanguageSpeakerCheckBox  matlab.ui.control.CheckBox
        sLastNameEditField              matlab.ui.control.EditField
        sLastNameEditFieldLabel         matlab.ui.control.Label
        fLastNameEditField              matlab.ui.control.EditField
        fLastNameEditFieldLabel         matlab.ui.control.Label
        nameEditField                   matlab.ui.control.EditField
        nameEditFieldLabel              matlab.ui.control.Label
        idivPanel                       matlab.ui.container.Panel
        createdAtEditField              matlab.ui.control.EditField
        createdAtEditFieldLabel         matlab.ui.control.Label
        idEditField                     matlab.ui.control.EditField
        idEditFieldLabel                matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        clockImage                      matlab.ui.control.Image
        customClock                     customClock
        imageViewerTab                  matlab.ui.container.Tab
        panImageButton                  matlab.ui.control.StateButton
        zoomRegionButton                matlab.ui.control.StateButton
        currentImageEditField           matlab.ui.control.EditField
        currentImageLabel               matlab.ui.control.Label
        homeAxisButton                  matlab.ui.control.Button
        controlsLabel                   matlab.ui.control.Label
        gammaSliderLabel                matlab.ui.control.Label
        gammaSlider                     matlab.ui.control.Slider
        contrastSlider                  matlab.ui.control.Slider
        contrastSliderLabel             matlab.ui.control.Label
        viewerUITable                   matlab.ui.control.Table
        Panel                           matlab.ui.container.Panel
        imageGridLayout                 matlab.ui.container.GridLayout
        histogramUIAxes                 matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        brightnessSlider                matlab.ui.control.Slider
        brightnessSliderLabel           matlab.ui.control.Label
        clockImage_2                    matlab.ui.control.Image
        customClock_2                   customClock
    end

    properties (Access = private)
        %% Theme ,window and language control
        currentTheme           % 0 for light theme and 1 for dark theme
        currentWindowState     % 0 for maximized screen and 1 for fullscren
        currentLanguage

        %% Setting colors for dark theme and light theme
        figureColor

        darkThemeColorUIF =  [0,0,0]; 
        darkThemeColorToolBar = [15,15,15]/100;
        darkThemeColorBGLabels = "none";
        darkThemeColorBGContainers= [12,12,12]/100;
        darkThemeColorBGPanels = [7,7,7]/100;
        darkThemeFontColor = [94,94,94]/100;

        lightThemeColorUIF = [94, 94, 94]/100;
        lightThemeColorToolBar = '#3db52d';
        lightThemeColorBGLabels = "none";
        lightThemeColorBGContainers= [1,1,1];
        lightThemeColorBGPanels = [90,90,90]/100;
        lightThemeFontColor = [0,0,0];  


        %% Origin paths for files
        originPath 

        %% Control of language labels
        viewRecordLanguage

        %% Icon paths
        infoIconPath
        successIconPath
        warningIconPath
        errorIconPath
        sampleImagePath

        %% Database connection
        conn
        dbSource
        
        %% Error flags
        errorFlags

        %% Components
        buttons 
        checkBoxes 
        dropDowns 
        efields 
        labels 
        listBoxes 
        nefields 
        panels
        textAreas
        tables 

        %% Patient information structure
        patientRecord
        fieldNames = {'patientID', 'name', 'lastName', 'secondLastName', ...
                  'biologicalSex', 'bloodType', 'birthDate', ...
                  'nationalityKey', 'placeOfBirthKey', 'placeOfResidenceKey', ...
                  'curp', 'indigenousLanguage', 'postalCode', 'address', ...
                  'phoneNumber', 'email', 'createdAt', ...
                  'inheritedPhatologicalHistory','pathologicalPersonalHistory',...
                  'nonPathologicalPersonalHistory','physicalExploration'};
        ID
        modality
        studyDate
        studyDescription
        bodyPartExamined
        studyName
        metadata
        image
        medicalRecord
        tempImagingStudies

        %% Table information display
        tableVarNames

        %% Configurations app
        configurationsApp

        %% Declaring variables to save nationalities, indigenous languages, federal entities
        % blood type and sex
        
        nationalities 
        indigenousLanguages 
        federalEntities 
        bloodTypes
        biologicalSexItems 
        
        %% UIGrid control
        imageAxis

        %% Image modification
        bright
        gamma
        contrast
        tempImage
        maxImageRange
        sharedImage
        currentBright
        lastBright

        %% Axis and pointer control
        pointerShape = "crosshair"
        pointerManager

        %% Progress bar messages
        startingAppMessage
        loadingRecordMessage
        decipheringMedicalRecordMessage

        %% Info messages
        infoToolMessage
        infoToolTitle


    end

    properties (SetAccess = immutable)
        %% SQL Server querys
        checkIC = "SELECT init_counter FROM identification_data WHERE init_counter = %s"
        getPatientIndex = "SELECT patient_index FROM admins WHERE username = '%s'"
        getAdminID = "SELECT admin_id FROM admins WHERE username = '%s'"
        increaseIndex = "UPDATE admins " + ...
            "SET patient_index = patient_index + 1 " + ...
            "WHERE username = '%s' " 

        getIdentificationData = "SELECT * FROM identification_data WHERE patient_id = '%s'"
        getClinicHistory ="SELECT * FROM clinic_history WHERE history_id = '%s'"
        getImagingStudies = "SELECT * FROM imaging_studies WHERE patient_id = '%s'"

        %% Configurations app control
        canModify = 0
    end
    
methods (Access = private)

         function setDarkTheme(app)
            
            %% Updating current theme an icon
            app.themeTool.State = "on";
            app.figureColor = [0,0,0];
            app.currentTheme = 1;
            iconPath = fullfile(app.originPath,"icons\sun-svgrepo-com-LC-White.svg");
            app.themeTool.Icon = iconPath;
            
            %% Changing colors 
            app.UIFigure.Color = app.darkThemeColorUIF;
            app.Toolbar.BackgroundColor = app.darkThemeColorToolBar;
            app.customClock.BackgroundColor = app.darkThemeColorUIF;
            app.customClock.fontColor = app.darkThemeFontColor;
            app.ageEditField.BackgroundColor = app.darkThemeColorBGContainers;
            app.ageEditField.FontColor = app.darkThemeFontColor;
            app.recordTab.BackgroundColor = app.darkThemeColorUIF;
            
            for i = 1 : length(app.buttons)
                app.buttons(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.buttons(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.checkBoxes)
                app.checkBoxes(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.dropDowns)
                app.dropDowns(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.dropDowns(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.efields)
                app.efields(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.efields(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.labels)
                app.labels(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.listBoxes)
                app.listBoxes(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.listBoxes(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.nefields)
                app.nefields(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.nefields(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.panels)
                app.panels(i).BackgroundColor = app.darkThemeColorBGPanels;
                app.panels(i).ForegroundColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.textAreas)
                app.textAreas(i).BackgroundColor = app.darkThemeColorBGContainers;
                app.textAreas(i).FontColor = app.darkThemeFontColor;
            end

            for i = 1 : length(app.tables)
                app.tables(i).ForegroundColor = app.darkThemeFontColor;
                app.tables(i).BackgroundColor = [app.darkThemeColorBGContainers;app.darkThemeColorBGContainers + 0.1];
            end
        end
        
        function setLightTheme(app)
            %% Updating state, current theme an icon
            app.themeTool.State = "off";
            app.figureColor = [1,1,1];
            app.currentTheme = 0;
            iconPath = fullfile(app.originPath,"icons\moon-stars-svgrepo-com-LC-Black.svg");
            app.themeTool.Icon = iconPath;

            %% Changing colors
            app.UIFigure.Color = app.lightThemeColorUIF;
            app.Toolbar.BackgroundColor = app.lightThemeColorToolBar;
            app.customClock.BackgroundColor = app.lightThemeColorUIF;
            app.customClock.fontColor = app.lightThemeFontColor;
            % app.birthDatePicker.BackgroundColor = app.lightThemeColorBGContainers;
            % app.birthDatePicker.FontColor = app.lightThemeFontColor;
            app.ageEditField.BackgroundColor = app.lightThemeColorBGContainers;
            app.ageEditField.FontColor = app.lightThemeFontColor;
            app.recordTab.BackgroundColor = app.lightThemeColorUIF;
            
            for i = 1 : length(app.buttons)
                app.buttons(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.buttons(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.checkBoxes)
                app.checkBoxes(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.dropDowns)
                app.dropDowns(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.dropDowns(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.efields)
                app.efields(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.efields(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.labels)
                app.labels(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.listBoxes)
                app.listBoxes(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.listBoxes(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.nefields)
                app.nefields(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.nefields(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.panels)
                app.panels(i).BackgroundColor = app.lightThemeColorBGPanels;
                app.panels(i).ForegroundColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.textAreas)
                app.textAreas(i).BackgroundColor = app.lightThemeColorBGContainers;
                app.textAreas(i).FontColor = app.lightThemeFontColor;
            end

            for i = 1 : length(app.tables)
                app.tables(i).ForegroundColor = app.lightThemeFontColor;
                app.tables(i).BackgroundColor = [app.lightThemeColorBGContainers;app.lightThemeColorBGContainers - 0.1];
            end
        end


        function setLanguage(app,languageValue)
            
            %% Loading language table
            app.currentLanguage = languageValue;
            component = app.viewRecordLanguage.componentName;
            property = app.viewRecordLanguage.propertyName;
            label = app.viewRecordLanguage.(languageValue);
            
            %% Changing language
            app.errorFlags = [];
            for i = 1 : length(component)
                if(property{i} == "none")
                    app.(component{i}) = label{i};
                elseif (property{i} == "errorFlag")
                    app.errorFlags = [app.errorFlags,label(i)];
                else
                    app.(component{i}).(property{i}) = label{i};
                end
            end

            app.tableVarNames = {app.ID, app.modality, app.studyDate...
                , app.studyDescription,app.bodyPartExamined, app.studyName...
                , app.metadata, app.image};
            app.UITable.ColumnName = app.tableVarNames;
            app.viewerUITable.ColumnName = app.tableVarNames;
        end

        function saveSettings(app)
            %% Save settings 
            windowState = app.currentWindowState;
            theme = app.currentTheme;
            language = app.currentLanguage;
            lastSessionPath = fullfile(app.originPath,"settings","lastSessionSettings.mat");
            save(lastSessionPath,'windowState','theme','language')
        end
        
        function record = loadRecord(app,ID)
            key = 1:16;
            %% Get identification data
            query = sprintf(app.getIdentificationData,ID);
            identificationData = fetch(app.conn,query);
            identificationData = table2cell(identificationData);

            %% Decrypt data
            data2decrypt = identificationData(4:end);
            IC = identificationData{2};

            data = decipherCellArray(data2decrypt,key,IC);
            identificationData = [identificationData(1),data];

            %% Get clinic history
            query = sprintf(app.getClinicHistory,ID);
            clinicHistory = fetch(app.conn,query);
            clinicHistory = table2cell(clinicHistory);

            %% Decrypt data 
            data2decrypt = clinicHistory(4:end);
            IC = clinicHistory{2};

            clinicHistory = decipherCellArray(data2decrypt,key,IC);

            patientInfo = [identificationData,clinicHistory];
            record = cell2struct(patientInfo,app.fieldNames,2);

            %% Get imaging studies
            colNames ={app.ID, app.modality, app.studyDate, app.studyDescription,...
                app.bodyPartExamined, app.studyName, app.metadata, app.image};
            query = sprintf(app.getImagingStudies,ID);
            imagingStudies = fetch(app.conn,query);
            imagingStudiesP = table2cell(imagingStudies);

            %% Decrypt image metadata and organize it in a table
            if ~isempty(imagingStudies)
                [r,~] = size(imagingStudiesP);
                imData = cell(r,8);
                imData = cell2table(imData,"VariableNames",colNames);
                for i = 1 : r
                    IC = imagingStudiesP{i,3};
                    data2decrypt = imagingStudiesP(i,5:end-1);
                    [uncipheredData,~,~] = decipherCellArray(data2decrypt,key,IC);
                    imageData = getArrayFromByteStream(uint8(imagingStudiesP{i,end}));
                    imDataToTable = array2table([ID,uncipheredData,imageData]);
                    imData(i,:) = imDataToTable;
                end
                %% Add imaging studies to patient record
                formatStudyDate = rowfun(@app.numberToDate,imData,"InputVariables",app.studyDate);
                formatStudyDate = table2cell(formatStudyDate);
                imData.(app.studyDate) = formatStudyDate;
                record.imagingStudies = imData;
            else
                record.imagingStudies = [];
            end
            

        end
        
        function disableFields(app)

            app.postalCodeEditField.Editable = "off";

            for i = 1 : length(app.efields)
                app.efields(i).Editable = "off";
            end

            for i = 1 : length(app.dropDowns)
                app.dropDowns(i).Editable = "off";
            end

            for i = 1 : length(app.textAreas)
                app.textAreas(i).Editable = "off";
            end

            for i = 1 : length(app.checkBoxes)
                app.checkBoxes(i).Enable = "off";
            end

        end

        
        function showInformation(app,record)
            %% Identification data
            app.idEditField.Value = record.patientID;
            app.nameEditField.Value = record.name;
            app.fLastNameEditField.Value = record.lastName;
            app.sLastNameEditField.Value = record.secondLastName;

            biologicalSex = app.getBiologicalSexFromKey(record.biologicalSex);
            biologicalSex = biologicalSex{:};
            app.biologicalSexEditField.Value = biologicalSex; 

            app.bloodTypeEditField.Value = record.bloodType;

            %% Format birth date and calculate age
            birthDate = record.birthDate;
            birthDate = datetime(birthDate,'InputFormat','yyyyMMdd','Format','yyyy/MM/dd');
            age = floor(years(datetime("today") - birthDate));
            birthDate = char(birthDate);
            app.birthDateEditField.Value = birthDate;
            app.ageEditField.Value = age;

            %% Get nationality and entity of residence and birth
            nationality = app.getNationalityFromKey(record.nationalityKey);
            nationality = nationality{:};
            app.nationalityEditField.Value = nationality;

            placeOfBirth = app.getFederalEntityFromKey(record.placeOfBirthKey);
            placeOfBirth = placeOfBirth{:};
            app.placeOfBirthEditField.Value = placeOfBirth;

            placeOfResidence = app.getFederalEntityFromKey(record.placeOfResidenceKey);
            placeOfResidence = placeOfResidence{:};
            app.placeOfResidenceEditField.Value = placeOfResidence;

            app.curpEditField.Value = record.curp;
            app.indigenousLanguageEditField.Value = record.indigenousLanguage;
            app.postalCodeEditField.Value = record.postalCode;
            app.addressTextArea.Value = record.address;
            app.phoneNumberEditField.Value = record.phoneNumber;
            app.emailEditField.Value = record.email;

            %% Format creation date
            creationDate = record.createdAt;
            creationDate = datetime(creationDate,'Format','yyyy/MM/dd hh:mm:ss');
            creationDate = char(creationDate);
            app.createdAtEditField.Value = creationDate;
            
            %% clinic history
            app.inheritedPathologicalHistoryTextArea.Value = record.inheritedPhatologicalHistory;
            app.pathologicalPersonalHistoryTextArea.Value = record.pathologicalPersonalHistory;
            app.nonPathologicalPersonalHistoryTextArea.Value = record.nonPathologicalPersonalHistory;
            app.physicalExplorationTextArea.Value = record.physicalExploration;
            
        end

        function key = getKeyFromNationality(app,nationality)
            %% Search nationality key using a nationality string in any language available
            nationality = convertCharsToStrings(nationality);
            key = app.nationalities.nationalityKey{app.nationalities.(app.currentLanguage) == nationality};
            key = convertStringsToChars(key);
        end
        
        function nationality = getNationalityFromKey(app,key)
            %% Search nationality using a nationality key string in any language available
            key = convertCharsToStrings(key);
            nationalityLanguage = app.nationalities.(app.currentLanguage);
            nationality = nationalityLanguage(app.nationalities.nationalityKey == key);
            nationality = convertStringsToChars(nationality);
        end
        
        function countryCode = getCountryCodeFromKey(app,key)
            %% Search country code using a nationality key string in any language available
            countryCode = app.nationalities.countryCode(app.nationalities.nationalityKey == key);
        end
        
        function federalEntity = getFederalEntityFromKey(app,key)
            %% Search federal entity using a entity key string in any language available
            key = convertCharsToStrings(key);
            stateLanguage = app.federalEntities.(app.currentLanguage);
            federalEntity = stateLanguage(app.federalEntities.stateKey == key);
            federalEntity = convertStringsToChars(federalEntity);
        end
        
        function key = getKeyFromFederalEntity(app,federalEntity)
            %% Search entity key using a federal entity string in any language available
            federalEntity = convertCharsToStrings(federalEntity);
            key = app.federalEntities.stateKey(app.federalEntities.(app.currentLanguage)== federalEntity);
            key = convertStringsToChars(key);
        end

        
        function sex = getBiologicalSexFromKey(app,key)
            %% Search sex label using a key
            key = convertCharsToStrings(key);
            biologicalSexLanguage = app.biologicalSexItems.(app.currentLanguage);
            sex = biologicalSexLanguage(app.biologicalSexItems.key == key);
            sex = convertCharsToStrings(sex);
            
        end
        
        function date = numberToDate(~,number)
            number = string(number);
            date = datetime(number,'InputFormat','yyyyMMdd','Format','yyyy/MM/dd');
        end
        
        function configurePointers(app,inPointer)
            % Define pointer behavior when pointer enter axes
            app.pointerManager.enterFcn = @(~,~) set(app.UIFigure, 'Pointer', inPointer);
            app.pointerManager.exitFcn  = @(~,~) set(app.UIFigure, 'Pointer', 'arrow');
            app.pointerManager.traverseFcn = [];
            iptSetPointerBehavior(app.UIAxes, app.pointerManager)
            
            % Enable pointer manager for app
            iptPointerManager(app.UIFigure,'enable'); 
        end
        
        function disableImageControls(app)
            %% Disable image modifiers controls
            app.brightnessSlider.Enable = "off";
            app.contrastSlider.Enable = "off";
            app.gammaSlider.Enable = "off";

        end

        function enableImageControls(app)
            %% Disable image modifiers controls
            app.brightnessSlider.Enable = "on";
            app.contrastSlider.Enable = "on";
            app.gammaSlider.Enable = "on";

        end

        function imageControlsToZero(app)
            %% Disable image modifiers controls
            app.brightnessSlider.Value = 0;
            app.lastBright = 0;
            app.contrastSlider.Value = 1;
            app.gammaSlider.Value = 1;
        end
    end

    methods (Access = public)
        %% Updating language and database source from configurations app
        function updateConfigurations(app,dbSource,language)
            app.setLanguage(language)
            app.showInformation(app.medicalRecord)

            if ~isempty(dbSource)
                app.dbSource = dbSource;
                dbSourcePath = fullfile(app.originPath,"settings","dbSource.mat");
                save(dbSourcePath,'dbSource');
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, windowState, theme, language, connection, ID)
            %% Initial settings
            if isdeployed
                app.originPath = fullfile(ctfroot,"login");
            else
                app.originPath = pwd;
            end

             %% Get icon paths
            app.infoIconPath = fullfile(app.originPath,"icons\sign-info-svgrepo-com.svg");
            app.successIconPath = fullfile(app.originPath,"icons\success-icon.svg");
            app.warningIconPath = fullfile(app.originPath,"icons\warning-svgrepo-com.svg");
            app.errorIconPath = fullfile(app.originPath,"icons\error-svgrepo-com.svg");
            app.sampleImagePath = fullfile(app.originPath,"images\attachImage.png");

            %% get connection object
            app.conn = connection;

            %% Load language file
            languagePath = fullfile(app.originPath,"language\viewRecordLanguage.xlsx");
            app.viewRecordLanguage = readtable(languagePath); 

             %% Setting up language
            app.setLanguage(language)
            
            %% Adjusting window state from last app 

            if(windowState)
                app.screenToolOn;
            else
                app.screenToolOff;
            end

            %% Obtaining ui componentes
            app.buttons = findobj(app.UIFigure,'Type','uibutton','-and','-not','Tag','IV');
            app.checkBoxes = findobj(app.UIFigure,'Type','uicheckbox','-and','-not','Tag','IV');
            app.dropDowns = findobj(app.UIFigure,'Type','uidropdown','-and','-not','Tag','IV');
            app.efields = findobj(app.UIFigure,'Type','uieditfield','-and','-not','Tag','IV');
            app.labels = findobj(app.UIFigure,'Type','uilabel','-and','-not','Tag','IV');
            app.listBoxes = findobj(app.UIFigure,'Type','uilistbox','-and','-not','Tag','IV');
            app.nefields = findobj(app.UIFigure,'Tag','nefield','-and','-not','Tag','IV');
            app.panels = findobj(app.UIFigure,'Type','uipanel','-and','-not','Tag','IV');
            app.textAreas = findobj(app.UIFigure,'Type','uitextarea','-and','-not','Tag','IV');
            app.tables = findobj(app.UIFigure,'Type','uitable','-and','-not','Tag','IV');

            %% Disable components
            app.disableFields()

            %% Updating theme from last app
            if(theme)
                app.setDarkTheme
            else
                app.setLightTheme
            end

            progressBar = uiprogressdlg(app.UIFigure,"Indeterminate","on");
            progressBar.Message = app.startingAppMessage;

            %% Load catalogs and variables
            nationalitiesPath = fullfile(app.originPath,"catalogs\CAT_NATIONALITIES.xlsx");
            app.nationalities = readtable(nationalitiesPath);

            indigenousLanguagePath = fullfile(app.originPath,"catalogs\INDIGENOUS_LANGUAGE_2018.xlsx");
            app.indigenousLanguages = readtable(indigenousLanguagePath);
            
            federalEntitiesPath = fullfile(app.originPath,"catalogs\FEDERAL_ENTITIES_201602.xlsx");
            app.federalEntities = readtable(federalEntitiesPath);
    
            bloodTypePath = fullfile(app.originPath,"settings","bloodTypes.mat");
            app.bloodTypes = load(bloodTypePath);

            biologicalSexPath = fullfile(app.originPath,"language\biologicalSex.xlsx");
            app.biologicalSexItems = readtable(biologicalSexPath);

            %% Get patient medical record
            progressBar.Message = app.decipheringMedicalRecordMessage;
            app.medicalRecord = app.loadRecord(ID);

            progressBar.Message = app.loadingRecordMessage;
            pause(1)
            app.showInformation(app.medicalRecord)

            app.UITable.Data = app.medicalRecord.imagingStudies;
            app.tempImagingStudies = app.medicalRecord.imagingStudies;
            app.viewerUITable.Data = app.tempImagingStudies;

            close(progressBar)

            %% Create pointer manager
            app.configurePointers("arrow")

            %% Show sample image and disable controls and interactions
            app.UIAxes.Interactions = [];
            app.histogramUIAxes.Interactions = [];
            axis(app.UIAxes,"off")
            axis(app.histogramUIAxes,"off")

            imshow(app.sampleImagePath,"Parent",app.UIAxes)
            axis(app.UIAxes,"auto")

            app.disableImageControls()

        end

        % On callback: screenTool
        function screenToolOn(app, event)
            %% Fullscreen
            app.currentWindowState = 1;
            app.screenTool.State = "on";
            app.UIFigure.WindowState = "fullscreen";
        end

        % Off callback: screenTool
        function screenToolOff(app, event)
            %% Maximized screen
            app.currentWindowState = 0;
            app.screenTool.State = "off";
            app.UIFigure.WindowState = "maximized";
        end

        % On callback: themeTool
        function themeToolOn(app, event)
            app.setDarkTheme();
        end

        % Off callback: themeTool
        function themeToolOff(app, event)
            app.setLightTheme();
        end

        % Button down function: imageViewerTab
        function imageViewerTabButtonDown(app, event)
            app.themeTool.Visible = "off";
            app.themeTool.Enable = "off";
            app.Toolbar.BackgroundColor = app.darkThemeColorToolBar;
            app.imageViewerTab.BackgroundColor = app.darkThemeColorUIF;
        end

        % Button down function: recordTab
        function recordTabButtonDown(app, event)
            app.themeTool.Visible = "on";
            app.themeTool.Enable = "on";
            
            if app.currentTheme
                app.setDarkTheme;
            else
                app.setLightTheme;
            end
        end

        % Button pushed function: imageViewerButton
        function imageViewerButtonPushed(app, event)
            app.imageViewerTabButtonDown();
            app.TabGroup.SelectedTab = app.imageViewerTab;
            scroll(app.GridLayout,"top")

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(timerfindall)
            app.saveSettings()
            delete(app)
        end

        % Callback function: configTool
        function configToolClicked(app, event)
            %% Open configurations app
            app.configurationsApp = configurations(app.currentTheme...
                ,app.currentLanguage,viewRecordV2,app.canModify);
        end

        % Callback function: closeTool
        function closeToolClicked(app, event)
            %% Stop and delete timers and app
            delete(app.configurationsApp)
            app.UIFigureCloseRequest
        end

        % Callback function: logoutTool
        function logoutToolClicked(app, event)
            %% Close current app and open log in app
            delete(app.configurationsApp)
            delete(timerfindall)
            login(app.currentWindowState,app.currentTheme,app.currentLanguage)
            delete(app)
        end

        % Callback function: mainMenuTool
        function mainMenuToolClicked(app, event)
            delete(app.configurationsApp)
            delete(timerfindall)
            selectAction(app.currentWindowState,app.currentTheme,app.currentLanguage,app.conn)
            delete(app)
        end

        % Button pushed function: homeAxisButton
        function homeAxisButtonPushed(app, event)
            axis(app.UIAxes,"auto")
        end

        % Selection changed function: viewerUITable
        function viewerUITableSelectionChanged(app, event)
            row = app.viewerUITable.Selection;
            app.imageControlsToZero()
            if isempty(row)
                app.disableImageControls;
                axis(app.histogramUIAxes,"off")
                cla(app.histogramUIAxes)
                imshow(app.sampleImagePath,"Parent",app.UIAxes)
                return
            end

            %% Get image information
            selectedImageInfo = app.viewerUITable.Data(row,[2,3,4,6]);
            selectedImageInfo = table2cell(selectedImageInfo);
            selectedImageInfoFormat = [char(selectedImageInfo{2}),'   '...,
                selectedImageInfo{1},'/',selectedImageInfo{3},'/',...
                selectedImageInfo{end}];

            %% Show image information
            app.currentImageEditField.Value = selectedImageInfoFormat;

            %% Change image
            try
                %% Show image
                app.tempImage = app.viewerUITable.Data(row,end);
                app.tempImage = table2array(app.tempImage);
                app.tempImage = imadjust(app.tempImage{:});
                imshow(app.tempImage,"Parent",app.UIAxes)
                app.homeAxisButtonPushed();
                app.enableImageControls();

                %% Show image histogram
                axis(app.histogramUIAxes,"on")
                histogram(app.histogramUIAxes,app.tempImage,'FaceColor',[1,1,1])

                if isa(app.tempImage,"uint16") || isa(app.tempImage,"int16")
                    app.maxImageRange = 65535;
                elseif isa(app.tempImage,"uint8") || isa(app.tempImage,"int8")
                    app.maxImageRange = 255;
                end

            catch 
                app.disableImageControls();
                axis(app.histogramUIAxes,"off")
                cla(app.histogramUIAxes)
            end


        end

        % Value changed function: zoomRegionButton
        function zoomRegionButtonValueChanged(app, event)
            value = app.zoomRegionButton.Value;
            if value
                app.UIAxes.Interactions = [regionZoomInteraction zoomInteraction];
                app.pointerShape = "crosshair";
                app.configurePointers(app.pointerShape)
            else
                app.UIAxes.Interactions = [];
                app.pointerShape = "arrow";
                app.configurePointers(app.pointerShape)
            end
        end

        % Value changed function: panImageButton
        function panImageButtonValueChanged(app, event)
            value = app.panImageButton.Value;
            if value
                app.UIAxes.Interactions = panInteraction;
                app.pointerShape = "fleur";
                app.configurePointers(app.pointerShape)
            else
                app.UIAxes.Interactions = [];
                app.pointerShape = "arrow";
                app.configurePointers(app.pointerShape)
            end
        end

        % Value changing function: contrastSlider
        function contrastSliderValueChanging(app, event)
            changingValue = event.Value;
            contrastValue = changingValue ;

            toShow = app.tempImage * contrastValue;
            imshow(toShow,"Parent",app.UIAxes)
            histogram(app.histogramUIAxes,toShow,"FaceColor",[1,1,1])

        end

        % Value changing function: gammaSlider
        function gammaSliderValueChanging(app, event)
            changingValue = event.Value;
            gammaValue = changingValue;

            classVar = class(app.tempImage);
            toShow = double(app.tempImage) .^ gammaValue;
            toShow = cast(toShow,classVar);
            imshow(toShow,"Parent",app.UIAxes)
            histogram(app.histogramUIAxes,toShow,"FaceColor",[1,1,1])
        end

        % Value changing function: brightnessSlider
        function brightnessSliderValueChanging(app, event)
            changingValue = event.Value;
            brightnessValue = changingValue * app.maxImageRange/2;
            toShow = app.tempImage + brightnessValue;
            imshow(toShow,"Parent",app.UIAxes)
            histogram(app.histogramUIAxes,toShow,"FaceColor",[1,1,1])

        end

        % Callback function: infoTool
        function infoToolClicked(app, event)
            uialert(app.UIFigure,app.infoToolMessage,app.infoToolTitle,...
                "Icon",app.infoIconPath)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.infoTool = uipushtool(app.Toolbar);
            app.infoTool.ClickedCallback = createCallbackFcn(app, @infoToolClicked, true);
            app.infoTool.Icon = fullfile(pathToMLAPP, 'icons', 'sign-info-svgrepo-com.svg');
            app.screenTool = uitoggletool(app.Toolbar);
            app.screenTool.Icon = fullfile(pathToMLAPP, 'icons', 'full-screen-svgrepo-com.svg');
            app.screenTool.Separator = 'on';
            app.screenTool.OffCallback = createCallbackFcn(app, @screenToolOff, true);
            app.screenTool.OnCallback = createCallbackFcn(app, @screenToolOn, true);
            app.themeTool = uitoggletool(app.Toolbar);
            app.themeTool.Icon = fullfile(pathToMLAPP, 'icons', 'moon-stars-svgrepo-com-LC-Black.svg');
            app.themeTool.OffCallback = createCallbackFcn(app, @themeToolOff, true);
            app.themeTool.OnCallback = createCallbackFcn(app, @themeToolOn, true);
            app.configTool = uipushtool(app.Toolbar);
            app.configTool.ClickedCallback = createCallbackFcn(app, @configToolClicked, true);
            app.configTool.Icon = fullfile(pathToMLAPP, 'icons', 'cogwheel-svgrepo-com.svg');
            app.logoutTool = uipushtool(app.Toolbar);
            app.logoutTool.ClickedCallback = createCallbackFcn(app, @logoutToolClicked, true);
            app.logoutTool.Icon = fullfile(pathToMLAPP, 'icons', 'power-button-power-svgrepo-com.svg');
            app.logoutTool.Separator = 'on';
            app.closeTool = uipushtool(app.Toolbar);
            app.closeTool.ClickedCallback = createCallbackFcn(app, @closeToolClicked, true);
            app.closeTool.Icon = fullfile(pathToMLAPP, 'icons', 'cross-close-svgrepo-com.svg');
            app.mainMenuTool = uipushtool(app.Toolbar);
            app.mainMenuTool.ClickedCallback = createCallbackFcn(app, @mainMenuToolClicked, true);
            app.mainMenuTool.Icon = fullfile(pathToMLAPP, 'icons', 'visualization-grid-svgrepo-com.svg');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.recordTab = uitab(app.TabGroup);
            app.recordTab.Title = 'Tab';
            app.recordTab.ButtonDownFcn = createCallbackFcn(app, @recordTabButtonDown, true);
            app.customClock = customClock(app.recordTab);
            app.clockImage = uiimage(app.recordTab);
            app.clockImage.Tag = 'image';
            app.clockImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'calendar-clock-svgrepo-com.svg');
            app.idivPanel = uipanel(app.recordTab);
            app.idivPanel.Title = 'Panel2';
            app.Image2 = uiimage(app.idivPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'icons', 'id-card-svgrepo-com.svg');
            app.idEditFieldLabel = uilabel(app.idivPanel);
            app.idEditFieldLabel.Text = 'Edit Field';
            app.idEditField = uieditfield(app.idivPanel, 'text');
            app.idEditField.Editable = 'off';
            app.createdAtEditFieldLabel = uilabel(app.idivPanel);
            app.createdAtEditFieldLabel.Text = 'Edit Field';
            app.createdAtEditField = uieditfield(app.idivPanel, 'text');
            app.createdAtEditField.Editable = 'off';
            app.generalInformationPanel = uipanel(app.recordTab);
            app.generalInformationPanel.Title = 'Panel';
            app.nameEditFieldLabel = uilabel(app.generalInformationPanel);
            app.nameEditFieldLabel.Text = 'Edit Field2';
            app.nameEditField = uieditfield(app.generalInformationPanel, 'text');
            app.nameEditField.CharacterLimits = [0 50];
            app.nameEditField.Tooltip = {'El valor solo puede contener letras'};
            app.fLastNameEditFieldLabel = uilabel(app.generalInformationPanel);
            app.fLastNameEditFieldLabel.Text = 'Edit Field2';
            app.fLastNameEditField = uieditfield(app.generalInformationPanel, 'text');
            app.fLastNameEditField.CharacterLimits = [0 50];
            app.sLastNameEditFieldLabel = uilabel(app.generalInformationPanel);
            app.sLastNameEditFieldLabel.Text = 'Edit Field2';
            app.sLastNameEditField = uieditfield(app.generalInformationPanel, 'text');
            app.sLastNameEditField.CharacterLimits = [0 50];
            app.indigenousLanguageSpeakerCheckBox = uicheckbox(app.generalInformationPanel);
            app.Image = uiimage(app.generalInformationPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'blood-drop-svgrepo-com.svg');
            app.Image_2 = uiimage(app.generalInformationPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'calendar-svgrepo-com.svg');
            app.Image_3 = uiimage(app.generalInformationPanel);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'icons', 'globe-showing-americas-svgrepo-com.svg');
            app.Image_4 = uiimage(app.generalInformationPanel);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'icons', 'genders-gender-svgrepo-com.svg');
            app.Image_5 = uiimage(app.generalInformationPanel);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'icons', 'people-speak-svgrepo-com.svg');
            app.ageEditFieldLabel = uilabel(app.generalInformationPanel);
            app.ageEditFieldLabel.Text = 'Edit Field';
            app.ageEditField = uieditfield(app.generalInformationPanel, 'numeric');
            app.ageEditField.Limits = [0 Inf];
            app.ageEditField.Editable = 'off';
            app.sLastNameCheckBox = uicheckbox(app.generalInformationPanel);
            app.sLastNameCheckBox.Text = 'Check Box2';
            app.curpEditFieldLabel = uilabel(app.generalInformationPanel);
            app.curpEditFieldLabel.Text = 'Edit Field2';
            app.curpEditField = uieditfield(app.generalInformationPanel, 'text');
            app.curpEditField.CharacterLimits = [0 18];
            app.curpEditField.InputType = 'alphanumerics';
            app.curpCheckBox = uicheckbox(app.generalInformationPanel);
            app.Image_6 = uiimage(app.generalInformationPanel);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'icons', 'user-7-svgrepo-com.svg');
            app.Image_7 = uiimage(app.generalInformationPanel);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'icons', 'baby-symbol-svgrepo-com.svg');
            app.Image_8 = uiimage(app.generalInformationPanel);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'icons', 'maps-and-flags-pin-svgrepo-com.svg');
            app.nationalityEditFieldLabel = uilabel(app.generalInformationPanel);
            app.nationalityEditFieldLabel.Text = 'Edit Field6';
            app.nationalityEditField = uieditfield(app.generalInformationPanel, 'text');
            app.placeOfBirthEditFieldLabel = uilabel(app.generalInformationPanel);
            app.placeOfBirthEditFieldLabel.Text = 'Edit Field7';
            app.placeOfBirthEditField = uieditfield(app.generalInformationPanel, 'text');
            app.placeOfResidenceEditFieldLabel = uilabel(app.generalInformationPanel);
            app.placeOfResidenceEditFieldLabel.Text = 'Edit Field8';
            app.placeOfResidenceEditField = uieditfield(app.generalInformationPanel, 'text');
            app.biologicalSexEditFieldLabel = uilabel(app.generalInformationPanel);
            app.biologicalSexEditFieldLabel.Text = 'Edit Field9';
            app.biologicalSexEditField = uieditfield(app.generalInformationPanel, 'text');
            app.birthDateEditFieldLabel = uilabel(app.generalInformationPanel);
            app.birthDateEditFieldLabel.Text = 'Edit Field10';
            app.birthDateEditField = uieditfield(app.generalInformationPanel, 'text');
            app.bloodTypeEditFieldLabel = uilabel(app.generalInformationPanel);
            app.bloodTypeEditFieldLabel.Text = 'Edit Field11';
            app.bloodTypeEditField = uieditfield(app.generalInformationPanel, 'text');
            app.indigenousLanguageEditFieldLabel = uilabel(app.generalInformationPanel);
            app.indigenousLanguageEditFieldLabel.Text = 'Edit Field12';
            app.indigenousLanguageEditField = uieditfield(app.generalInformationPanel, 'text');
            app.contactInformationPanel = uipanel(app.recordTab);
            app.contactInformationPanel.Title = 'Panel5';
            app.phoneNumberEditFieldLabel = uilabel(app.contactInformationPanel);
            app.phoneNumberEditFieldLabel.Text = 'Edit Field3';
            app.phoneNumberEditField = uieditfield(app.contactInformationPanel, 'text');
            app.phoneNumberEditField.InputType = 'digits';
            app.emailEditFieldLabel = uilabel(app.contactInformationPanel);
            app.emailEditFieldLabel.Text = 'Edit Field3';
            app.emailEditField = uieditfield(app.contactInformationPanel, 'text');
            app.postalCodeEditFieldLabel = uilabel(app.contactInformationPanel);
            app.postalCodeEditFieldLabel.Text = 'Edit Field4';
            app.postalCodeEditField = uieditfield(app.contactInformationPanel, 'numeric');
            app.postalCodeEditField.Limits = [0 Inf];
            app.postalCodeEditField.ValueDisplayFormat = '%.0f';
            app.postalCodeEditField.Tag = 'nefield';
            app.Image5 = uiimage(app.contactInformationPanel);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'icons', 'email-mail-svgrepo-com.svg');
            app.Image5_2 = uiimage(app.contactInformationPanel);
            app.Image5_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'phone-number-svgrepo-com.svg');
            app.Image5_3 = uiimage(app.contactInformationPanel);
            app.Image5_3.ImageSource = fullfile(pathToMLAPP, 'icons', 'map-map-marker-svgrepo-com.svg');
            app.addressTextAreaLabel = uilabel(app.contactInformationPanel);
            app.addressTextAreaLabel.Text = 'Text Area2';
            app.addressTextArea = uitextarea(app.contactInformationPanel);
            app.selectFilesPanel = uipanel(app.recordTab);
            app.selectFilesPanel.Title = 'Panel6';
            app.Image3 = uiimage(app.selectFilesPanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'images', 'attachImage.png');
            app.filesAvailableLabel = uilabel(app.selectFilesPanel);
            app.imageViewerButton = uibutton(app.selectFilesPanel, 'push');
            app.imageViewerButton.ButtonPushedFcn = createCallbackFcn(app, @imageViewerButtonPushed, true);
            app.imageViewerButton.Icon = fullfile(pathToMLAPP, 'icons', 'image-svgrepo-com.svg');
            app.UITable = uitable(app.selectFilesPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'};
            app.UITable.RowName = {};
            app.clinicHistoryPanel = uipanel(app.recordTab);
            app.clinicHistoryPanel.Title = 'Panel4';
            app.inheritedPhatologicalHistoryTextAreaLabel = uilabel(app.clinicHistoryPanel);
            app.inheritedPhatologicalHistoryTextAreaLabel.Text = 'Text Area';
            app.inheritedPathologicalHistoryTextArea = uitextarea(app.clinicHistoryPanel);
            app.nonPhatologicalPersonalHistoryTextAreaLabel = uilabel(app.clinicHistoryPanel);
            app.nonPhatologicalPersonalHistoryTextAreaLabel.Text = 'Text Area';
            app.nonPathologicalPersonalHistoryTextArea = uitextarea(app.clinicHistoryPanel);
            app.phatologicalPersonalHistoryTextAreaLabel = uilabel(app.clinicHistoryPanel);
            app.phatologicalPersonalHistoryTextAreaLabel.Text = 'Text Area';
            app.pathologicalPersonalHistoryTextArea = uitextarea(app.clinicHistoryPanel);
            app.physicalExplorationTextAreaLabel = uilabel(app.clinicHistoryPanel);
            app.physicalExplorationTextAreaLabel.Text = 'Text Area';
            app.physicalExplorationTextArea = uitextarea(app.clinicHistoryPanel);
            app.Image4 = uiimage(app.clinicHistoryPanel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'icons', 'stethoscope-svgrepo-com.svg');
            app.Image4_2 = uiimage(app.clinicHistoryPanel);
            app.Image4_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'family-institution-svgrepo-com.svg');
            app.Image4_3 = uiimage(app.clinicHistoryPanel);
            app.Image4_3.ImageSource = fullfile(pathToMLAPP, 'icons', 'man-with-sore-throat-svgrepo-com.svg');
            app.Image4_4 = uiimage(app.clinicHistoryPanel);
            app.Image4_4.ImageSource = fullfile(pathToMLAPP, 'icons', 'hospital-svgrepo-com.svg');
            app.modifyButton = uibutton(app.recordTab, 'push');
            app.modifyButton.Icon = fullfile(pathToMLAPP, 'icons', 'eraser-svgrepo-com.svg');
            app.patientRecordLabel = uilabel(app.recordTab);
            app.patientRecordLabel.Text = 'Label3';
            app.imageViewerTab = uitab(app.TabGroup);
            app.imageViewerTab.Title = 'Tab2';
            app.imageViewerTab.ButtonDownFcn = createCallbackFcn(app, @imageViewerTabButtonDown, true);
            app.customClock_2 = customClock(app.imageViewerTab);
            app.customClock_2.Tag = 'IV';
            app.clockImage_2 = uiimage(app.imageViewerTab);
            app.clockImage_2.Tag = 'IV';
            app.clockImage_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'calendar-clock-svgrepo-com.svg');
            app.brightnessSliderLabel = uilabel(app.imageViewerTab);
            app.brightnessSliderLabel.Tag = 'IV';
            app.brightnessSliderLabel.Text = 'Slider';
            app.brightnessSlider = uislider(app.imageViewerTab);
            app.brightnessSlider.Limits = [-2 2];
            app.brightnessSlider.ValueChangingFcn = createCallbackFcn(app, @brightnessSliderValueChanging, true);
            app.brightnessSlider.Tag = 'IV';
            app.Panel = uipanel(app.imageViewerTab);
            app.imageGridLayout = uigridlayout(app.Panel);
            app.imageGridLayout.Tag = 'IV';
            app.UIAxes = uiaxes(app.imageGridLayout);
            title(app.UIAxes, 'Title')
            app.UIAxes.XLim = [0.0177862811791384 0.926877190270047];
            app.UIAxes.YLim = [0.0440849169662729 0.953175826057182];
            app.histogramUIAxes = uiaxes(app.imageGridLayout);
            title(app.histogramUIAxes, 'Title')
            xlabel(app.histogramUIAxes, 'X')
            ylabel(app.histogramUIAxes, 'Y')
            zlabel(app.histogramUIAxes, 'Z')
            app.viewerUITable = uitable(app.imageViewerTab);
            app.viewerUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'};
            app.viewerUITable.RowName = {};
            app.viewerUITable.SelectionType = 'row';
            app.viewerUITable.SelectionChangedFcn = createCallbackFcn(app, @viewerUITableSelectionChanged, true);
            app.viewerUITable.Multiselect = 'off';
            app.viewerUITable.Tag = 'IV';
            app.contrastSliderLabel = uilabel(app.imageViewerTab);
            app.contrastSliderLabel.Tag = 'IV';
            app.contrastSliderLabel.Text = 'Slider';
            app.contrastSlider = uislider(app.imageViewerTab);
            app.contrastSlider.Limits = [0 2];
            app.contrastSlider.ValueChangingFcn = createCallbackFcn(app, @contrastSliderValueChanging, true);
            app.contrastSlider.Tag = 'IV';
            app.contrastSlider.Value = 1;
            app.gammaSlider = uislider(app.imageViewerTab);
            app.gammaSlider.Limits = [0 2];
            app.gammaSlider.ValueChangingFcn = createCallbackFcn(app, @gammaSliderValueChanging, true);
            app.gammaSlider.Tag = 'IV';
            app.gammaSlider.Value = 1;
            app.gammaSliderLabel = uilabel(app.imageViewerTab);
            app.gammaSliderLabel.Tag = 'IV';
            app.gammaSliderLabel.Text = 'Slider';
            app.controlsLabel = uilabel(app.imageViewerTab);
            app.controlsLabel.Tag = 'IV';
            app.controlsLabel.Text = 'Label4';
            app.homeAxisButton = uibutton(app.imageViewerTab, 'push');
            app.homeAxisButton.ButtonPushedFcn = createCallbackFcn(app, @homeAxisButtonPushed, true);
            app.homeAxisButton.Tag = 'IV';
            app.homeAxisButton.Icon = fullfile(pathToMLAPP, 'icons', 'home-selected-state-svgrepo-com.svg');
            app.homeAxisButton.Text = '';
            app.currentImageLabel = uilabel(app.imageViewerTab);
            app.currentImageLabel.Tag = 'IV';
            app.currentImageLabel.Text = 'Edit Field13';
            app.currentImageEditField = uieditfield(app.imageViewerTab, 'text');
            app.currentImageEditField.Tag = 'IV';
            app.zoomRegionButton = uibutton(app.imageViewerTab, 'state');
            app.zoomRegionButton.ValueChangedFcn = createCallbackFcn(app, @zoomRegionButtonValueChanged, true);
            app.zoomRegionButton.Tag = 'IV';
            app.zoomRegionButton.Icon = fullfile(pathToMLAPP, 'icons', 'zoom-internal-svgrepo-com.svg');
            app.zoomRegionButton.Text = '';
            app.panImageButton = uibutton(app.imageViewerTab, 'state');
            app.panImageButton.ValueChangedFcn = createCallbackFcn(app, @panImageButtonValueChanged, true);
            app.panImageButton.Tag = 'IV';
            app.panImageButton.Icon = fullfile(pathToMLAPP, 'icons', 'pan-cursor-svgrepo-com.svg');
            app.panImageButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewRecordV2(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end